classdef AAA_MasterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AttitudeProfileButton         matlab.ui.control.Button
        OrbitalParametersButton       matlab.ui.control.Button
        GroundStationsTargetsButton   matlab.ui.control.Button
        AnalysisTimePeriodandTimeStepButton  matlab.ui.control.Button
        RUNSTKButton                  matlab.ui.control.Button
        AccessandAERPassDataButton    matlab.ui.control.Button
        NoteLabel                     matlab.ui.control.Label
        VirginiaTechLabel             matlab.ui.control.Label
        Openthe4GUIsbelowandenterthesimulationinformationLabel  matlab.ui.control.Label
        Openthe4GUIsbelowandenterthesimulationinformationLabel_2  matlab.ui.control.Label
        Openthe4GUIsbelowandenterthesimulationinformationLabel_3  matlab.ui.control.Label
        LifetimeEstimationButton      matlab.ui.control.Button
        InitializationLabel           matlab.ui.control.Label
        AnalysisToolsLabel            matlab.ui.control.Label
        ImagerFOVSimulationButton     matlab.ui.control.Button
        GroundTrackButton             matlab.ui.control.Button
        HealthPowerThermalButton      matlab.ui.control.Button
        ThenclickRUNSTKTheanalysistoolsmaybeusedafterwardsLabel  matlab.ui.control.Label
        StatusSTKnotrunLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MayberunwithoutInitializationandRUNSTKLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        SmallSatelliteSimulatorLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupImgFcn(app)
            % imshow('VT_logo.jpg', 'Parent', app.UIAxes);
            % I = imshow('VT_logo.jpg', 'Parent', app.UIAxes);
            % app.UIAxes.XLim = [0 I.XData(2)];
            % app.UIAxes.YLim = [0 I.YData(2)];
            
            % Fill figure with axes and remove tick labels
            % app.UIAxes.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('VT_logo.png', 'Parent', app.UIAxes, ...
                'XData', [0 app.UIAxes.Position(3)], ...
                'YData', [0 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
             
        end

        % Button pushed function: AttitudeProfileButton
        function AttitudeProfileButtonPushed(app, event)
            SatelliteAttitudeGUI
        end

        % Button pushed function: GroundStationsTargetsButton
        function GroundStationsTargetsButtonPushed(app, event)
            GroundStationTargetsGUI
        end

        % Button pushed function: OrbitalParametersButton
        function OrbitalParametersButtonPushed(app, event)
            OrbitInitializationGUI
        end

        % Button pushed function: AnalysisTimePeriodandTimeStepButton
        function AnalysisTimePeriodandTimeStepButtonPushed(app, event)
            AnalysisTimePeriodGUI
        end

        % Button pushed function: RUNSTKButton
        function RUNSTKButtonPushed(app, event)
            [RE, SolarConst, EarthOrbRad, Sigma, EarthIRAvg, muEarth, NormSat] = LoadingConstants();
            assignin('base', 'RE', RE);
            assignin('base', 'SolarConst', SolarConst);
            assignin('base', 'EarthOrbRad', EarthOrbRad);
            assignin('base', 'Sigma', Sigma);
            assignin('base', 'EarthIRAvg', EarthIRAvg);
            assignin('base', 'muEarth', muEarth);
            assignin('base', 'NormSat', NormSat);
            
            try
                AttitudeParameters = evalin('base','AttitudeParameters');
                COEs = evalin('base','COEs');
                TLElines = evalin('base', 'TLElines');
                EndDate = evalin('base','EndDate');
                EpochDate = evalin('base','EpochDate');
                GroundStationLLA = evalin('base','GroundStationLLA');
                MinElevations = evalin('base','MinElevations');
                StartDate = evalin('base','StartDate');
                TargetsLLA = evalin('base','TargetsLLA');
                TimeStep = evalin('base','TimeStep');
                AccessTimeStep = evalin('base','AccessTimeStep');
            catch
                app.StatusSTKnotrunLabel.Text = "STK Status: Failed to run! Inputs missing?";
                return
            end

            
            % If initial state was entered in cartesian form, convert to keplerian
            %%% Now Done in OribtInitializationGUI
            %{
            if CartesianorKeplerianValue == 0 
                [COEs] = Cartesian2Keplerian(InitialStateVector(1:3), InitialStateVector(4:6), muEarth);
            end
            %}
            
           
            f = uifigure;
            uiprogressdlg(f,'Title','Running STK. May take a minute','Indeterminate','on');
              
            [Sat2EarthECEF, Sat2SunECEF, SatRPYAnglesECEF, Sat2EarthBody,...
                Sat2SunBody, LatLongAlt, Time, TimeUTCG, Access2GroundStation, AERfromGroundStationCell,... 
                Access2Targets, AERfromTargets, AccessGSNote] = Call_STK(StartDate, EndDate, EpochDate, TimeStep, COEs, TLElines, ...
                GroundStationLLA, TargetsLLA, AttitudeParameters, MinElevations, AccessTimeStep);
                
            app.StatusSTKnotrunLabel.Text = "STK Status: Complete! Time for analysis.";
            app.NoteLabel.Text = AccessGSNote;
            
            close(f);
            
            assignin('base', 'Sat2EarthECEF', Sat2EarthECEF);
            assignin('base', 'Sat2SunECEF', Sat2SunECEF);
            assignin('base', 'SatRPYAnglesECEF', SatRPYAnglesECEF);
            assignin('base', 'Sat2EarthBody', Sat2EarthBody);
            assignin('base', 'Sat2SunBody', Sat2SunBody);
            assignin('base', 'LatLongAlt', LatLongAlt);
            assignin('base', 'Time', Time);
            assignin('base', 'Access2GroundStation', Access2GroundStation);
            assignin('base', 'AERfromGroundStationCell', AERfromGroundStationCell);
            assignin('base', 'AERfromTargets', AERfromTargets);
            assignin('base', 'Access2Targets', Access2Targets);
            assignin('base', 'AccessGSNote', AccessGSNote);
            assignin('base', 'TimeUTCG', TimeUTCG);

        end

        % Button pushed function: AccessandAERPassDataButton
        function AccessandAERPassDataButtonPushed(app, event)
            AccessPassesGUI
        end

        % Button pushed function: LifetimeEstimationButton
        function LifetimeEstimationButtonPushed(app, event)
            LifetimeGUI
        end

        % Button pushed function: ImagerFOVSimulationButton
        function ImagerFOVSimulationButtonPushed(app, event)
            ImagerGUI
        end

        % Button pushed function: HealthPowerThermalButton
        function HealthPowerThermalButtonPushed(app, event)
            HealthPowerHeatGUI
        end

        % Button pushed function: GroundTrackButton
        function GroundTrackButtonPushed(app, event)
            GroundTrackGUI
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AttitudeProfileButton = uibutton(app.UIFigure, 'push');
            app.AttitudeProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AttitudeProfileButtonPushed, true);
            app.AttitudeProfileButton.Text = 'Attitude Profile';
            app.OrbitalParametersButton = uibutton(app.UIFigure, 'push');
            app.OrbitalParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitalParametersButtonPushed, true);
            app.OrbitalParametersButton.Text = 'Orbital Parameters';
            app.GroundStationsTargetsButton = uibutton(app.UIFigure, 'push');
            app.GroundStationsTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @GroundStationsTargetsButtonPushed, true);
            app.GroundStationsTargetsButton.Text = 'Ground Stations / Targets';
            app.AnalysisTimePeriodandTimeStepButton = uibutton(app.UIFigure, 'push');
            app.AnalysisTimePeriodandTimeStepButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisTimePeriodandTimeStepButtonPushed, true);
            app.AnalysisTimePeriodandTimeStepButton.Text = 'Analysis Time Period and Time Step';
            app.RUNSTKButton = uibutton(app.UIFigure, 'push');
            app.RUNSTKButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSTKButtonPushed, true);
            app.RUNSTKButton.Text = 'RUN STK';
            app.AccessandAERPassDataButton = uibutton(app.UIFigure, 'push');
            app.AccessandAERPassDataButton.ButtonPushedFcn = createCallbackFcn(app, @AccessandAERPassDataButtonPushed, true);
            app.AccessandAERPassDataButton.Text = 'Access and AER Pass Data';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note:';
            app.VirginiaTechLabel = uilabel(app.UIFigure);
            app.VirginiaTechLabel.Text = 'Virginia Tech';
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel = uilabel(app.UIFigure);
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel.Text = 'Open the 4 GUIs below and enter the simulation information.';
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel_2 = uilabel(app.UIFigure);
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel_2.Text = 'The variables created via the initialization GUIs can be saved';
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel_3 = uilabel(app.UIFigure);
            app.Openthe4GUIsbelowandenterthesimulationinformationLabel_3.Text = 'in a .mat file for future use. Likewise for the output of RUN STK';
            app.LifetimeEstimationButton = uibutton(app.UIFigure, 'push');
            app.LifetimeEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @LifetimeEstimationButtonPushed, true);
            app.LifetimeEstimationButton.Text = 'Lifetime Estimation';
            app.InitializationLabel = uilabel(app.UIFigure);
            app.InitializationLabel.Text = 'Initialization';
            app.AnalysisToolsLabel = uilabel(app.UIFigure);
            app.AnalysisToolsLabel.Text = 'Analysis Tools';
            app.ImagerFOVSimulationButton = uibutton(app.UIFigure, 'push');
            app.ImagerFOVSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ImagerFOVSimulationButtonPushed, true);
            app.ImagerFOVSimulationButton.Text = 'Imager FOV Simulation';
            app.GroundTrackButton = uibutton(app.UIFigure, 'push');
            app.GroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @GroundTrackButtonPushed, true);
            app.GroundTrackButton.Text = 'Ground Track';
            app.HealthPowerThermalButton = uibutton(app.UIFigure, 'push');
            app.HealthPowerThermalButton.ButtonPushedFcn = createCallbackFcn(app, @HealthPowerThermalButtonPushed, true);
            app.HealthPowerThermalButton.Text = 'Health (Power/Thermal)';
            app.ThenclickRUNSTKTheanalysistoolsmaybeusedafterwardsLabel = uilabel(app.UIFigure);
            app.ThenclickRUNSTKTheanalysistoolsmaybeusedafterwardsLabel.Text = 'Then click RUN STK. The analysis tools may be used afterwards.';
            app.StatusSTKnotrunLabel = uilabel(app.UIFigure);
            app.StatusSTKnotrunLabel.Text = 'Status: STK not run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '***';
            app.MayberunwithoutInitializationandRUNSTKLabel = uilabel(app.UIFigure);
            app.MayberunwithoutInitializationandRUNSTKLabel.Text = '*** : May be run without Initialization and RUN STK';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '***';
            app.UIAxes = uiaxes(app.UIFigure);
            app.SmallSatelliteSimulatorLabel = uilabel(app.UIFigure);
            app.SmallSatelliteSimulatorLabel.Text = 'Small Satellite Simulator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AAA_MasterGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupImgFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end