classdef AccessPassesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofAccessestoGroundStationEditFieldLabel  matlab.ui.control.Label
        NumberofAccessestoGroundStationEditField  matlab.ui.control.NumericEditField
        MinGroundStationElevationUsedEditFieldLabel  matlab.ui.control.Label
        MinGroundStationElevationUsedEditField  matlab.ui.control.NumericEditField
        AccessNumberToViewEditFieldLabel  matlab.ui.control.Label
        AccessNumberToViewEditField    matlab.ui.control.NumericEditField
        GroundStationAERDataLabel      matlab.ui.control.Label
        ViewGSAERDataPlotsButton       matlab.ui.control.Button
        NoteLabel                      matlab.ui.control.Label
        NumberofTargetsEditFieldLabel  matlab.ui.control.Label
        NumberofTargetsEditField       matlab.ui.control.NumericEditField
        MinTargetElevationUsedEditFieldLabel  matlab.ui.control.Label
        MinTargetElevationUsedEditField  matlab.ui.control.NumericEditField
        PlotGroundTrackButton          matlab.ui.control.Button
        CreateLLAcsvFileButton         matlab.ui.control.Button
        CreateGSAERcsvButton           matlab.ui.control.Button
        TargetAERDataLabel             matlab.ui.control.Label
        TargetNumbertoViewEditFieldLabel  matlab.ui.control.Label
        TargetNumbertoViewEditField    matlab.ui.control.NumericEditField
        NumberofAccessestoTargetEditFieldLabel  matlab.ui.control.Label
        NumberofAccessestoTargetEditField  matlab.ui.control.NumericEditField
        AccesstoViewEditFieldLabel     matlab.ui.control.Label
        AccesstoViewEditField          matlab.ui.control.NumericEditField
        NoteLabel_2                    matlab.ui.control.Label
        ViewTargetAERDataPlotsButton   matlab.ui.control.Button
        CreateTargetAERcsvButton       matlab.ui.control.Button
        AccessAERGroundTrackLabel      matlab.ui.control.Label
        ViewTargetDopplerPlotsButton   matlab.ui.control.Button
        NominalSignalFrequencyhzEditFieldLabel  matlab.ui.control.Label
        NominalSignalFrequencyhzEditField  matlab.ui.control.NumericEditField
        CreateGSDopplercsvButton       matlab.ui.control.Button
        CreateTargetDopplercsvButton   matlab.ui.control.Button
        ViewGSDopplerPlotsButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Access2GroundStation = evalin('base', 'Access2GroundStation');
            Access2Targets = evalin('base', 'Access2Targets');
            AERfromGroundStationCell = evalin('base', 'AERfromGroundStationCell');
            StartDate = evalin('base', 'StartDate');
            Time = evalin('base', 'Time');
            Timestep = evalin('base', 'TimeStep');
            MinElevations = evalin('base', 'MinElevations');
            TargetsLLA = evalin('base', 'TargetsLLA');
            
            if ischar(Access2GroundStation.EpSec)
                app.NumberofAccessestoGroundStationEditField.Value = 0;
                app.NoteLabel.Text = "Note: No access to the ground station.";
                app.AccessNumberToViewEditField.Enable = 'off';
                app.ViewGSAERDataPlotsButton.Enable = 'off';
                app.CreateGSAERcsvButton.Enable = 'off';
            else
                app.NumberofAccessestoGroundStationEditField.Value = size(Access2GroundStation.EpSec,1);
            end
            app.MinGroundStationElevationUsedEditField.Value = MinElevations(1);
            
            if ischar(TargetsLLA)
                app.NumberofTargetsEditField.Value = 0;
                app.NoteLabel.Text = "Note: No targets.";
                app.TargetNumbertoViewEditField.Enable = 'off';
                app.NumberofAccessestoTargetEditField.Enable = 'off';
                app.AccesstoViewEditField.Enable = 'off';
                app.ViewTargetAERDataPlotsButton.Enable = 'off';
                app.CreateTargetAERcsvButton.Enable = 'off';
            else
                app.NumberofTargetsEditField.Value = size(TargetsLLA, 1);
                if ischar(Access2Targets.Target1)
                    app.NumberofAccessestoTargetEditField.Value = 0;
                    app.NoteLabel.Text = "Note: No access to this target.";
                    app.AccesstoViewEditField.Enable = 'off';
                    app.ViewTargetAERDataPlotsButton.Enable = 'off';
                    app.CreateTargetAERcsvButton.Enable = 'off';
                else
                    app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target1,1);
                end
            end
            app.MinTargetElevationUsedEditField.Value = MinElevations(2);           
        end

        % Button pushed function: ViewGSAERDataPlotsButton
        function ViewGSAERDataPlotsButtonPushed(app, event)
            AERfromGroundStationCell = evalin('base', 'AERfromGroundStationCell');
            PassNumber = app.AccessNumberToViewEditField.Value;
            Plot_AzElRange(AERfromGroundStationCell, PassNumber)
        end

        % Button pushed function: PlotGroundTrackButton
        function PlotGroundTrackButtonPushed(app, event)
            GroundStationLLA = evalin('base', 'GroundStationLLA');
            TargetsLLA = evalin('base', 'TargetsLLA');
            LatLongAlt = evalin('base', 'LatLongAlt');
            GroundTrackPlot(LatLongAlt, GroundStationLLA, TargetsLLA)
        end

        % Button pushed function: CreateLLAcsvFileButton
        function CreateLLAcsvFileButtonPushed(app, event)
            Time = evalin('base', 'Time');
            LatLongAlt = evalin('base', 'LatLongAlt');
            Latitude = LatLongAlt(:,1);
            Longitude = LatLongAlt(:,2);
            Altitude = LatLongAlt(:,3);
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, Latitude, Longitude, Altitude);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'Latitude', 'Longitude', 'AltitudeM'};
            writetable(TableA, filename)
        end

        % Button pushed function: CreateGSAERcsvButton
        function CreateGSAERcsvButtonPushed(app, event)
            PassNumber = app.AccessNumberToViewEditField.Value;
            AERfromGroundStationCell = evalin('base', 'AERfromGroundStationCell');
            AERDataForPass = AERfromGroundStationCell(PassNumber,1:6);
            TimeEpochSec = AERDataForPass{1,1};
            %TimeUTCG = AERDataForPass{1,6};
            %for k = 1 : length(TimeUTCG)
                %cellContents = TimeUTCG{k};
                % Truncate and stick back into the cell
                %TimeUTCG{k} = cellContents(1:end-10);
            %end
            Azimuth = AERDataForPass{1,2};
            Elevation = AERDataForPass{1,3};
            RangeM = AERDataForPass{1,4};
            RangeRateMpS = AERDataForPass{1,5};
            try
                filename = inputdlg('Enter name of .csv to create');
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeEpochSec, Azimuth, Elevation, RangeM, RangeRateMpS);
                writetable(TableA, filename)
            catch
            end
        end

        % Value changed function: TargetNumbertoViewEditField
        function TargetNumbertoViewEditFieldValueChanged(app, event)
            Access2Targets = evalin('base', 'Access2Targets');
            value = app.TargetNumbertoViewEditField.Value;
            
            if value > app.NumberofTargetsEditField.Value
                app.NoteLabel_2.Text = "Note: Please enter a valid target number.";
                app.AccesstoViewEditField.Enable = 'off';
                app.ViewTargetAERDataPlotsButton.Enable = 'off';
                app.CreateTargetAERcsvButton.Enable = 'off';
            else
                if value == 1
                    if ischar(Access2Targets.Target1)
                        app.NumberofAccessestoTargetEditField.Value = 0;
                        app.NoteLabel_2.Text = "Note: No access to this target.";
                        app.AccesstoViewEditField.Enable = 'off';
                        app.ViewTargetAERDataPlotsButton.Enable = 'off';
                        app.CreateTargetAERcsvButton.Enable = 'off';
                    else
                        app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target1,1);
                        app.NoteLabel_2.Text = "Note: ";
                        app.AccesstoViewEditField.Enable = 'on';
                        app.ViewTargetAERDataPlotsButton.Enable = 'on';
                        app.CreateTargetAERcsvButton.Enable = 'on';
                    end
                elseif value == 2
                    if ischar(Access2Targets.Target2)
                        app.NumberofAccessestoTargetEditField.Value = 0;
                        app.NoteLabel_2.Text = "Note: No access to this target.";
                        app.AccesstoViewEditField.Enable = 'off';
                        app.ViewTargetAERDataPlotsButton.Enable = 'off';
                        app.CreateTargetAERcsvButton.Enable = 'off';
                    else
                        app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target2,1);
                        app.NoteLabel_2.Text = "Note: ";
                        app.AccesstoViewEditField.Enable = 'on';
                        app.ViewTargetAERDataPlotsButton.Enable = 'on';
                        app.CreateTargetAERcsvButton.Enable = 'on';
                    end
                elseif value == 3
                    if ischar(Access2Targets.Target3)
                        app.NumberofAccessestoTargetEditField.Value = 0;
                        app.NoteLabel_2.Text = "Note: No access to this target.";
                        app.AccesstoViewEditField.Enable = 'off';
                        app.ViewTargetAERDataPlotsButton.Enable = 'off';
                        app.CreateTargetAERcsvButton.Enable = 'off';
                    else
                        app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target3,1);
                        app.NoteLabel_2.Text = "Note: ";
                        app.AccesstoViewEditField.Enable = 'on';
                        app.ViewTargetAERDataPlotsButton.Enable = 'on';
                        app.CreateTargetAERcsvButton.Enable = 'on';
                    end
                elseif value == 4
                    if ischar(Access2Targets.Target4)
                        app.NumberofAccessestoTargetEditField.Value = 0;
                        app.NoteLabel_2.Text = "Note: No access to this target.";
                        app.AccesstoViewEditField.Enable = 'off';
                        app.ViewTargetAERDataPlotsButton.Enable = 'off';
                        app.CreateTargetAERcsvButton.Enable = 'off';
                    else
                        app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target4,1);
                        app.NoteLabel_2.Text = "Note: ";
                        app.AccesstoViewEditField.Enable = 'on';
                        app.ViewTargetAERDataPlotsButton.Enable = 'on';
                        app.CreateTargetAERcsvButton.Enable = 'on';
                    end
                elseif value == 5
                    if ischar(Access2Targets.Target5)
                        app.NumberofAccessestoTargetEditField.Value = 0;
                        app.NoteLabel_2.Text = "Note: No access to this target.";
                        app.AccesstoViewEditField.Enable = 'off';
                        app.ViewTargetAERDataPlotsButton.Enable = 'off';
                        app.CreateTargetAERcsvButton.Enable = 'off';
                    else
                        app.NumberofAccessestoTargetEditField.Value = size(Access2Targets.Target5,1);
                        app.NoteLabel_2.Text = "Note: ";
                        app.AccesstoViewEditField.Enable = 'on';
                        app.ViewTargetAERDataPlotsButton.Enable = 'on';
                        app.CreateTargetAERcsvButton.Enable = 'on';
                    end
                end
            end
        end

        % Value changed function: AccessNumberToViewEditField
        function AccessNumberToViewEditFieldValueChanged(app, event)
            PassNumber = app.AccessNumberToViewEditField.Value;
            Access2GroundStation = evalin('base', 'Access2GroundStation');
            if PassNumber > size(Access2GroundStation.EpSec,1)
                app.NoteLabel.Text = "Note: Please Select a valid Pass Number to view.";
                app.ViewGSAERDataPlotsButton.Enable = 'off';
                app.CreateGSAERcsvButton.Enable = 'off';
            else
                app.NoteLabel.Text = "Note: ";
                app.ViewGSAERDataPlotsButton.Enable = 'on';
                app.CreateGSAERcsvButton.Enable = 'on';
            end
        end

        % Value changed function: AccesstoViewEditField
        function AccesstoViewEditFieldValueChanged(app, event)
            value = app.AccesstoViewEditField.Value;
            target = app.TargetNumbertoViewEditField.Value;
            Access2Targets = evalin('base', 'Access2Targets');
            
            if target == 1
                targetacceses = Access2Targets.Target1;
            elseif target == 2
                targetacceses = Access2Targets.Target2;
            elseif target == 3
                targetacceses = Access2Targets.Target3;
            elseif target == 4
                targetacceses = Access2Targets.Target4;
            elseif target == 5
                targetacceses = Access2Targets.Target5;
            end
            if value > size(targetacceses, 1)
                app.NoteLabel_2.Text = "Note: Please Select a valid Pass Number to view.";
                app.ViewTargetAERDataPlotsButton.Enable = 'off';
                app.CreateTargetAERcsvButton.Enable = 'off';
            else
                app.NoteLabel_2.Text = "Note: ";
                app.ViewTargetAERDataPlotsButton.Enable = 'on';
                app.CreateTargetAERcsvButton.Enable = 'on';
            end
        end

        % Button pushed function: ViewTargetAERDataPlotsButton
        function ViewTargetAERDataPlotsButtonPushed(app, event)
            AERfromTargets = evalin('base', 'AERfromTargets');
            Target = app.TargetNumbertoViewEditField.Value;
            if Target == 1
                AERdata = AERfromTargets.Target1;
            elseif Target == 2
                AERdata = AERfromTargets.Target2;
            elseif Target == 3
                AERdata = AERfromTargets.Target3;
            elseif Target == 4
                AERdata = AERfromTargets.Target4;
            elseif Target == 5
                AERdata = AERfromTargets.Target5;
            end
            
            PassNumber = app.AccesstoViewEditField.Value;
            Plot_AzElRange(AERdata, PassNumber)
        end

        % Button pushed function: CreateTargetAERcsvButton
        function CreateTargetAERcsvButtonPushed(app, event)
            PassNumber = app.AccesstoViewEditField.Value;
            AERfromTargets = evalin('base', 'AERfromTargets');
            Target = app.TargetNumbertoViewEditField.Value;
            if Target == 1
                AERdata = AERfromTargets.Target1;
            elseif Target == 2
                AERdata = AERfromTargets.Target2;
            elseif Target == 3
                AERdata = AERfromTargets.Target3;
            elseif Target == 4
                AERdata = AERfromTargets.Target4;
            elseif Target == 5
                AERdata = AERfromTargets.Target5;
            end
            AERDataForPass = AERdata(PassNumber,1:5);
            TimeEpochSec = AERDataForPass{1,1};
            Azimuth = AERDataForPass{1,2};
            Elevation = AERDataForPass{1,3};
            RangeM = AERDataForPass{1,4};
            RangeRateMpS = AERDataForPass{1,5};
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeEpochSec, Azimuth, Elevation, RangeM, RangeRateMpS);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: ViewTargetDopplerPlotsButton
        function ViewTargetDopplerPlotsButtonPushed(app, event)
            AERfromTargets = evalin('base', 'AERfromTargets');
            Target = app.TargetNumbertoViewEditField.Value;
            if Target == 1
                AERdata = AERfromTargets.Target1;
            elseif Target == 2
                AERdata = AERfromTargets.Target2;
            elseif Target == 3
                AERdata = AERfromTargets.Target3;
            elseif Target == 4
                AERdata = AERfromTargets.Target4;
            elseif Target == 5
                AERdata = AERfromTargets.Target5;
            end
            
            PassNumber = app.AccesstoViewEditField.Value;
            NominalFreq = app.NominalSignalFrequencyhzEditField.Value;
            Plot_Doppler(NominalFreq, AERdata, PassNumber)
        end

        % Button pushed function: CreateGSDopplercsvButton
        function CreateGSDopplercsvButtonPushed(app, event)
            PassNumber = app.AccesstoViewEditField.Value;
            
            AERfromGroundStationCell = evalin('base', 'AERfromGroundStationCell');
            AERDataForPass = AERfromGroundStationCell(PassNumber,1:6);
            TimeEpochSec = AERDataForPass{1,1};
            RangeM = AERDataForPass{1,4};
            RangeRateMpS = AERDataForPass{1,5};
            
            c = 299792458; % Speed of light in m/s
            
            NominalFreq = app.NominalSignalFrequencyhzEditField.Value;
            ObservedFreq = (c./(c+RangeRateData))*NominalFreq;
            DopplerShift = ObservedFreq-NominalFreq;
            
            
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeEpochSec, DopplerShift, ObservedFreq, RangeM, RangeRateMpS);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: CreateTargetDopplercsvButton
        function CreateTargetDopplercsvButtonPushed(app, event)
            PassNumber = app.AccesstoViewEditField.Value;
            AERfromTargets = evalin('base', 'AERfromTargets');
            Target = app.TargetNumbertoViewEditField.Value;
            if Target == 1
                AERdata = AERfromTargets.Target1;
            elseif Target == 2
                AERdata = AERfromTargets.Target2;
            elseif Target == 3
                AERdata = AERfromTargets.Target3;
            elseif Target == 4
                AERdata = AERfromTargets.Target4;
            elseif Target == 5
                AERdata = AERfromTargets.Target5;
            end
            AERDataForPass = AERdata(PassNumber,1:5);
            TimeEpochSec = AERDataForPass{1,1};
            RangeM = AERDataForPass{1,4};
            RangeRateMpS = AERDataForPass{1,5};
            
            c = 299792458; % Speed of light in m/s
            
            NominalFreq = app.NominalSignalFrequencyhzEditField.Value;
            ObservedFreq = (c./(c+RangeRateData))*NominalFreq;
            DopplerShift = ObservedFreq-NominalFreq;
            
            
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeEpochSec, DopplerShift, ObservedFreq, RangeM, RangeRateMpS);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: ViewGSDopplerPlotsButton
        function ViewGSDopplerPlotsButtonPushed(app, event)
            AERfromGroundStationCell = evalin('base', 'AERfromGroundStationCell');
            PassNumber = app.AccessNumberToViewEditField.Value;
            NominalFreq = app.NominalSignalFrequencyhzEditField.Value;
            Plot_Doppler(NominalFreq, AERfromGroundStationCell, PassNumber)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofAccessestoGroundStationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAccessestoGroundStationEditFieldLabel.Text = 'Number of Accesses to Ground Station';
            app.NumberofAccessestoGroundStationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAccessestoGroundStationEditField.Editable = 'off';
            app.MinGroundStationElevationUsedEditFieldLabel = uilabel(app.UIFigure);
            app.MinGroundStationElevationUsedEditFieldLabel.Text = 'Min Ground Station Elevation Used';
            app.MinGroundStationElevationUsedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinGroundStationElevationUsedEditField.Editable = 'off';
            app.AccessNumberToViewEditFieldLabel = uilabel(app.UIFigure);
            app.AccessNumberToViewEditFieldLabel.Text = 'Access Number To View';
            app.AccessNumberToViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccessNumberToViewEditField.Limits = [1 Inf];
            app.AccessNumberToViewEditField.ValueChangedFcn = createCallbackFcn(app, @AccessNumberToViewEditFieldValueChanged, true);
            app.AccessNumberToViewEditField.Value = 1;
            app.GroundStationAERDataLabel = uilabel(app.UIFigure);
            app.GroundStationAERDataLabel.Text = 'Ground Station AER Data';
            app.ViewGSAERDataPlotsButton = uibutton(app.UIFigure, 'push');
            app.ViewGSAERDataPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGSAERDataPlotsButtonPushed, true);
            app.ViewGSAERDataPlotsButton.Text = 'View GS AER Data Plots';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note:';
            app.NumberofTargetsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTargetsEditFieldLabel.Text = 'Number of Targets';
            app.NumberofTargetsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTargetsEditField.Editable = 'off';
            app.MinTargetElevationUsedEditFieldLabel = uilabel(app.UIFigure);
            app.MinTargetElevationUsedEditFieldLabel.Text = 'Min Target Elevation Used';
            app.MinTargetElevationUsedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinTargetElevationUsedEditField.Editable = 'off';
            app.PlotGroundTrackButton = uibutton(app.UIFigure, 'push');
            app.PlotGroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTrackButtonPushed, true);
            app.PlotGroundTrackButton.Text = 'Plot Ground Track';
            app.CreateLLAcsvFileButton = uibutton(app.UIFigure, 'push');
            app.CreateLLAcsvFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLLAcsvFileButtonPushed, true);
            app.CreateLLAcsvFileButton.Text = 'Create LLA .csv File';
            app.CreateGSAERcsvButton = uibutton(app.UIFigure, 'push');
            app.CreateGSAERcsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGSAERcsvButtonPushed, true);
            app.CreateGSAERcsvButton.Text = 'Create GS AER .csv';
            app.TargetAERDataLabel = uilabel(app.UIFigure);
            app.TargetAERDataLabel.Text = 'Target AER Data';
            app.TargetNumbertoViewEditFieldLabel = uilabel(app.UIFigure);
            app.TargetNumbertoViewEditFieldLabel.Text = 'Target Number to View';
            app.TargetNumbertoViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetNumbertoViewEditField.Limits = [1 5];
            app.TargetNumbertoViewEditField.ValueChangedFcn = createCallbackFcn(app, @TargetNumbertoViewEditFieldValueChanged, true);
            app.TargetNumbertoViewEditField.Value = 1;
            app.NumberofAccessestoTargetEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAccessestoTargetEditFieldLabel.Text = 'Number of Accesses to Target';
            app.NumberofAccessestoTargetEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAccessestoTargetEditField.Editable = 'off';
            app.AccesstoViewEditFieldLabel = uilabel(app.UIFigure);
            app.AccesstoViewEditFieldLabel.Text = 'Access to View';
            app.AccesstoViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccesstoViewEditField.Limits = [1 Inf];
            app.AccesstoViewEditField.ValueChangedFcn = createCallbackFcn(app, @AccesstoViewEditFieldValueChanged, true);
            app.AccesstoViewEditField.Value = 1;
            app.NoteLabel_2 = uilabel(app.UIFigure);
            app.NoteLabel_2.Text = 'Note:';
            app.ViewTargetAERDataPlotsButton = uibutton(app.UIFigure, 'push');
            app.ViewTargetAERDataPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTargetAERDataPlotsButtonPushed, true);
            app.ViewTargetAERDataPlotsButton.Text = 'View Target AER Data Plots';
            app.CreateTargetAERcsvButton = uibutton(app.UIFigure, 'push');
            app.CreateTargetAERcsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTargetAERcsvButtonPushed, true);
            app.CreateTargetAERcsvButton.Text = 'Create Target AER .csv';
            app.AccessAERGroundTrackLabel = uilabel(app.UIFigure);
            app.AccessAERGroundTrackLabel.Text = 'Access / AER / GroundTrack';
            app.ViewTargetDopplerPlotsButton = uibutton(app.UIFigure, 'push');
            app.ViewTargetDopplerPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTargetDopplerPlotsButtonPushed, true);
            app.ViewTargetDopplerPlotsButton.Text = 'View Target Doppler Plots';
            app.NominalSignalFrequencyhzEditFieldLabel = uilabel(app.UIFigure);
            app.NominalSignalFrequencyhzEditFieldLabel.Text = 'Nominal Signal Frequency (hz)';
            app.NominalSignalFrequencyhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.NominalSignalFrequencyhzEditField.Limits = [0 Inf];
            app.CreateGSDopplercsvButton = uibutton(app.UIFigure, 'push');
            app.CreateGSDopplercsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGSDopplercsvButtonPushed, true);
            app.CreateGSDopplercsvButton.Text = 'Create GS Doppler .csv';
            app.CreateTargetDopplercsvButton = uibutton(app.UIFigure, 'push');
            app.CreateTargetDopplercsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTargetDopplercsvButtonPushed, true);
            app.CreateTargetDopplercsvButton.Text = 'Create Target Doppler .csv';
            app.ViewGSDopplerPlotsButton = uibutton(app.UIFigure, 'push');
            app.ViewGSDopplerPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGSDopplerPlotsButtonPushed, true);
            app.ViewGSDopplerPlotsButton.Text = 'View GS Doppler Plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AccessPassesGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end