classdef AnalysisTimePeriodGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        YearStartEditFieldLabel     matlab.ui.control.Label
        YearStartEditField          matlab.ui.control.NumericEditField
        DayStartEditFieldLabel      matlab.ui.control.Label
        DayStartEditField           matlab.ui.control.NumericEditField
        MonthStartDropDownLabel     matlab.ui.control.Label
        MonthStartDropDown          matlab.ui.control.DropDown
        HourStartDropDownLabel      matlab.ui.control.Label
        HourStartDropDown           matlab.ui.control.DropDown
        MinutesStartDropDownLabel   matlab.ui.control.Label
        MinutesStartDropDown        matlab.ui.control.DropDown
        SecondsStartEditFieldLabel  matlab.ui.control.Label
        SecondsStartEditField       matlab.ui.control.NumericEditField
        AnalysisTimePeriodandTimeStepLabel  matlab.ui.control.Label
        ScenarioStartTimeLabel      matlab.ui.control.Label
        EnterButton                 matlab.ui.control.Button
        YearEndEditFieldLabel       matlab.ui.control.Label
        YearEndEditField            matlab.ui.control.NumericEditField
        DayEndEditFieldLabel        matlab.ui.control.Label
        DayEndEditField             matlab.ui.control.NumericEditField
        MonthEndDropDownLabel       matlab.ui.control.Label
        MonthEndDropDown            matlab.ui.control.DropDown
        HourEndDropDownLabel        matlab.ui.control.Label
        HourEndDropDown             matlab.ui.control.DropDown
        MinutesEndDropDownLabel     matlab.ui.control.Label
        MinutesEndDropDown          matlab.ui.control.DropDown
        SecondsEndEditFieldLabel    matlab.ui.control.Label
        SecondsEndEditField         matlab.ui.control.NumericEditField
        EndTimeorDuration           matlab.ui.control.RockerSwitch
        DayEditFieldLabel           matlab.ui.control.Label
        DayEditField                matlab.ui.control.NumericEditField
        HourDropDownLabel           matlab.ui.control.Label
        HourDropDown                matlab.ui.control.DropDown
        MinutesDropDownLabel        matlab.ui.control.Label
        MinutesDropDown             matlab.ui.control.DropDown
        SecondsEditFieldLabel       matlab.ui.control.Label
        SecondsEditField            matlab.ui.control.NumericEditField
        TimeStepsEditFieldLabel     matlab.ui.control.Label
        TimeStepsEditField          matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: EndTimeorDuration
        function EndTimeorDurationValueChanged(app, event)
            value = app.EndTimeorDuration.Value;
            if value == 'Scenario End Time'
                app.DayEditField.Enable = 'off';
                app.HourDropDown.Enable = 'off';
                app.MinutesDropDown.Enable = 'off';
                app.SecondsEditField.Enable = 'off';
                app.YearEndEditField.Enable = 'on';
                app.MonthEndDropDown.Enable = 'on';
                app.DayEndEditField.Enable = 'on';
                app.HourEndDropDown.Enable = 'on';
                app.MinutesEndDropDown.Enable = 'on';
                app.SecondsEndEditField.Enable = 'on';   
            end
                
            if value == 'Scenario Duration'
                app.DayEditField.Enable = 'on';
                app.HourDropDown.Enable = 'on';
                app.MinutesDropDown.Enable = 'on';
                app.SecondsEditField.Enable = 'on';
                app.YearEndEditField.Enable = 'off';
                app.MonthEndDropDown.Enable = 'off';
                app.DayEndEditField.Enable = 'off';
                app.HourEndDropDown.Enable = 'off';
                app.MinutesEndDropDown.Enable = 'off';
                app.SecondsEndEditField.Enable = 'off';
            end
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
            assignin('base', 'TimeStep', app.TimeStepsEditField.Value)
            
            YS = string(app.YearStartEditField.Value);
            MoS = string(app.MonthStartDropDown.Value);
            DS = string(app.DayStartEditField.Value);
            HS = string(app.HourStartDropDown.Value);
            MiS = string(app.MinutesStartDropDown.Value);
            SS = string(app.SecondsStartEditField.Value);
            sp = " ";
            co = ":";
            assignin('base', 'StartDate', char(strcat(DS,sp,MoS,sp,YS,sp,HS,co,MiS,co,SS)))
            
            
            if strcmp(app.EndTimeorDuration.Value, 'Scenario End Time')
                YE = string(app.YearEndEditField.Value);
                MoE = string(app.MonthEndDropDown.Value);
                DE = string(app.DayEndEditField.Value);
                HE = string(app.HourEndDropDown.Value);
                MiE = string(app.MinutesEndDropDown.Value);
                SE = string(app.SecondsEndEditField.Value);
                sp = " ";
                co = ":";
                assignin('base', 'EndDate', char(strcat(DE,sp,MoE,sp,YE,sp,HE,co,MiE,co,SE)))
                assignin('base', 'EndTimeOrDurationValue', 0)
            end 
            if strcmp(app.EndTimeorDuration.Value, 'Scenario Duration')
                Days = app.DayEditField.Value;
                assignin('base', 'Days', app.DayEditField.Value)
                Hours = str2double(app.HourDropDown.Value);
                assignin('base', 'Hours', str2double(app.HourDropDown.Value))
                Minutes = str2double(app.MinutesDropDown.Value);
                assignin('base', 'Minutes', str2double(app.MinutesDropDown.Value))
                Seconds = app.SecondsEditField.Value;
                assignin('base', 'Seconds', app.SecondsEditField.Value)
                EndTimeSeconds = string(((Days*24+Hours)*60+Minutes)*60+Seconds);
                sp = " ";
                pl = "+";
                se = "sec";
                assignin('base', 'EndDate', char(strcat(pl,EndTimeSeconds,sp,se)))
                assignin('base', 'EndTimeOrDurationValue', 1)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.YearStartEditFieldLabel = uilabel(app.UIFigure);
            app.YearStartEditFieldLabel.Text = 'Year, Start';
            app.YearStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearStartEditField.Value = 2018;
            app.DayStartEditFieldLabel = uilabel(app.UIFigure);
            app.DayStartEditFieldLabel.Text = 'Day, Start';
            app.DayStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayStartEditField.Value = 1;
            app.MonthStartDropDownLabel = uilabel(app.UIFigure);
            app.MonthStartDropDownLabel.Text = 'Month, Start ';
            app.MonthStartDropDown = uidropdown(app.UIFigure);
            app.MonthStartDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthStartDropDown.Value = 'January';
            app.HourStartDropDownLabel = uilabel(app.UIFigure);
            app.HourStartDropDownLabel.Text = 'Hour, Start';
            app.HourStartDropDown = uidropdown(app.UIFigure);
            app.HourStartDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.HourStartDropDown.Value = '0';
            app.MinutesStartDropDownLabel = uilabel(app.UIFigure);
            app.MinutesStartDropDownLabel.Text = 'Minutes, Start';
            app.MinutesStartDropDown = uidropdown(app.UIFigure);
            app.MinutesStartDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59'};
            app.MinutesStartDropDown.Value = '0';
            app.SecondsStartEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsStartEditFieldLabel.Text = 'Seconds, Start';
            app.SecondsStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalysisTimePeriodandTimeStepLabel = uilabel(app.UIFigure);
            app.AnalysisTimePeriodandTimeStepLabel.Text = 'Analysis Time Period and Time Step';
            app.ScenarioStartTimeLabel = uilabel(app.UIFigure);
            app.ScenarioStartTimeLabel.Text = 'Scenario Start Time';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.YearEndEditFieldLabel = uilabel(app.UIFigure);
            app.YearEndEditFieldLabel.Enable = 'off';
            app.YearEndEditFieldLabel.Text = 'Year, End';
            app.YearEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearEndEditField.Enable = 'off';
            app.YearEndEditField.Value = 2018;
            app.DayEndEditFieldLabel = uilabel(app.UIFigure);
            app.DayEndEditFieldLabel.Enable = 'off';
            app.DayEndEditFieldLabel.Text = 'Day, End';
            app.DayEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEndEditField.Enable = 'off';
            app.DayEndEditField.Value = 2;
            app.MonthEndDropDownLabel = uilabel(app.UIFigure);
            app.MonthEndDropDownLabel.Enable = 'off';
            app.MonthEndDropDownLabel.Text = 'Month, End';
            app.MonthEndDropDown = uidropdown(app.UIFigure);
            app.MonthEndDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthEndDropDown.Enable = 'off';
            app.MonthEndDropDown.Value = 'January';
            app.HourEndDropDownLabel = uilabel(app.UIFigure);
            app.HourEndDropDownLabel.Enable = 'off';
            app.HourEndDropDownLabel.Text = 'Hour, End';
            app.HourEndDropDown = uidropdown(app.UIFigure);
            app.HourEndDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.HourEndDropDown.Enable = 'off';
            app.HourEndDropDown.Value = '0';
            app.MinutesEndDropDownLabel = uilabel(app.UIFigure);
            app.MinutesEndDropDownLabel.Enable = 'off';
            app.MinutesEndDropDownLabel.Text = 'Minutes, End';
            app.MinutesEndDropDown = uidropdown(app.UIFigure);
            app.MinutesEndDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59'};
            app.MinutesEndDropDown.Enable = 'off';
            app.MinutesEndDropDown.Value = '0';
            app.SecondsEndEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsEndEditFieldLabel.Enable = 'off';
            app.SecondsEndEditFieldLabel.Text = 'Seconds, End';
            app.SecondsEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsEndEditField.Enable = 'off';
            app.EndTimeorDuration = uiswitch(app.UIFigure, 'rocker');
            app.EndTimeorDuration.Items = {'Scenario End Time', 'Scenario Duration'};
            app.EndTimeorDuration.ValueChangedFcn = createCallbackFcn(app, @EndTimeorDurationValueChanged, true);
            app.EndTimeorDuration.Value = 'Scenario Duration';
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEditField.Limits = [0 Inf];
            app.HourDropDownLabel = uilabel(app.UIFigure);
            app.HourDropDownLabel.Text = 'Hour';
            app.HourDropDown = uidropdown(app.UIFigure);
            app.HourDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.HourDropDown.Value = '6';
            app.MinutesDropDownLabel = uilabel(app.UIFigure);
            app.MinutesDropDownLabel.Text = 'Minutes';
            app.MinutesDropDown = uidropdown(app.UIFigure);
            app.MinutesDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59'};
            app.MinutesDropDown.Value = '0';
            app.SecondsEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsEditFieldLabel.Text = 'Seconds';
            app.SecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsEditField.Limits = [0 Inf];
            app.TimeStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepsEditFieldLabel.Text = 'Time Step (s)';
            app.TimeStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepsEditField.Value = 60;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Note: This time step determines granularity of simulated data outside of';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'access and pass data. ex. power, thermal, imaging, LLA, etc...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AnalysisTimePeriodGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end