classdef ComponentsPowerUsageGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterButton                     matlab.ui.control.Button
        ComponentSystemsPowerUsageLabel  matlab.ui.control.Label
        AttitudeControlEditFieldLabel   matlab.ui.control.Label
        AttitudeControlLowEditField     matlab.ui.control.NumericEditField
        AttitudeDeterminationEditFieldLabel  matlab.ui.control.Label
        AttitudeDeterminationLowEditField  matlab.ui.control.NumericEditField
        HealthMonitoringEditFieldLabel  matlab.ui.control.Label
        HealthMonitoringLowEditField    matlab.ui.control.NumericEditField
        ComputingEditFieldLabel         matlab.ui.control.Label
        ComputingLowEditField           matlab.ui.control.NumericEditField
        CommunicationsEditFieldLabel    matlab.ui.control.Label
        CommunicationsLowEditField      matlab.ui.control.NumericEditField
        AdditionalEditFieldLabel        matlab.ui.control.Label
        AdditionalLowEditField          matlab.ui.control.NumericEditField
        AttitudeControlEffEditField     matlab.ui.control.NumericEditField
        AttitudeDeterminationEffEditField  matlab.ui.control.NumericEditField
        HealthMonitoringEffEditField    matlab.ui.control.NumericEditField
        ComputingEffEditField           matlab.ui.control.NumericEditField
        CommunicationsEffEditField      matlab.ui.control.NumericEditField
        AdditionalEffEditField          matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        PassiveLowLabel                 matlab.ui.control.Label
        AttitudeControlHighEditField    matlab.ui.control.NumericEditField
        AttitudeDeterminationHighEditField  matlab.ui.control.NumericEditField
        HealthMonitoringHighEditField   matlab.ui.control.NumericEditField
        ComputingHighEditField          matlab.ui.control.NumericEditField
        CommunicationsHighEditField     matlab.ui.control.NumericEditField
        AdditionalHighEditField         matlab.ui.control.NumericEditField
        ActiveHighLabel                 matlab.ui.control.Label
        AttitudeControlFractionEditField  matlab.ui.control.NumericEditField
        AttitudeDeterminationFractionEditField  matlab.ui.control.NumericEditField
        HealthMonitoringFractionEditField  matlab.ui.control.NumericEditField
        ComputingFractionEditField      matlab.ui.control.NumericEditField
        CommunicationsFractionEditField  matlab.ui.control.NumericEditField
        AdditionalFractionEditField     matlab.ui.control.NumericEditField
        FractionofLabel                 matlab.ui.control.Label
        AttitudeControlDutyEditField    matlab.ui.control.NumericEditField
        AttitudeDeterminationDutyEditField  matlab.ui.control.NumericEditField
        HealthMonitoringDutyEditField   matlab.ui.control.NumericEditField
        ComputingDutyEditField          matlab.ui.control.NumericEditField
        CommunicationsDutyEditField     matlab.ui.control.NumericEditField
        AdditionalDutyEditField         matlab.ui.control.NumericEditField
        DutyCycleLabel                  matlab.ui.control.Label
        AttitudeControlPowerMethodSwitch  matlab.ui.control.RockerSwitch
        PowerWLabel                     matlab.ui.control.Label
        PowerWLabel_2                   matlab.ui.control.Label
        TimeActiveLabel                 matlab.ui.control.Label
        TimesLabel                      matlab.ui.control.Label
        PowerusageLabel                 matlab.ui.control.Label
        AttitudeDeterminationPowerMethodSwitch  matlab.ui.control.RockerSwitch
        HealthMonitoringPowerMethodSwitch  matlab.ui.control.RockerSwitch
        ComputingPowerMethodSwitch      matlab.ui.control.RockerSwitch
        CommunicationsPowerMethodSwitch  matlab.ui.control.RockerSwitch
        AdditionalPowerMethodSwitch     matlab.ui.control.RockerSwitch
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel  matlab.ui.control.Label
        SeebelowforexplanationofoptionstermsLabel  matlab.ui.control.Label
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_2  matlab.ui.control.Label
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_3  matlab.ui.control.Label
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_4  matlab.ui.control.Label
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_5  matlab.ui.control.Label
        AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_6  matlab.ui.control.Label
        calculationmethodLabel          matlab.ui.control.Label
        AttitudeControlNormEditField    matlab.ui.control.NumericEditField
        AttitudeDeterminationNormEditField  matlab.ui.control.NumericEditField
        HealthMonitoringNormEditField   matlab.ui.control.NumericEditField
        ComputingNormEditField          matlab.ui.control.NumericEditField
        CommunicationsNormEditField     matlab.ui.control.NumericEditField
        AdditionalNormEditField         matlab.ui.control.NumericEditField
        NormAvgLabel                    matlab.ui.control.Label
        PowerWLabel_3                   matlab.ui.control.Label
        WhichpowersettingtouseduringLabel  matlab.ui.control.Label
        GSAccessLabel                   matlab.ui.control.Label
        TargetAccessLabel               matlab.ui.control.Label
        AllOtherTimesLabel              matlab.ui.control.Label
        AttitudeControlGSDropDown       matlab.ui.control.DropDown
        AttitudeDeterminationGSDropDown  matlab.ui.control.DropDown
        HealthMonitoringGSDropDown      matlab.ui.control.DropDown
        ComputingGSDropDown             matlab.ui.control.DropDown
        CommunicationsGSDropDown        matlab.ui.control.DropDown
        AdditionalGSDropDown            matlab.ui.control.DropDown
        AttitudeControlTargetDropDown   matlab.ui.control.DropDown
        AttitudeDeterminationTargetDropDown  matlab.ui.control.DropDown
        HealthMonitoringTargetDropDown  matlab.ui.control.DropDown
        ComputingTargetDropDown         matlab.ui.control.DropDown
        CommunicationsTargetDropDown    matlab.ui.control.DropDown
        AdditionalTargetDropDown        matlab.ui.control.DropDown
        AttitudeControlDropDown         matlab.ui.control.DropDown
        AttitudeDeterminationDropDown   matlab.ui.control.DropDown
        HealthMonitoringDropDown        matlab.ui.control.DropDown
        ComputingDropDown               matlab.ui.control.DropDown
        CommunicationsDropDown          matlab.ui.control.DropDown
        AdditionalDropDown              matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)

            LowPower = [app.AttitudeControlLowEditField.Value;app.AttitudeDeterminationLowEditField.Value;app.HealthMonitoringLowEditField.Value;app.ComputingLowEditField.Value;app.CommunicationsLowEditField.Value;app.AttitudeControlLowEditField.Value];
            %assignin('base', 'LowPower', LowPower)
            
            HighPower = [app.AttitudeControlHighEditField.Value;app.AttitudeDeterminationHighEditField.Value;app.HealthMonitoringHighEditField.Value;app.ComputingHighEditField.Value;app.CommunicationsHighEditField.Value;app.AttitudeControlHighEditField.Value];
            %assignin('base', 'HighPower', HighPower)
            
            NormPower = [app.AttitudeControlNormEditField.Value;app.AttitudeDeterminationNormEditField.Value;app.HealthMonitoringNormEditField.Value;app.ComputingNormEditField.Value;app.CommunicationsNormEditField.Value;app.AttitudeControlNormEditField.Value];
            %assignin('base', 'NormPower', NormPower)
            
            Eff = [app.AttitudeControlEffEditField.Value;app.AttitudeDeterminationEffEditField.Value;app.HealthMonitoringEffEditField.Value;app.ComputingEffEditField.Value;app.CommunicationsEffEditField.Value;app.AttitudeControlEffEditField.Value];
            %assignin('base', 'Eff', Eff)
            
            AttCtrlGS = convertCharsToStrings(app.AttitudeControlGSDropDown.Value);
            AttDetGS = convertCharsToStrings(app.AttitudeDeterminationGSDropDown.Value);
            HealthGS = convertCharsToStrings(app.HealthMonitoringGSDropDown.Value);
            CompGS = convertCharsToStrings(app.ComputingGSDropDown.Value);
            CommGS = convertCharsToStrings(app.CommunicationsGSDropDown.Value);
            AddGS = convertCharsToStrings(app.AttitudeControlGSDropDown.Value);
            GSPower = [AttCtrlGS;AttDetGS;HealthGS;CompGS;CommGS;AddGS];
            %assignin('base', 'GSPower', GSPower)
            
            AttCtrlTarget = convertCharsToStrings(app.AttitudeControlTargetDropDown.Value);
            AttDetTarget = convertCharsToStrings(app.AttitudeDeterminationTargetDropDown.Value);
            HealthTarget = convertCharsToStrings(app.HealthMonitoringTargetDropDown.Value);
            CompTarget = convertCharsToStrings(app.ComputingTargetDropDown.Value);
            CommTarget = convertCharsToStrings(app.CommunicationsTargetDropDown.Value);
            AddTarget = convertCharsToStrings(app.AttitudeControlTargetDropDown.Value);
            TargetPower = [AttCtrlTarget;AttDetTarget;HealthTarget;CompTarget;CommTarget;AddTarget];
            %assignin('base', 'TargetPower', TargetPower)
            
            AttCtrlAll = convertCharsToStrings(app.AttitudeControlDropDown.Value);
            AttDetAll = convertCharsToStrings(app.AttitudeDeterminationDropDown.Value);
            HealthAll = convertCharsToStrings(app.HealthMonitoringDropDown.Value);
            CompAll = convertCharsToStrings(app.ComputingDropDown.Value);
            CommAll = convertCharsToStrings(app.CommunicationsDropDown.Value);
            AddAll = convertCharsToStrings(app.AttitudeControlDropDown.Value);
            AllPower = [AttCtrlAll;AttDetAll;HealthAll;CompAll;CommAll;AddAll];
            %assignin('base', 'AllPower', AllPower)
            
            Time = evalin('base', 'Time');
            TimeStep = evalin('base', 'TimeStep');
            TargetsLLA = evalin('base', 'TargetsLLA');
            GroundStationLLA = evalin('base', 'GroundStationLLA');
            Access2GroundStation = evalin('base', 'Access2GroundStation');
            Access2Targets = evalin('base', 'Access2Targets');
            
            [EnergyUsePerComponent, EnergyUseTotal, HeatGenerationPerComponent, HeatGenerationTotal]...
                = DetermineComponentPowerHistory(LowPower, HighPower, NormPower, Eff, GSPower, TargetPower, ...
                AllPower, Time, TargetsLLA, GroundStationLLA, Access2GroundStation, Access2Targets);
            
            %{
            for i = 1:size(LowPower,1)
                
                if strcmp(GSPower(i), "High")
                    GSPowerUsage(i,1) = HighPower(i,1);
                elseif strcmp(GSPower(i), "Low")
                    GSPowerUsage(i,1) = LowPower(i,1);
                else
                    GSPowerUsage(i,1) = NormPower(i,1);
                end
            
                if strcmp(TargetPower(i), "High")
                    TargetPowerUsage(i,1) = HighPower(i,1);
                elseif strcmp(GSPower(i), "Low")
                    TargetPowerUsage(i,1) = LowPower(i,1);
                else
                    TargetPowerUsage(i,1) = NormPower(i,1);
                end
            
                if strcmp(AllPower(i), "High")
                    AllPowerUsage(i,1) = HighPower(i,1);
                elseif strcmp(AllPower(i), "Low")
                    AllPowerUsage(i,1) = LowPower(i,1);
                else
                    AllPowerUsage(i,1) = NormPower(i,1);
                end

            end
            
            assignin('base', 'GSPowerUsage', GSPowerUsage)
            assignin('base', 'TargetPowerUsage', TargetPowerUsage)
            assignin('base', 'AllPowerUsage', AllPowerUsage)
            
            
            TimeStep = evalin('base', 'TimeStep');
            Time = evalin('base', 'Time');
            TargetsLLA = evalin('base', 'TargetsLLA');
            if isstring(TargetsLLA) || ischar(TargetsLLA)
                NumOfTargets = 0;
            else
                NumOfTargets = size(TargetsLLA, 1);
            end
            GroundStationLLA = evalin('base', 'GroundStationLLA');
            if isstring(GroundStationLLA) || ischar(GroundStationLLA)
                NumOfGS = 0;
            else
                NumOfGS = size(GroundStationLLA, 1);
            end
            Access2GroundStation = evalin('base', 'Access2GroundStation');
            Access2Targets = evalin('base', 'Access2Targets');
            % Access2GroundStationEpSec = Access2GroundStation.EpSec;
            
            switch NumOfTargets
                case 0
                    TargetAccessTimes = [];
                case 1
                    TargetAccessTimes = [Access2Targets.Target1(:,1:2)];
                case 2
                    TargetAccessTimes = [Access2Targets.Target1(:,1:2); Access2Targets.Target2(:,1:2)];
                case 3
                    TargetAccessTimes = [Access2Targets.Target1(:,1:2); Access2Targets.Target2(:,1:2), Access2Targets.Target3(:,1:2)];
                case 4
                    TargetAccessTimes = [Access2Targets.Target1(:,1:2); Access2Targets.Target2(:,1:2), Access2Targets.Target3(:,1:2),...
                        Access2Targets.Target4(:,1:2)];
                case 5
                    TargetAccessTimes = [Access2Targets.Target1(:,1:2); Access2Targets.Target2(:,1:2), Access2Targets.Target3(:,1:2),...
                        Access2Targets.Target4(:,1:2), Access2Targets.Target5(:,1:2)];
            end
            
            switch NumOfGS
                case 0
                    GSAccessTimes = [];
                case 1
                    GSAccessTimes = [Access2GroundStation.EpSec(:,1:2)];
            end


            CurrentGSAccess = zeros(length(Time), 1);
            CurrentTargetAccess = zeros(length(Time), 1);


            for i = 1:length(Time)
                CurrentTime = Time(i);
                
                for j = 1:size(GSAccessTimes, 1)
                    if CurrentTime >= GSAccessTimes(j,1) && CurrentTime <= GSAccessTimes(j,2)
                        CurrentGSAccess(j,1) = 1;
                    else
                        CurrentGSAccess(j,1) = 0;
                    end
                end
                if sum(CurrentGSAccess) >= 1
                    GSAccess = 1;
                else
                    GSAccess = 0;
                end
                
                for j = 1:size(TargetAccessTimes, 1)
                    if CurrentTime >= TargetAccessTimes(j,1) && CurrentTime <= TargetAccessTimes(j,2)
                        CurrentTargetAccess(j,1) = 1;
                    else
                        CurrentTargetAccess(j,1) = 0;
                    end
                end
                if sum(CurrentTargetAccess) >= 1
                    TargetAccess = 1;
                else
                    TargetAccess = 0;
                end
                
                PowerUsage = zeros(size(Time,1),size(LowPower,1));
                if GSAccess == 1 && TargetAccess == 1
                    PowerUsage(i,:) = max(GSPowerUsage, TargetPowerUsage);
                    try
                        PowerUsage(i-1,:) = PowerUsage(i,:);
                    catch
                    end
                elseif GSAccess == 1 && TargetAccess == 0
                    PowerUsage(i,:) = GSPowerUsage;
                    try
                        PowerUsage(i-1,:) = PowerUsage(i,:);
                    catch
                    end
                elseif GSAccess == 0 && TargetAccess == 1
                    PowerUsage(i,:) = TargetPowerUsage;
                    try
                        PowerUsage(i-1,:) = PowerUsage(i,:);
                    catch
                    end
                else
                    PowerUsage(i,:) = AllPowerUsage;
                end
                
                % disp(PowerUsage)
                
                EnergyUsePerComponent(i, :) = PowerUsage(i,:)'*TimeStep; % Joules
                %disp('EnergyUsePerComponent')
                %disp(EnergyUsePerComponent)
                EnergyUseTotal(i) = sum(EnergyUsePerComponent(i,:)); % Joules
                %disp(EnergyUseTotal)
                %disp('Eff')
                %disp(Eff)
                HeatGenerationPerComponent(i,:) = EnergyUsePerComponent(i,:).*Eff';
                %disp(HeatGenerationPerComponent)
                HeatGenerationTotal(i) = sum(HeatGenerationPerComponent(i,:));
                %disp(HeatGenerationTotal)
                
            end
            %}
            
                assignin('base', 'EnergyUsePerComponent', EnergyUsePerComponent)
                assignin('base', 'EnergyUseTotalComponents', EnergyUseTotal)
                assignin('base', 'HeatGenerationPerComponent', HeatGenerationPerComponent)
                assignin('base', 'HeatGenerationTotalComponents', HeatGenerationTotal)    


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ComponentSystemsPowerUsageLabel = uilabel(app.UIFigure);
            app.ComponentSystemsPowerUsageLabel.Text = 'Component/Systems Power Usage';
            app.AttitudeControlEditFieldLabel = uilabel(app.UIFigure);
            app.AttitudeControlEditFieldLabel.Text = 'Attitude Control';
            app.AttitudeControlLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlLowEditField.Value = 0.85;
            app.AttitudeDeterminationEditFieldLabel = uilabel(app.UIFigure);
            app.AttitudeDeterminationEditFieldLabel.Text = 'Attitude Determination';
            app.AttitudeDeterminationLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationLowEditField.Value = 0.25;
            app.HealthMonitoringEditFieldLabel = uilabel(app.UIFigure);
            app.HealthMonitoringEditFieldLabel.Text = 'Health Monitoring';
            app.HealthMonitoringLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringLowEditField.Value = 0.2;
            app.ComputingEditFieldLabel = uilabel(app.UIFigure);
            app.ComputingEditFieldLabel.Text = 'Computing';
            app.ComputingLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingLowEditField.Value = 0.75;
            app.CommunicationsEditFieldLabel = uilabel(app.UIFigure);
            app.CommunicationsEditFieldLabel.Text = 'Communications';
            app.CommunicationsLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsLowEditField.Value = 0.5;
            app.AdditionalEditFieldLabel = uilabel(app.UIFigure);
            app.AdditionalEditFieldLabel.Text = 'Additional';
            app.AdditionalLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalLowEditField.Value = 0.5;
            app.AttitudeControlEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlEffEditField.Value = 0.5;
            app.AttitudeDeterminationEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationEffEditField.Value = 0.5;
            app.HealthMonitoringEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringEffEditField.Value = 0.5;
            app.ComputingEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingEffEditField.Value = 0.5;
            app.CommunicationsEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsEffEditField.Value = 0.5;
            app.AdditionalEffEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalEffEditField.Value = 0.5;
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Efficiency';
            app.PassiveLowLabel = uilabel(app.UIFigure);
            app.PassiveLowLabel.Text = 'Passive/Low';
            app.AttitudeControlHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlHighEditField.Value = 1.5;
            app.AttitudeDeterminationHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationHighEditField.Value = 0.5;
            app.HealthMonitoringHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringHighEditField.Value = 0.5;
            app.ComputingHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingHighEditField.Value = 1.5;
            app.CommunicationsHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsHighEditField.Value = 1;
            app.AdditionalHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalHighEditField.Value = 1;
            app.ActiveHighLabel = uilabel(app.UIFigure);
            app.ActiveHighLabel.Text = 'Active/High';
            app.AttitudeControlFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlFractionEditField.Enable = 'off';
            app.AttitudeControlFractionEditField.Value = 0.8;
            app.AttitudeDeterminationFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationFractionEditField.Enable = 'off';
            app.AttitudeDeterminationFractionEditField.Value = 0.3;
            app.HealthMonitoringFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringFractionEditField.Enable = 'off';
            app.HealthMonitoringFractionEditField.Value = 0.4;
            app.ComputingFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingFractionEditField.Enable = 'off';
            app.ComputingFractionEditField.Value = 0.5;
            app.CommunicationsFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsFractionEditField.Enable = 'off';
            app.CommunicationsFractionEditField.Value = 0.2;
            app.AdditionalFractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalFractionEditField.Enable = 'off';
            app.AdditionalFractionEditField.Value = 0.3;
            app.FractionofLabel = uilabel(app.UIFigure);
            app.FractionofLabel.Enable = 'off';
            app.FractionofLabel.Text = 'Fraction of';
            app.AttitudeControlDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlDutyEditField.Enable = 'off';
            app.AttitudeControlDutyEditField.Value = 300;
            app.AttitudeDeterminationDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationDutyEditField.Enable = 'off';
            app.AttitudeDeterminationDutyEditField.Value = 200;
            app.HealthMonitoringDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringDutyEditField.Enable = 'off';
            app.HealthMonitoringDutyEditField.Value = 30;
            app.ComputingDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingDutyEditField.Enable = 'off';
            app.ComputingDutyEditField.Value = 30;
            app.CommunicationsDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsDutyEditField.Enable = 'off';
            app.CommunicationsDutyEditField.Value = 15;
            app.AdditionalDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalDutyEditField.Enable = 'off';
            app.AdditionalDutyEditField.Value = 100;
            app.DutyCycleLabel = uilabel(app.UIFigure);
            app.DutyCycleLabel.Enable = 'off';
            app.DutyCycleLabel.Text = 'Duty Cycle';
            app.AttitudeControlPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AttitudeControlPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.AttitudeControlPowerMethodSwitch.Enable = 'off';
            app.AttitudeControlPowerMethodSwitch.Value = 'Avg';
            app.PowerWLabel = uilabel(app.UIFigure);
            app.PowerWLabel.Text = 'Power (W)';
            app.PowerWLabel_2 = uilabel(app.UIFigure);
            app.PowerWLabel_2.Text = 'Power (W)';
            app.TimeActiveLabel = uilabel(app.UIFigure);
            app.TimeActiveLabel.Enable = 'off';
            app.TimeActiveLabel.Text = 'Time Active';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Enable = 'off';
            app.TimesLabel.Text = 'Time (s)';
            app.PowerusageLabel = uilabel(app.UIFigure);
            app.PowerusageLabel.Enable = 'off';
            app.PowerusageLabel.Text = 'Power usage';
            app.AttitudeDeterminationPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AttitudeDeterminationPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.AttitudeDeterminationPowerMethodSwitch.Enable = 'off';
            app.AttitudeDeterminationPowerMethodSwitch.Value = 'Avg';
            app.HealthMonitoringPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.HealthMonitoringPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.HealthMonitoringPowerMethodSwitch.Enable = 'off';
            app.HealthMonitoringPowerMethodSwitch.Value = 'Avg';
            app.ComputingPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.ComputingPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.ComputingPowerMethodSwitch.Enable = 'off';
            app.ComputingPowerMethodSwitch.Value = 'Avg';
            app.CommunicationsPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.CommunicationsPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.CommunicationsPowerMethodSwitch.Enable = 'off';
            app.CommunicationsPowerMethodSwitch.Value = 'Avg';
            app.AdditionalPowerMethodSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AdditionalPowerMethodSwitch.Items = {'Avg', 'Cycle'};
            app.AdditionalPowerMethodSwitch.Enable = 'off';
            app.AdditionalPowerMethodSwitch.Value = 'Avg';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel.Text = 'Fraction of Time Active: For what fraction of a given duty cycle is the component in the Active/High power usage mode';
            app.SeebelowforexplanationofoptionstermsLabel = uilabel(app.UIFigure);
            app.SeebelowforexplanationofoptionstermsLabel.Enable = 'off';
            app.SeebelowforexplanationofoptionstermsLabel.Text = '(See below for explanation of options/terms)';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_2 = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_2.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_2.Text = 'Duty Cycle: The period of time over which the component will be Passive and Active before going back to Passive';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_3 = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_3.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_3.Text = 'Power Usage Calculation Methods:';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_4 = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_4.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_4.Text = 'Avg: The power usage will be modeled as a steady level';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_5 = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_5.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_5.Text = 'Cycle: The power usage will cycle between the Passive level and Active level according to the parameters entered above';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_6 = uilabel(app.UIFigure);
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_6.Enable = 'off';
            app.AvgThepowerusageiscalculatedasasteadyconstantlevelLabel_6.Text = 'Efficiency: The fraction of the components power usage that will be converted to heat';
            app.calculationmethodLabel = uilabel(app.UIFigure);
            app.calculationmethodLabel.Enable = 'off';
            app.calculationmethodLabel.Text = 'calculation method';
            app.AttitudeControlNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeControlNormEditField.Value = 1.5;
            app.AttitudeDeterminationNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttitudeDeterminationNormEditField.Value = 0.5;
            app.HealthMonitoringNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.HealthMonitoringNormEditField.Value = 0.5;
            app.ComputingNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputingNormEditField.Value = 1.5;
            app.CommunicationsNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommunicationsNormEditField.Value = 1;
            app.AdditionalNormEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdditionalNormEditField.Value = 1;
            app.NormAvgLabel = uilabel(app.UIFigure);
            app.NormAvgLabel.Text = 'Norm/Avg';
            app.PowerWLabel_3 = uilabel(app.UIFigure);
            app.PowerWLabel_3.Text = 'Power (W)';
            app.WhichpowersettingtouseduringLabel = uilabel(app.UIFigure);
            app.WhichpowersettingtouseduringLabel.Text = 'Which power setting to use during:';
            app.GSAccessLabel = uilabel(app.UIFigure);
            app.GSAccessLabel.Text = 'GS Access';
            app.TargetAccessLabel = uilabel(app.UIFigure);
            app.TargetAccessLabel.Text = 'Target Access';
            app.AllOtherTimesLabel = uilabel(app.UIFigure);
            app.AllOtherTimesLabel.Text = 'All Other Times';
            app.AttitudeControlGSDropDown = uidropdown(app.UIFigure);
            app.AttitudeControlGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeControlGSDropDown.Value = 'High';
            app.AttitudeDeterminationGSDropDown = uidropdown(app.UIFigure);
            app.AttitudeDeterminationGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeDeterminationGSDropDown.Value = 'High';
            app.HealthMonitoringGSDropDown = uidropdown(app.UIFigure);
            app.HealthMonitoringGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.HealthMonitoringGSDropDown.Value = 'Low';
            app.ComputingGSDropDown = uidropdown(app.UIFigure);
            app.ComputingGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.ComputingGSDropDown.Value = 'Low';
            app.CommunicationsGSDropDown = uidropdown(app.UIFigure);
            app.CommunicationsGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.CommunicationsGSDropDown.Value = 'High';
            app.AdditionalGSDropDown = uidropdown(app.UIFigure);
            app.AdditionalGSDropDown.Items = {'Low', 'Norm', 'High'};
            app.AdditionalGSDropDown.Value = 'Low';
            app.AttitudeControlTargetDropDown = uidropdown(app.UIFigure);
            app.AttitudeControlTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeControlTargetDropDown.Value = 'High';
            app.AttitudeDeterminationTargetDropDown = uidropdown(app.UIFigure);
            app.AttitudeDeterminationTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeDeterminationTargetDropDown.Value = 'High';
            app.HealthMonitoringTargetDropDown = uidropdown(app.UIFigure);
            app.HealthMonitoringTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.HealthMonitoringTargetDropDown.Value = 'Low';
            app.ComputingTargetDropDown = uidropdown(app.UIFigure);
            app.ComputingTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.ComputingTargetDropDown.Value = 'Low';
            app.CommunicationsTargetDropDown = uidropdown(app.UIFigure);
            app.CommunicationsTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.CommunicationsTargetDropDown.Value = 'High';
            app.AdditionalTargetDropDown = uidropdown(app.UIFigure);
            app.AdditionalTargetDropDown.Items = {'Low', 'Norm', 'High'};
            app.AdditionalTargetDropDown.Value = 'Low';
            app.AttitudeControlDropDown = uidropdown(app.UIFigure);
            app.AttitudeControlDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeControlDropDown.Value = 'Low';
            app.AttitudeDeterminationDropDown = uidropdown(app.UIFigure);
            app.AttitudeDeterminationDropDown.Items = {'Low', 'Norm', 'High'};
            app.AttitudeDeterminationDropDown.Value = 'Low';
            app.HealthMonitoringDropDown = uidropdown(app.UIFigure);
            app.HealthMonitoringDropDown.Items = {'Low', 'Norm', 'High'};
            app.HealthMonitoringDropDown.Value = 'Norm';
            app.ComputingDropDown = uidropdown(app.UIFigure);
            app.ComputingDropDown.Items = {'Low', 'Norm', 'High'};
            app.ComputingDropDown.Value = 'Norm';
            app.CommunicationsDropDown = uidropdown(app.UIFigure);
            app.CommunicationsDropDown.Items = {'Low', 'Norm', 'High'};
            app.CommunicationsDropDown.Value = 'Low';
            app.AdditionalDropDown = uidropdown(app.UIFigure);
            app.AdditionalDropDown.Items = {'Low', 'Norm', 'High'};
            app.AdditionalDropDown.Value = 'Norm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ComponentsPowerUsageGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end