classdef GroundStationTargetsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GroundStationsandTargetsLabel  matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        LatdegEditFieldLabel           matlab.ui.control.Label
        LatdegEditField                matlab.ui.control.NumericEditField
        LongdegEditFieldLabel          matlab.ui.control.Label
        LongdegEditField               matlab.ui.control.NumericEditField
        AltmEditFieldLabel             matlab.ui.control.Label
        AltmEditField                  matlab.ui.control.NumericEditField
        GroundStationLabel             matlab.ui.control.Label
        TargetsLabel                   matlab.ui.control.Label
        LatEditFieldLabel              matlab.ui.control.Label
        LatEditField_1                 matlab.ui.control.NumericEditField
        LongEditFieldLabel             matlab.ui.control.Label
        LongEditField_1                matlab.ui.control.NumericEditField
        AltmEditField_2Label           matlab.ui.control.Label
        AltmEditField_2                matlab.ui.control.NumericEditField
        LatEditField_2                 matlab.ui.control.NumericEditField
        LongEditField_2                matlab.ui.control.NumericEditField
        AltEditField_2                 matlab.ui.control.NumericEditField
        LatEditField_3                 matlab.ui.control.NumericEditField
        LongEditField_3                matlab.ui.control.NumericEditField
        AltEditField_3                 matlab.ui.control.NumericEditField
        Target1CheckBox                matlab.ui.control.CheckBox
        Target2CheckBox                matlab.ui.control.CheckBox
        Target3CheckBox                matlab.ui.control.CheckBox
        LatEditField_4                 matlab.ui.control.NumericEditField
        LongEditField_4                matlab.ui.control.NumericEditField
        AltEditField_4                 matlab.ui.control.NumericEditField
        Target4CheckBox                matlab.ui.control.CheckBox
        LatEditField_5                 matlab.ui.control.NumericEditField
        LongEditField_5                matlab.ui.control.NumericEditField
        AltEditField_5                 matlab.ui.control.NumericEditField
        Target5CheckBox                matlab.ui.control.CheckBox
        MinGSAccessElevationEditField  matlab.ui.control.NumericEditField
        MinimumelevationforaccesstogroundstationdegLabel  matlab.ui.control.Label
        MinTargetAccessElevationEditField  matlab.ui.control.NumericEditField
        MinimumelevationforaccesstotargetsdegLabel  matlab.ui.control.Label
        TimeStepforAccessPassesDatasecEditFieldLabel  matlab.ui.control.Label
        TimeStepforAccessPassesDatasecEditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            MinGSElevation = app.MinGSAccessElevationEditField.Value;
            MinTargetElevation = app.MinTargetAccessElevationEditField.Value;
            assignin('base', 'MinElevations', [MinGSElevation; MinTargetElevation])
            
            GSLongitude = app.LongdegEditField.Value;
            GSLatitude = app.LatdegEditField.Value;
            GSAltitude = app.AltmEditField.Value;
            assignin('base', 'GroundStationLLA', [GSLatitude, GSLongitude, GSAltitude])
            
            
            assignin('base', 'AccessTimeStep', app.TimeStepforAccessPassesDatasecEditField.Value)
            
            if app.Target5CheckBox.Value == 1
                Tar1Longitude = app.LongEditField_1.Value;
                Tar1Latitude = app.LatEditField_1.Value;
                Tar1Altitude = app.AltmEditField_2.Value;
                Target1LLA = [Tar1Latitude, Tar1Longitude, Tar1Altitude];
                Tar2Longitude = app.LongEditField_2.Value;
                Tar2Latitude = app.LatEditField_2.Value;
                Tar2Altitude = app.AltEditField_2.Value;
                Target2LLA = [Tar2Latitude, Tar2Longitude, Tar2Altitude];
                Tar3Longitude = app.LongEditField_3.Value;
                Tar3Latitude = app.LatEditField_3.Value;
                Tar3Altitude = app.AltEditField_3.Value;
                Target3LLA = [Tar3Latitude, Tar3Longitude, Tar3Altitude];
                Tar4Longitude = app.LongEditField_4.Value;
                Tar4Latitude = app.LatEditField_4.Value;
                Tar4Altitude = app.AltEditField_4.Value;
                Target4LLA = [Tar4Latitude, Tar4Longitude, Tar4Altitude];
                Tar5Longitude = app.LongEditField_5.Value;
                Tar5Latitude = app.LatEditField_5.Value;
                Tar5Altitude = app.AltEditField_5.Value;
                Target5LLA = [Tar5Latitude, Tar5Longitude, Tar5Altitude];
                assignin('base', 'TargetsLLA', [Target1LLA;Target2LLA;Target3LLA;Target4LLA;Target5LLA])
            elseif app.Target4CheckBox.Value == 1
                Tar1Longitude = app.LongEditField_1.Value;
                Tar1Latitude = app.LatEditField_1.Value;
                Tar1Altitude = app.AltmEditField_2.Value;
                Target1LLA = [Tar1Latitude, Tar1Longitude, Tar1Altitude];
                Tar2Longitude = app.LongEditField_2.Value;
                Tar2Latitude = app.LatEditField_2.Value;
                Tar2Altitude = app.AltEditField_2.Value;
                Target2LLA = [Tar2Latitude, Tar2Longitude, Tar2Altitude];
                Tar3Longitude = app.LongEditField_3.Value;
                Tar3Latitude = app.LatEditField_3.Value;
                Tar3Altitude = app.AltEditField_3.Value;
                Target3LLA = [Tar3Latitude, Tar3Longitude, Tar3Altitude];
                Tar4Longitude = app.LongEditField_4.Value;
                Tar4Latitude = app.LatEditField_4.Value;
                Tar4Altitude = app.AltEditField_4.Value;
                Target4LLA = [Tar4Latitude, Tar4Longitude, Tar4Altitude];
                assignin('base', 'TargetsLLA', [Target1LLA;Target2LLA;Target3LLA;Target4LLA])
            elseif app.Target3CheckBox.Value == 1
                Tar1Longitude = app.LongEditField_1.Value;
                Tar1Latitude = app.LatEditField_1.Value;
                Tar1Altitude = app.AltmEditField_2.Value;
                Target1LLA = [Tar1Latitude, Tar1Longitude, Tar1Altitude];
                Tar2Longitude = app.LongEditField_2.Value;
                Tar2Latitude = app.LatEditField_2.Value;
                Tar2Altitude = app.AltEditField_2.Value;
                Target2LLA = [Tar2Latitude, Tar2Longitude, Tar2Altitude];
                Tar3Longitude = app.LongEditField_3.Value;
                Tar3Latitude = app.LatEditField_3.Value;
                Tar3Altitude = app.AltEditField_3.Value;
                Target3LLA = [Tar3Latitude, Tar3Longitude, Tar3Altitude];
                assignin('base', 'TargetsLLA', [Target1LLA;Target2LLA;Target3LLA])
            elseif app.Target2CheckBox.Value == 1
                Tar1Longitude = app.LongEditField_1.Value;
                Tar1Latitude = app.LatEditField_1.Value;
                Tar1Altitude = app.AltmEditField_2.Value;
                Target1LLA = [Tar1Latitude, Tar1Longitude, Tar1Altitude];
                Tar2Longitude = app.LongEditField_2.Value;
                Tar2Latitude = app.LatEditField_2.Value;
                Tar2Altitude = app.AltEditField_2.Value;
                Target2LLA = [Tar2Latitude, Tar2Longitude, Tar2Altitude];
                assignin('base', 'TargetsLLA', [Target1LLA;Target2LLA])
            elseif app.Target1CheckBox.Value == 1
                Tar1Longitude = app.LongEditField_1.Value;
                Tar1Latitude = app.LatEditField_1.Value;
                Tar1Altitude = app.AltmEditField_2.Value;
                Target1LLA = [Tar1Latitude, Tar1Longitude, Tar1Altitude];
                assignin('base', 'TargetsLLA', Target1LLA)
            else
                TargetsLLA = "NoTargets";
                assignin('base', 'TargetsLLA', TargetsLLA)
            end

        end

        % Value changed function: Target1CheckBox
        function Target1CheckBoxValueChanged(app, event)
            value = app.Target1CheckBox.Value;
            if value == 0
                app.LatEditField_1.Enable = 'off';
                app.LongEditField_1.Enable = 'off';
                app.AltmEditField_2.Enable = 'off';
                app.Target2CheckBox.Value = 0;
                app.Target2CheckBox.Enable = 'off';
                app.LatEditField_2.Enable = 'off';
                app.LongEditField_2.Enable = 'off';
                app.AltEditField_2.Enable = 'off';
                app.Target3CheckBox.Value = 0;
                app.Target3CheckBox.Enable = 'off';
                app.LatEditField_3.Enable = 'off';
                app.LongEditField_3.Enable = 'off';
                app.AltEditField_3.Enable = 'off';
                app.Target4CheckBox.Value = 0;
                app.Target4CheckBox.Enable = 'off';
                app.LatEditField_4.Enable = 'off';
                app.LongEditField_4.Enable = 'off';
                app.AltEditField_4.Enable = 'off';
                app.Target5CheckBox.Value = 0;
                app.Target5CheckBox.Enable = 'off';
                app.LatEditField_5.Enable = 'off';
                app.LongEditField_5.Enable = 'off';
                app.AltEditField_5.Enable = 'off';
            end
            if value == 1
                app.LatEditField_1.Enable = 'on';
                app.LongEditField_1.Enable = 'on';
                app.AltmEditField_2.Enable = 'on';
                app.Target2CheckBox.Enable = 'on';
            end
        end

        % Value changed function: Target2CheckBox
        function Target2CheckBoxValueChanged(app, event)
            value = app.Target2CheckBox.Value;
            if value == 0
                app.LatEditField_2.Enable = 'off';
                app.LongEditField_2.Enable = 'off';
                app.AltEditField_2.Enable = 'off';
                app.Target3CheckBox.Value = 0;
                app.Target3CheckBox.Enable = 'off';
                app.LatEditField_3.Enable = 'off';
                app.LongEditField_3.Enable = 'off';
                app.AltEditField_3.Enable = 'off';
                app.Target4CheckBox.Value = 0;
                app.Target4CheckBox.Enable = 'off';
                app.LatEditField_4.Enable = 'off';
                app.LongEditField_4.Enable = 'off';
                app.AltEditField_4.Enable = 'off';
                app.Target5CheckBox.Value = 0;
                app.Target5CheckBox.Enable = 'off';
                app.LatEditField_5.Enable = 'off';
                app.LongEditField_5.Enable = 'off';
                app.AltEditField_5.Enable = 'off';
            end
            if value == 1
                app.LatEditField_2.Enable = 'on';
                app.LongEditField_2.Enable = 'on';
                app.AltEditField_2.Enable = 'on';
                app.Target3CheckBox.Enable = 'on';
            end
        end

        % Value changed function: Target3CheckBox
        function Target3CheckBoxValueChanged(app, event)
            value = app.Target3CheckBox.Value;
            if value == 0
                app.LatEditField_3.Enable = 'off';
                app.LongEditField_3.Enable = 'off';
                app.AltEditField_3.Enable = 'off';
                app.Target4CheckBox.Value = 0;
                app.Target4CheckBox.Enable = 'off';
                app.LatEditField_4.Enable = 'off';
                app.LongEditField_4.Enable = 'off';
                app.AltEditField_4.Enable = 'off';
                app.Target5CheckBox.Value = 0;
                app.Target5CheckBox.Enable = 'off';
                app.LatEditField_5.Enable = 'off';
                app.LongEditField_5.Enable = 'off';
                app.AltEditField_5.Enable = 'off';
            end
            if value == 1
                app.LatEditField_3.Enable = 'on';
                app.LongEditField_3.Enable = 'on';
                app.AltEditField_3.Enable = 'on';
                app.Target4CheckBox.Enable = 'on';
            end
        end

        % Value changed function: Target4CheckBox
        function Target4CheckBoxValueChanged(app, event)
            value = app.Target4CheckBox.Value;
            if value == 0
                app.LatEditField_4.Enable = 'off';
                app.LongEditField_4.Enable = 'off';
                app.AltEditField_4.Enable = 'off';
                app.Target5CheckBox.Value = 0;
                app.Target5CheckBox.Enable = 'off';
                app.LatEditField_5.Enable = 'off';
                app.LongEditField_5.Enable = 'off';
                app.AltEditField_5.Enable = 'off';
            end
            if value == 1
                app.LatEditField_4.Enable = 'on';
                app.LongEditField_4.Enable = 'on';
                app.AltEditField_4.Enable = 'on';
                app.Target5CheckBox.Enable = 'on';
            end
        end

        % Value changed function: Target5CheckBox
        function Target5CheckBoxValueChanged(app, event)
            value = app.Target5CheckBox.Value;
            if value == 0
                app.LatEditField_5.Enable = 'off';
                app.LongEditField_5.Enable = 'off';
                app.AltEditField_5.Enable = 'off';
            end
            if value == 1
                app.LatEditField_5.Enable = 'on';
                app.LongEditField_5.Enable = 'on';
                app.AltEditField_5.Enable = 'on'; 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GroundStationsandTargetsLabel = uilabel(app.UIFigure);
            app.GroundStationsandTargetsLabel.Text = 'Ground Stations and Targets';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.LatdegEditFieldLabel = uilabel(app.UIFigure);
            app.LatdegEditFieldLabel.Text = 'Lat (deg)';
            app.LatdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatdegEditField.Value = 37.2296;
            app.LongdegEditFieldLabel = uilabel(app.UIFigure);
            app.LongdegEditFieldLabel.Text = 'Long (deg)';
            app.LongdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongdegEditField.Value = -80.4139;
            app.AltmEditFieldLabel = uilabel(app.UIFigure);
            app.AltmEditFieldLabel.Text = 'Alt (m)';
            app.AltmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltmEditField.Value = 634;
            app.GroundStationLabel = uilabel(app.UIFigure);
            app.GroundStationLabel.Text = 'Ground Station';
            app.TargetsLabel = uilabel(app.UIFigure);
            app.TargetsLabel.Text = 'Targets';
            app.LatEditFieldLabel = uilabel(app.UIFigure);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_1.Value = 58;
            app.LongEditFieldLabel = uilabel(app.UIFigure);
            app.LongEditFieldLabel.Text = 'Long';
            app.LongEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.LongEditField_1.Value = -20;
            app.AltmEditField_2Label = uilabel(app.UIFigure);
            app.AltmEditField_2Label.Text = 'Alt (m)';
            app.AltmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AltmEditField_2.Value = 300;
            app.LatEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_2.Enable = 'off';
            app.LongEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LongEditField_2.Enable = 'off';
            app.AltEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AltEditField_2.Enable = 'off';
            app.LatEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_3.Enable = 'off';
            app.LongEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LongEditField_3.Enable = 'off';
            app.AltEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.AltEditField_3.Enable = 'off';
            app.Target1CheckBox = uicheckbox(app.UIFigure);
            app.Target1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Target1CheckBoxValueChanged, true);
            app.Target1CheckBox.Text = 'Target 1';
            app.Target1CheckBox.Value = true;
            app.Target2CheckBox = uicheckbox(app.UIFigure);
            app.Target2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Target2CheckBoxValueChanged, true);
            app.Target2CheckBox.Text = 'Target 2';
            app.Target3CheckBox = uicheckbox(app.UIFigure);
            app.Target3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Target3CheckBoxValueChanged, true);
            app.Target3CheckBox.Enable = 'off';
            app.Target3CheckBox.Text = 'Target 3';
            app.LatEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_4.Enable = 'off';
            app.LongEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.LongEditField_4.Enable = 'off';
            app.AltEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.AltEditField_4.Enable = 'off';
            app.Target4CheckBox = uicheckbox(app.UIFigure);
            app.Target4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Target4CheckBoxValueChanged, true);
            app.Target4CheckBox.Enable = 'off';
            app.Target4CheckBox.Text = 'Target 4';
            app.LatEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_5.Enable = 'off';
            app.LongEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.LongEditField_5.Enable = 'off';
            app.AltEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.AltEditField_5.Enable = 'off';
            app.Target5CheckBox = uicheckbox(app.UIFigure);
            app.Target5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Target5CheckBoxValueChanged, true);
            app.Target5CheckBox.Enable = 'off';
            app.Target5CheckBox.Text = 'Target 5';
            app.MinGSAccessElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumelevationforaccesstogroundstationdegLabel = uilabel(app.UIFigure);
            app.MinimumelevationforaccesstogroundstationdegLabel.Text = 'Minimum elevation for access to ground station (deg)';
            app.MinTargetAccessElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumelevationforaccesstotargetsdegLabel = uilabel(app.UIFigure);
            app.MinimumelevationforaccesstotargetsdegLabel.Text = 'Minimum elevation for access to targets (deg)';
            app.TimeStepforAccessPassesDatasecEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepforAccessPassesDatasecEditFieldLabel.Text = 'Time Step for Access/Passes Data (sec)';
            app.TimeStepforAccessPassesDatasecEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepforAccessPassesDatasecEditField.Limits = [1 Inf];
            app.TimeStepforAccessPassesDatasecEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GroundStationTargetsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end