classdef GroundTrackGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotGroundTrackButton          matlab.ui.control.Button
        AvailableTimeStepsEditFieldLabel  matlab.ui.control.Label
        AvailableTimeStepsEditField    matlab.ui.control.NumericEditField
        TimeStepLengthsEditFieldLabel  matlab.ui.control.Label
        TimeStepLengthsEditField       matlab.ui.control.NumericEditField
        NoteLabel_2                    matlab.ui.control.Label
        StartTimeStepEditFieldLabel    matlab.ui.control.Label
        StartTimeStepEditField         matlab.ui.control.NumericEditField
        EndTimeStepEditFieldLabel      matlab.ui.control.Label
        EndTimeStepEditField           matlab.ui.control.NumericEditField
        DateTimeEditField_2            matlab.ui.control.EditField
        DateTimeEditField_3            matlab.ui.control.EditField
        NoteLabel_5                    matlab.ui.control.Label
        GroundTrackLabel               matlab.ui.control.Label
        LLAcsvFileButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                LatLongAlt = evalin('base', 'LatLongAlt');
                Time = evalin('base', 'Time');
                TimeUTCG = evalin('base', 'TimeUTCG');            
                TimeStepsNumber = size(Time,1);
                TimeStepSize = Time(2,1)-Time(1,1);
                app.AvailableTimeStepsEditField.Value = TimeStepsNumber;
                app.EndTimeStepEditField.Value = TimeStepsNumber;
                app.TimeStepLengthsEditField.Value = TimeStepSize;
                app.DateTimeEditField_2.Value = TimeUTCG{1}(1:end-10);
                app.DateTimeEditField_3.Value = TimeUTCG{end}(1:end-10);
            catch
                app.NoteLabel_2.Text = "Note: No STK data found!";
                app.AvailableTimeStepsEditField.Enable = 'off';
                app.TimeSteptoViewEditField.Enable = 'off';
                app.TimeStepLengthsEditField.Enable = 'off';
                app.StartTimeStepEditField.Enable = 'off';
                app.EndTimeStepEditField.Enable = 'off';
                app.DateTimeEditField_2.Enable = 'off';
                app.DateTimeEditField_3.Enable = 'off';
                app.LLAcsvFileButton.Enable = 'off';
                app.PlotGroundTrackButton.Enable = 'off';
            end
        end

        % Value changed function: StartTimeStepEditField
        function StartTimeStepEditFieldValueChanged(app, event)
            value = app.StartTimeStepEditField.Value;
            TimeUTCG = evalin('base', 'TimeUTCG');
            if value > app.AvailableTimeStepsEditField.Value
                app.DateTimeEditField_2.Value = 'Time Step Not Valid!';
            else
                app.DateTimeEditField_2.Value = TimeUTCG{value}(1:end-10);
            end
            if strcmp(app.DateTimeEditField_2.Value, 'Time Step Not Valid!') || strcmp(app.DateTimeEditField_3.Value, 'Time Step Not Valid!')
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotGroundTrackButton.Enable = 'off';
                app.LLAcsvFileButton.Enable = 'off';
            else
                app.NoteLabel_5.Text = "Note: ";
                app.PlotGroundTrackButton.Enable = 'on';
                app.LLAcsvFileButton.Enable = 'on';
            end
        end

        % Value changed function: EndTimeStepEditField
        function EndTimeStepEditFieldValueChanged(app, event)
            value = app.EndTimeStepEditField.Value;
            TimeUTCG = evalin('base', 'TimeUTCG');
            if value > app.AvailableTimeStepsEditField.Value
                app.DateTimeEditField_3.Value = 'Time Step Not Valid!';
            else
                app.DateTimeEditField_3.Value = TimeUTCG{value}(1:end-10);
            end
            if strcmp(app.DateTimeEditField_2.Value, 'Time Step Not Valid!') || strcmp(app.DateTimeEditField_3.Value, 'Time Step Not Valid!')
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotGroundTrackButton.Enable = 'off';
                app.LLAcsvFileButton.Enable = 'off';
            else
                app.NoteLabel_5.Text = "Note: ";
                app.PlotGroundTrackButton.Enable = 'on';
                app.LLAcsvFileButton.Enable = 'on';
            end
        end

        % Button pushed function: PlotGroundTrackButton
        function PlotGroundTrackButtonPushed(app, event)
            LatLongAlt = evalin('base', 'LatLongAlt');
            GroundStationLLA = evalin('base', 'GroundStationLLA');
            TargetsLLA = evalin('base', 'TargetsLLA');

            if app.StartTimeStepEditField.Value > app.EndTimeStepEditField.Value
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotGroundTrackButton.Enable = 'off';
                app.LLAcsvFileButton.Enable = 'off';
            else
                LatLongAltReduced = LatLongAlt(app.StartTimeStepEditField.Value:app.EndTimeStepEditField.Value,:);
                app.NoteLabel_5.Text = "Note: ";
                GroundTrackPlot(LatLongAltReduced, GroundStationLLA, TargetsLLA)
            end

        end

        % Button pushed function: LLAcsvFileButton
        function LLAcsvFileButtonPushed(app, event)
            LatLongAlt = evalin('base', 'LatLongAlt');
            Time = evalin('base', 'Time');

            if app.StartTimeStepEditField.Value > app.EndTimeStepEditField.Value
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotGroundTrackButton.Enable = 'off';
                app.LLAcsvFileButton.Enable = 'off';
            else
                LatLongAltReduced = LatLongAlt(app.StartTimeStepEditField.Value:app.EndTimeStepEditField.Value,:);
                app.NoteLabel_5.Text = "Note: ";
                try
                    filename = inputdlg('Enter name of .csv to create');
                    filename = strcat(filename{1,1}, '.csv');
                    Latitude = LatLongAltReduced(:,1);
                    Longitude = LatLongAltReduced(:,2);
                    AltitudeKM = LatLongAltReduced(:,3)/1000;
                    TimeEpSec = Time(app.StartTimeStepEditField.Value:app.EndTimeStepEditField.Value,:);
                    TableA = table(TimeEpSec, Latitude, Longitude, AltitudeKM);
                    writetable(TableA, filename)
                catch
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotGroundTrackButton = uibutton(app.UIFigure, 'push');
            app.PlotGroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTrackButtonPushed, true);
            app.PlotGroundTrackButton.Text = 'Plot Ground Track';
            app.AvailableTimeStepsEditFieldLabel = uilabel(app.UIFigure);
            app.AvailableTimeStepsEditFieldLabel.Text = 'Available Time Steps';
            app.AvailableTimeStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AvailableTimeStepsEditField.Editable = 'off';
            app.TimeStepLengthsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepLengthsEditFieldLabel.Text = 'Time Step Length (s)';
            app.TimeStepLengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepLengthsEditField.Editable = 'off';
            app.NoteLabel_2 = uilabel(app.UIFigure);
            app.NoteLabel_2.Text = 'Note:';
            app.StartTimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeStepEditFieldLabel.Text = 'Start Time Step';
            app.StartTimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeStepEditField.Limits = [1 Inf];
            app.StartTimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeStepEditFieldValueChanged, true);
            app.StartTimeStepEditField.Value = 1;
            app.EndTimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeStepEditFieldLabel.Text = 'End Time Step';
            app.EndTimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeStepEditField.Limits = [0 Inf];
            app.EndTimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeStepEditFieldValueChanged, true);
            app.EndTimeStepEditField.Value = 2;
            app.DateTimeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.DateTimeEditField_2.Editable = 'off';
            app.DateTimeEditField_3 = uieditfield(app.UIFigure, 'text');
            app.DateTimeEditField_3.Editable = 'off';
            app.NoteLabel_5 = uilabel(app.UIFigure);
            app.NoteLabel_5.Text = 'Note:';
            app.GroundTrackLabel = uilabel(app.UIFigure);
            app.GroundTrackLabel.Text = 'Ground Track';
            app.LLAcsvFileButton = uibutton(app.UIFigure, 'push');
            app.LLAcsvFileButton.ButtonPushedFcn = createCallbackFcn(app, @LLAcsvFileButtonPushed, true);
            app.LLAcsvFileButton.Text = 'LLA .csv File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GroundTrackGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end