classdef HealthPowerHeatGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HealthPowerThermalLabel         matlab.ui.control.Label
        MasskgEditFieldLabel            matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        SolarCellAbsorptivityEditFieldLabel  matlab.ui.control.Label
        SolarCellAbsorptivityEditField  matlab.ui.control.NumericEditField
        SolarCellEmissivityEditFieldLabel  matlab.ui.control.Label
        SolarCellEmissivityEditField    matlab.ui.control.NumericEditField
        CalculatePowerandThermalHistoryButton  matlab.ui.control.Button
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.NumericEditField
        SpecificHeatJkgKLabel           matlab.ui.control.Label
        SpecificHeatJkgKEditField       matlab.ui.control.NumericEditField
        AllOtherAbsorptivityEditFieldLabel  matlab.ui.control.Label
        AllOtherAbsorptivityEditField   matlab.ui.control.NumericEditField
        AllOtherEmissivityEditFieldLabel  matlab.ui.control.Label
        AllOtherEmissivityEditField     matlab.ui.control.NumericEditField
        BatteryCapacityWhrEditFieldLabel  matlab.ui.control.Label
        BatteryCapacityWhrEditField     matlab.ui.control.NumericEditField
        IntitialBatteryChargeWhrEditFieldLabel  matlab.ui.control.Label
        IntitialBatteryChargeWhrEditField  matlab.ui.control.NumericEditField
        BatteryLabel                    matlab.ui.control.Label
        TemperatureLimitsLabel          matlab.ui.control.Label
        CelsiusorKelvins                matlab.ui.control.RockerSwitch
        SurvivalEditFieldLabel          matlab.ui.control.Label
        SurvivalMinEditField            matlab.ui.control.NumericEditField
        OperationalEditFieldLabel       matlab.ui.control.Label
        OperationalMinEditField         matlab.ui.control.NumericEditField
        ScientificEditFieldLabel        matlab.ui.control.Label
        ScientificMinEditField          matlab.ui.control.NumericEditField
        SurvivalMaxEditField            matlab.ui.control.NumericEditField
        OperationalMaxEditField         matlab.ui.control.NumericEditField
        ScientificMaxEditField          matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        ChargeLowerLimitWhrEditFieldLabel  matlab.ui.control.Label
        ChargeLowerLimitWhrEditField    matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SatSizeandSolarPanelDefinitionButton  matlab.ui.control.Button
        ComponentsDefinitionButton      matlab.ui.control.Button
        PlotTemperatureButton           matlab.ui.control.Button
        PlotBatteryChargeButton         matlab.ui.control.Button
        CreateTempcsvButton             matlab.ui.control.Button
        CreateBatChargecsvButton        matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        MassMaterialPropertiesLabel     matlab.ui.control.Label
        PlotHeatEnteringLeavingButton   matlab.ui.control.Button
        CreateHeatRatecsvButton         matlab.ui.control.Button
        PlotPowerGenerationButton       matlab.ui.control.Button
        CreatePowGencsvButton           matlab.ui.control.Button
        PlotPowerGenPerSideButton       matlab.ui.control.Button
        PowerGenPerSidecsvButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: 
        % CalculatePowerandThermalHistoryButton
        function CalculatePowerandThermalHistoryButtonPushed(app, event)
            
            
            MassSat = app.MasskgEditField.Value;
            SpecificHeat = app.SpecificHeatJkgKEditField.Value;
            AbsorpSolCell = app.SolarCellAbsorptivityEditField.Value;
            EmissSolCell = app.SolarCellEmissivityEditField.Value;
            AbsorpIRSolCell =app.SolarCellEmissivityEditField.Value;
            AbsorpSatSides = app.AllOtherAbsorptivityEditField.Value;
            EmissSatSides = app.AllOtherEmissivityEditField.Value;
            AbsorpIRSatSides = app.AllOtherEmissivityEditField.Value;
            InitialBatteryCharge = app.IntitialBatteryChargeWhrEditField.Value;
            BatteryCapacity = app.BatteryCapacityWhrEditField.Value;
            
            
            assignin('base', 'MassSat', app.MasskgEditField.Value)
            assignin('base', 'SpecificHeat', app.SpecificHeatJkgKEditField.Value)
            assignin('base', 'AbsorpSolCell', app.SolarCellAbsorptivityEditField.Value)
            assignin('base', 'EmissSolCell', app.SolarCellEmissivityEditField.Value)
            assignin('base', 'AbsorpIRSolCell', app.SolarCellEmissivityEditField.Value)
            assignin('base', 'AbsorpSatSides', app.AllOtherAbsorptivityEditField.Value)
            assignin('base', 'EmissSatSides', app.AllOtherEmissivityEditField.Value)
            assignin('base', 'AbsorpIRSatSides', app.AllOtherEmissivityEditField.Value)
            
            assignin('base', 'BatteryCapacity', app.BatteryCapacityWhrEditField.Value)
            assignin('base', 'InitialBatteryCharge', app.IntitialBatteryChargeWhrEditField.Value)
            assignin('base', 'BatteryChargeLowerLimit', app.ChargeLowerLimitWhrEditField.Value)
            
            if strcmp(app.CelsiusorKelvins.Value, 'Kelvins')
                TempInitial = app.InitialTemperatureEditField.Value;
                assignin('base', 'TempInitial', app.InitialTemperatureEditField.Value)
                if app.CheckBox.Value == 1
                    assignin('base', 'SurvivalMinTemp', app.SurvivalMinEditField.Value)
                    assignin('base', 'SurvivalMaxTemp', app.SurvivalMaxEditField.Value)
                else
                    assignin('base', 'SurvivalMinTemp', NaN)
                    assignin('base', 'SurvivalMaxTemp', NaN)
                end
                if app.CheckBox_2.Value == 1
                    assignin('base', 'OperationalMinTemp', app.OperationalMinEditField.Value)
                    assignin('base', 'OperationalMaxTemp', app.OperationalMaxEditField.Value)
                else
                    assignin('base', 'OperationalMinTemp', NaN)
                    assignin('base', 'OperationalMaxTemp', NaN)
                end
                if app.CheckBox_3.Value == 1
                    assignin('base', 'ScientificMinTemp', app.ScientificMinEditField.Value)
                    assignin('base', 'ScientificMaxTemp', app.ScientificMaxEditField.Value)
                else
                    assignin('base', 'ScientificMinTemp', NaN)
                    assignin('base', 'ScientificMaxTemp', NaN)
                end
            end
            if strcmp(app.CelsiusorKelvins.Value, 'Celsius')
                TempInitial = app.InitialTemperatureEditField.Value+273;
                assignin('base', 'TempInitial', app.InitialTemperatureEditField.Value+273)
                if app.CheckBox.Value == 1
                    assignin('base', 'SurvivalMinTemp', app.SurvivalMinEditField.Value+273)
                    assignin('base', 'SurvivalMaxTemp', app.SurvivalMaxEditField.Value+273)
                else
                    assignin('base', 'SurvivalMinTemp', NaN)
                    assignin('base', 'SurvivalMaxTemp', NaN)
                end
                if app.CheckBox_2.Value == 1
                    assignin('base', 'OperationalMinTemp', app.OperationalMinEditField.Value+273)
                    assignin('base', 'OperationalMaxTemp', app.OperationalMaxEditField.Value+273)
                else
                    assignin('base', 'OperationalMinTemp', NaN)
                    assignin('base', 'OperationalMaxTemp', NaN)
                end
                if app.CheckBox_3.Value == 1
                    assignin('base', 'ScientificMinTemp', app.ScientificMinEditField.Value+273)
                    assignin('base', 'ScientificMaxTemp', app.ScientificMaxEditField.Value+273)
                else
                    assignin('base', 'ScientificMinTemp', NaN)
                    assignin('base', 'ScientificMaxTemp', NaN)
                end
            end
            
            
            AreaSolarCells = evalin('base', 'AreaSolarCells');
            AreaSat = evalin('base', 'AreaSat');
            Sat2EarthECEF = evalin('base', 'Sat2EarthECEF');
            Sat2SunECEF = evalin('base', 'Sat2SunECEF');
            Sat2SunBody = evalin('base', 'Sat2SunBody');
            SatRPYAnglesECEF = evalin('base', 'SatRPYAnglesECEF');
            Time = evalin('base', 'Time');
            TimeStep = evalin('base', 'TimeStep');
            SolEffcy = evalin('base', 'SolEffcy');
            EnergyUseTotalComponents = evalin('base', 'EnergyUseTotalComponents');
            HeatGenerationTotalComponents = evalin('base', 'HeatGenerationTotalComponents');
            
            f = uifigure;
            uiprogressdlg(f,'Title','Computing Power and Thermal. This may take minutes.','Indeterminate','on');
            
            [SolarIrr, EclipseBinary, DirectIrrPerSide, AlbedoPerSide, IRPerSide, QinDirSolar, QinAlbedo, QinEarthIR, QoutSatRad, Temp, PowerDirectPerSide, PowerAlbedoPerSide, BatteryCharge, Iterations] = ...
                PowerAndThermalMainLoop(AreaSolarCells, AreaSat, Sat2EarthECEF, Sat2SunECEF, Sat2SunBody, SatRPYAnglesECEF, Time, TimeStep, InitialBatteryCharge, BatteryCapacity,...
                EmissSatSides, AbsorpSatSides, AbsorpIRSatSides, EmissSolCell, AbsorpSolCell, AbsorpIRSolCell, MassSat, SpecificHeat, SolEffcy, EnergyUseTotalComponents, HeatGenerationTotalComponents, TempInitial);
            
            close(f);
            
            assignin('base', 'SolarIrr', SolarIrr)
            assignin('base', 'EclipseBinary', EclipseBinary)
            assignin('base', 'DirectIrrPerSide', DirectIrrPerSide)
            assignin('base', 'AlbedoPerSide', AlbedoPerSide)
            assignin('base', 'IRPerSide', IRPerSide)
            assignin('base', 'QinDirSolar', QinDirSolar)
            assignin('base', 'QinAlbedo', QinAlbedo)
            assignin('base', 'QinEarthIR', QinEarthIR)
            assignin('base', 'QoutSatRad', QoutSatRad)
            assignin('base', 'Temp', Temp)
            assignin('base', 'PowerDirectPerSide', PowerDirectPerSide)
            assignin('base', 'PowerAlbedoPerSide', PowerAlbedoPerSide)
            assignin('base', 'BatteryCharge', BatteryCharge)
            assignin('base', 'Iterations', Iterations)
        end

        % Button pushed function: SatSizeandSolarPanelDefinitionButton
        function SatSizeandSolarPanelDefinitionButtonPushed(app, event)
            SizeSolarPanelsGUI
        end

        % Button pushed function: ComponentsDefinitionButton
        function ComponentsDefinitionButtonPushed(app, event)
            ComponentsPowerUsageGUI
        end

        % Button pushed function: PlotTemperatureButton
        function PlotTemperatureButtonPushed(app, event)
            Time = evalin('base', 'Time');
            Temp = evalin('base', 'Temp');
            Iterations = evalin('base', 'Iterations');
            SurvivalMinTemp = evalin('base', 'SurvivalMinTemp');
            SurvivalMaxTemp = evalin('base', 'SurvivalMaxTemp');
            OperationalMinTemp = evalin('base', 'OperationalMinTemp');
            OperationalMaxTemp = evalin('base', 'OperationalMaxTemp');
            ScientificMinTemp = evalin('base', 'ScientificMinTemp');
            ScientificMaxTemp = evalin('base', 'ScientificMaxTemp');
            Plot_Temperature(Time, Temp, Iterations,SurvivalMinTemp,SurvivalMaxTemp,OperationalMinTemp,OperationalMaxTemp, ScientificMinTemp, ScientificMaxTemp)
        end

        % Button pushed function: PlotBatteryChargeButton
        function PlotBatteryChargeButtonPushed(app, event)
            Time = evalin('base', 'Time');
            BatteryCharge = evalin('base', 'BatteryCharge');
            Iterations = evalin('base', 'Iterations');
            BatteryCapacity = evalin('base', 'BatteryCapacity');
            BatteryChargeLowerLimit = evalin('base', 'BatteryChargeLowerLimit');
            Plot_BatteryCharge(Time, BatteryCharge, BatteryCapacity, BatteryChargeLowerLimit, Iterations)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.SurvivalMinEditField.Enable = 'on';
                app.SurvivalMaxEditField.Enable = 'on';
                app.CheckBox_2.Enable = 'on';
            elseif value == 0
                app.SurvivalMinEditField.Enable = 'off';
                app.SurvivalMaxEditField.Enable = 'off';
                app.CheckBox_2.Value = 0;
                app.CheckBox_2.Enable = 'off';
                app.OperationalMinEditField.Enable = 'off';
                app.OperationalMaxEditField.Enable = 'off';
                app.CheckBox_3.Value = 0;
                app.CheckBox_3.Enable = 'off';
                app.ScientificMinEditField.Enable = 'off';
                app.ScientificMaxEditField.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1
                app.OperationalMinEditField.Enable = 'on';
                app.OperationalMaxEditField.Enable = 'on';
                app.CheckBox_3.Enable = 'on';
            elseif value == 0
                app.OperationalMinEditField.Enable = 'off';
                app.OperationalMaxEditField.Enable = 'off';
                app.CheckBox_3.Value = 0;
                app.CheckBox_3.Enable = 'off';
                app.ScientificMinEditField.Enable = 'off';
                app.ScientificMaxEditField.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value == 1
                app.ScientificMinEditField.Enable = 'on';
                app.ScientificMaxEditField.Enable = 'on';
            elseif value == 0
                app.ScientificMinEditField.Enable = 'off';
                app.ScientificMaxEditField.Enable = 'off';
            end
        end

        % Button pushed function: CreateTempcsvButton
        function CreateTempcsvButtonPushed(app, event)
            Time = evalin('base', 'Time');
            Temp = evalin('base', 'Temp');
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, Temp);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'TempKelvins'};
            writetable(TableA, filename)
            
        end

        % Button pushed function: CreateBatChargecsvButton
        function CreateBatChargecsvButtonPushed(app, event)
            Time = evalin('base', 'Time');
            BatteryCharge = evalin('base', 'BatteryCharge');
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, BatteryCharge);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'BatteryChargeWhr'};
            writetable(TableA, filename)
        end

        % Button pushed function: PlotHeatEnteringLeavingButton
        function PlotHeatEnteringLeavingButtonPushed(app, event)
            Time = evalin('base', 'Time');
            QinDirSolar = evalin('base', 'QinDirSolar');
            QinAlbedo = evalin('base', 'QinAlbedo');
            QinEarthIR = evalin('base', 'QinEarthIR');
            QoutSatRad = evalin('base', 'QoutSatRad');
            QCompGen = evalin('base', 'HeatGenerationTotalComponents');
            Plot_Heat(Time, QinDirSolar, QinAlbedo, QinEarthIR, QoutSatRad, QCompGen)
        end

        % Button pushed function: CreateHeatRatecsvButton
        function CreateHeatRatecsvButtonPushed(app, event)
            Time = evalin('base', 'Time');
            QinDirSolar = evalin('base', 'QinDirSolar');
            QinAlbedo = evalin('base', 'QinAlbedo');
            QinEarthIR = evalin('base', 'QinEarthIR');
            QoutSatRad = evalin('base', 'QoutSatRad');
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, QinDirSolar, QinAlbedo, QinEarthIR, QoutSatRad);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'HeatInDirSolarW', 'HeatInAlbedoW', 'HeatInEarthIRW', 'HeatOutSatRadW'};
            writetable(TableA, filename)
        end

        % Button pushed function: PlotPowerGenerationButton
        function PlotPowerGenerationButtonPushed(app, event)
            Time = evalin('base', 'Time');
            PowerDirectPerSide = evalin('base', 'PowerDirectPerSide');
            PowerAlbedoPerSide = evalin('base', 'PowerAlbedoPerSide');
            
            Plot_PowerGeneration(Time, PowerDirectPerSide, PowerAlbedoPerSide)
        end

        % Button pushed function: CreatePowGencsvButton
        function CreatePowGencsvButtonPushed(app, event)
            Time = evalin('base', 'Time');
            PowerDirectPerSide = evalin('base', 'PowerDirectPerSide');
            PowerAlbedoPerSide = evalin('base', 'PowerAlbedoPerSide');
            PowerDirect = sum(PowerDirectPerSide, 2);
            PowerAlbedo = sum(PowerAlbedoPerSide, 2);
            
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, PowerDirect, PowerAlbedo);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'PowerDirectW', 'PowerAlbedoW'};
            writetable(TableA, filename)
        end

        % Button pushed function: PlotPowerGenPerSideButton
        function PlotPowerGenPerSideButtonPushed(app, event)
            Time = evalin('base', 'Time');
            PowerDirectPerSide = evalin('base', 'PowerDirectPerSide');
            PowerAlbedoPerSide = evalin('base', 'PowerAlbedoPerSide');
            
            Plot_PowerGenerationPerSide(Time, PowerDirectPerSide, PowerAlbedoPerSide)
        end

        % Button pushed function: PowerGenPerSidecsvButton
        function PowerGenPerSidecsvButtonPushed(app, event)
            Time = evalin('base', 'Time');
            PowerDirectPerSide = evalin('base', 'PowerDirectPerSide');
            PowerAlbedoPerSide = evalin('base', 'PowerAlbedoPerSide');
            PowerPerSide = PowerDirectPerSide + PowerAlbedoPerSide;
            Power1 = PowerPerSide(:,1);
            Power2 = PowerPerSide(:,2);
            Power3 = PowerPerSide(:,3);
            Power4 = PowerPerSide(:,4);
            Power5 = PowerPerSide(:,5);
            Power6 = PowerPerSide(:,6);
            
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Time, Power1, Power2, Power3, Power4, Power5, Power6);
            TableA.Properties.VariableNames = {'TimeEpochSec', 'Power1W', 'Power2W', 'Power3W', 'Power4W', 'Power5W', 'Power6W'};
            writetable(TableA, filename)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HealthPowerThermalLabel = uilabel(app.UIFigure);
            app.HealthPowerThermalLabel.Text = 'Health, Power, Thermal';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Limits = [0 Inf];
            app.MasskgEditField.ValueDisplayFormat = '%11.6g';
            app.MasskgEditField.Value = 12;
            app.SolarCellAbsorptivityEditFieldLabel = uilabel(app.UIFigure);
            app.SolarCellAbsorptivityEditFieldLabel.Text = 'Solar Cell Absorptivity';
            app.SolarCellAbsorptivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolarCellAbsorptivityEditField.Value = 0.805;
            app.SolarCellEmissivityEditFieldLabel = uilabel(app.UIFigure);
            app.SolarCellEmissivityEditFieldLabel.Text = 'Solar Cell Emissivity';
            app.SolarCellEmissivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolarCellEmissivityEditField.Value = 0.825;
            app.CalculatePowerandThermalHistoryButton = uibutton(app.UIFigure, 'push');
            app.CalculatePowerandThermalHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePowerandThermalHistoryButtonPushed, true);
            app.CalculatePowerandThermalHistoryButton.Text = 'Calculate Power and Thermal History';
            app.InitialTemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTemperatureEditField.Value = 30;
            app.SpecificHeatJkgKLabel = uilabel(app.UIFigure);
            app.SpecificHeatJkgKLabel.Text = 'Specific Heat (J/(kg*K))';
            app.SpecificHeatJkgKEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificHeatJkgKEditField.Value = 864;
            app.AllOtherAbsorptivityEditFieldLabel = uilabel(app.UIFigure);
            app.AllOtherAbsorptivityEditFieldLabel.Text = 'All Other Absorptivity';
            app.AllOtherAbsorptivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AllOtherAbsorptivityEditField.Value = 0.14;
            app.AllOtherEmissivityEditFieldLabel = uilabel(app.UIFigure);
            app.AllOtherEmissivityEditFieldLabel.Text = 'All Other Emissivity';
            app.AllOtherEmissivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AllOtherEmissivityEditField.Value = 0.84;
            app.BatteryCapacityWhrEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryCapacityWhrEditFieldLabel.Text = 'Battery Capacity (Whr)';
            app.BatteryCapacityWhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatteryCapacityWhrEditField.Value = 92;
            app.IntitialBatteryChargeWhrEditFieldLabel = uilabel(app.UIFigure);
            app.IntitialBatteryChargeWhrEditFieldLabel.Text = 'Intitial Battery Charge (Whr)';
            app.IntitialBatteryChargeWhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntitialBatteryChargeWhrEditField.Value = 76;
            app.BatteryLabel = uilabel(app.UIFigure);
            app.BatteryLabel.Text = 'Battery';
            app.TemperatureLimitsLabel = uilabel(app.UIFigure);
            app.TemperatureLimitsLabel.Text = 'Temperature Limits ';
            app.CelsiusorKelvins = uiswitch(app.UIFigure, 'rocker');
            app.CelsiusorKelvins.Items = {'Celsius', 'Kelvins'};
            app.CelsiusorKelvins.Value = 'Celsius';
            app.SurvivalEditFieldLabel = uilabel(app.UIFigure);
            app.SurvivalEditFieldLabel.Text = 'Survival';
            app.SurvivalMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurvivalMinEditField.Value = -40;
            app.OperationalEditFieldLabel = uilabel(app.UIFigure);
            app.OperationalEditFieldLabel.Text = 'Operational';
            app.OperationalMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperationalMinEditField.Value = -20;
            app.ScientificEditFieldLabel = uilabel(app.UIFigure);
            app.ScientificEditFieldLabel.Text = 'Scientific';
            app.ScientificMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurvivalMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurvivalMaxEditField.Value = 85;
            app.OperationalMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperationalMaxEditField.Value = 75;
            app.ScientificMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScientificMaxEditField.Value = 50;
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.ChargeLowerLimitWhrEditFieldLabel = uilabel(app.UIFigure);
            app.ChargeLowerLimitWhrEditFieldLabel.Text = 'Charge Lower Limit (Whr)';
            app.ChargeLowerLimitWhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChargeLowerLimitWhrEditField.Value = 46;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Current values of Absorptivity, Emissivity, and Specific Heat are ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'good estimates for the average cubesat';
            app.SatSizeandSolarPanelDefinitionButton = uibutton(app.UIFigure, 'push');
            app.SatSizeandSolarPanelDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @SatSizeandSolarPanelDefinitionButtonPushed, true);
            app.SatSizeandSolarPanelDefinitionButton.Text = 'Sat Size and Solar Panel Definition';
            app.ComponentsDefinitionButton = uibutton(app.UIFigure, 'push');
            app.ComponentsDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentsDefinitionButtonPushed, true);
            app.ComponentsDefinitionButton.Text = 'Components Definition';
            app.PlotTemperatureButton = uibutton(app.UIFigure, 'push');
            app.PlotTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTemperatureButtonPushed, true);
            app.PlotTemperatureButton.Text = 'Plot Temperature';
            app.PlotBatteryChargeButton = uibutton(app.UIFigure, 'push');
            app.PlotBatteryChargeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBatteryChargeButtonPushed, true);
            app.PlotBatteryChargeButton.Text = 'Plot Battery Charge';
            app.CreateTempcsvButton = uibutton(app.UIFigure, 'push');
            app.CreateTempcsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTempcsvButtonPushed, true);
            app.CreateTempcsvButton.Text = 'Create Temp .csv';
            app.CreateBatChargecsvButton = uibutton(app.UIFigure, 'push');
            app.CreateBatChargecsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBatChargecsvButtonPushed, true);
            app.CreateBatChargecsvButton.Text = 'Create Bat Charge .csv';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.MassMaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MassMaterialPropertiesLabel.Text = 'Mass/Material Properties';
            app.PlotHeatEnteringLeavingButton = uibutton(app.UIFigure, 'push');
            app.PlotHeatEnteringLeavingButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHeatEnteringLeavingButtonPushed, true);
            app.PlotHeatEnteringLeavingButton.Text = 'Plot Heat Entering/Leaving';
            app.CreateHeatRatecsvButton = uibutton(app.UIFigure, 'push');
            app.CreateHeatRatecsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateHeatRatecsvButtonPushed, true);
            app.CreateHeatRatecsvButton.Text = 'Create Heat Rate .csv';
            app.PlotPowerGenerationButton = uibutton(app.UIFigure, 'push');
            app.PlotPowerGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPowerGenerationButtonPushed, true);
            app.PlotPowerGenerationButton.Text = 'Plot Power Generation';
            app.CreatePowGencsvButton = uibutton(app.UIFigure, 'push');
            app.CreatePowGencsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePowGencsvButtonPushed, true);
            app.CreatePowGencsvButton.Text = 'Create Pow Gen .csv';
            app.PlotPowerGenPerSideButton = uibutton(app.UIFigure, 'push');
            app.PlotPowerGenPerSideButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPowerGenPerSideButtonPushed, true);
            app.PlotPowerGenPerSideButton.Text = 'Plot Power Gen Per Side';
            app.PowerGenPerSidecsvButton = uibutton(app.UIFigure, 'push');
            app.PowerGenPerSidecsvButton.ButtonPushedFcn = createCallbackFcn(app, @PowerGenPerSidecsvButtonPushed, true);
            app.PowerGenPerSidecsvButton.Text = 'Power Gen Per Side .csv';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HealthPowerHeatGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end