classdef ImagerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InstFOVofImagerPlotsButton      matlab.ui.control.Button
        PlotAllAccessAreasButton        matlab.ui.control.Button
        AvailableTimeStepsEditFieldLabel  matlab.ui.control.Label
        AvailableTimeStepsEditField     matlab.ui.control.NumericEditField
        TimeSteptoViewEditFieldLabel    matlab.ui.control.Label
        TimeSteptoViewEditField         matlab.ui.control.NumericEditField
        DateTimeEditFieldLabel          matlab.ui.control.Label
        DateTimeEditField               matlab.ui.control.EditField
        TimeStepLengthsEditFieldLabel   matlab.ui.control.Label
        TimeStepLengthsEditField        matlab.ui.control.NumericEditField
        NoteLabel                       matlab.ui.control.Label
        LatEditFieldLabel               matlab.ui.control.Label
        LatEditField                    matlab.ui.control.NumericEditField
        LonEditFieldLabel               matlab.ui.control.Label
        LonEditField                    matlab.ui.control.NumericEditField
        AltkmEditFieldLabel             matlab.ui.control.Label
        AltkmEditField                  matlab.ui.control.NumericEditField
        ImagerFOVShapeDropDownLabel     matlab.ui.control.Label
        ImagerFOVShapeDropDown          matlab.ui.control.DropDown
        HalfAngle1EditFieldLabel        matlab.ui.control.Label
        HalfAngle1EditField             matlab.ui.control.NumericEditField
        HalfAngle2EditFieldLabel        matlab.ui.control.Label
        HalfAngle2EditField             matlab.ui.control.NumericEditField
        LLAtoUseSwitchLabel             matlab.ui.control.Label
        LLAtoUseSwitch                  matlab.ui.control.RockerSwitch
        LatEditField_2                  matlab.ui.control.NumericEditField
        LonEditField_2                  matlab.ui.control.NumericEditField
        AltEditField_2                  matlab.ui.control.NumericEditField
        OrientationAnglesofSensorViewLabel  matlab.ui.control.Label
        SpinAroundNadirZEditFieldLabel  matlab.ui.control.Label
        SpinAroundNadirZEditField       matlab.ui.control.NumericEditField
        TiltAlongLongitudeXEditFieldLabel  matlab.ui.control.Label
        TiltAlongLongitudeXEditField    matlab.ui.control.NumericEditField
        TiltAlongLatitudeYEditFieldLabel  matlab.ui.control.Label
        TiltAlongLatitudeYEditField     matlab.ui.control.NumericEditField
        NoteLabel_2                     matlab.ui.control.Label
        StartTimeStepEditFieldLabel     matlab.ui.control.Label
        StartTimeStepEditField          matlab.ui.control.NumericEditField
        EndTimeStepEditFieldLabel       matlab.ui.control.Label
        EndTimeStepEditField            matlab.ui.control.NumericEditField
        DateTimeEditField_2             matlab.ui.control.EditField
        DateTimeEditField_3             matlab.ui.control.EditField
        NoteLabel_5                     matlab.ui.control.Label
        ImagerFOVSimulationLabel        matlab.ui.control.Label
        AccessAreaOverTimeIntervalLabel  matlab.ui.control.Label
        InstantaneousFOVLabel           matlab.ui.control.Label
        NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel  matlab.ui.control.Label
        NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_2  matlab.ui.control.Label
        NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_3  matlab.ui.control.Label
        NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_4  matlab.ui.control.Label
        InstFOVBoundingLatLonscsvFileButton  matlab.ui.control.Button
        ImageGenerationButton           matlab.ui.control.Button
        PixelsWidthEditFieldLabel       matlab.ui.control.Label
        PixelsWidthEditField            matlab.ui.control.NumericEditField
        PixelsHeightEditFieldLabel      matlab.ui.control.Label
        PixelsHeightEditField           matlab.ui.control.NumericEditField
        ImagesOverIntervalGenerationButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                LatLongAlt = evalin('base', 'LatLongAlt');
                Time = evalin('base', 'Time');
                TimeUTCG = evalin('base', 'TimeUTCG');            
                TimeStepsNumber = size(Time,1);
                TimeStepSize = Time(2,1)-Time(1,1);
                app.AvailableTimeStepsEditField.Value = TimeStepsNumber;
                app.EndTimeStepEditField.Value = TimeStepsNumber;
                app.TimeStepLengthsEditField.Value = TimeStepSize;
                app.LatEditField.Value = LatLongAlt(1,1);
                app.LonEditField.Value = LatLongAlt(1,2);
                app.AltkmEditField.Value = LatLongAlt(1,3)/1000;
                app.DateTimeEditField.Value = TimeUTCG{1}(1:end-10);
                app.DateTimeEditField_2.Value = TimeUTCG{1}(1:end-10);
                app.DateTimeEditField_3.Value = TimeUTCG{end}(1:end-10);
            catch
                app.NoteLabel_2.Text = "Note: No STK data found! Feel free to use without STK";
                app.LLAtoUseSwitch.Value = 'User Input';
                app.LatEditField.Enable = 'off';
                app.LonEditField.Enable = 'off';
                app.AltkmEditField.Enable = 'off';
                app.LatEditField_2.Enable = 'on';
                app.LonEditField_2.Enable = 'on';
                app.AltEditField_2.Enable = 'on';
                app.LLAtoUseSwitch.Enable = 'off';
                app.AvailableTimeStepsEditField.Enable = 'off';
                app.TimeSteptoViewEditField.Enable = 'off';
                app.DateTimeEditField.Enable = 'off';
                app.TimeStepLengthsEditField.Enable = 'off';
                app.StartTimeStepEditField.Enable = 'off';
                app.EndTimeStepEditField.Enable = 'off';
                app.PlotAllAccessAreasButton.Enable = 'off';
                app.DateTimeEditField_2.Enable = 'off';
                app.DateTimeEditField_3.Enable = 'off';
                app.AccessAreaOverTimeIntervalLabel.Enable = 'off';
                app.NoteLabel_5.Text = 'Note: Unable to use without STK data';
            end
        end

        % Value changed function: TimeSteptoViewEditField
        function TimeSteptoViewEditFieldValueChanged(app, event)
            LatLongAlt = evalin('base', 'LatLongAlt');
            TimeUTCG = evalin('base', 'TimeUTCG');
            value = app.TimeSteptoViewEditField.Value;
            if value > app.AvailableTimeStepsEditField.Value
                app.NoteLabel.Text = "Note: Please enter a valid time step!";
                app.DateTimeEditField.Value = 'Time Step Not Valid!';
                app.LatEditField.Value = 0;
                app.LonEditField.Value = 0;
                app.AltkmEditField.Value = 0;
                app.InstFOVofImagerPlotsButton.Enable = 'off';
            else
                app.DateTimeEditField.Value = TimeUTCG{value}(1:end-10);
                app.NoteLabel.Text = "Note: ";
                app.LatEditField.Value = LatLongAlt(value,1);
                app.LonEditField.Value = LatLongAlt(value,2);
                app.AltkmEditField.Value = LatLongAlt(value,3)/1000;
                app.InstFOVofImagerPlotsButton.Enable = 'on';
            end
        end

        % Value changed function: ImagerFOVShapeDropDown
        function ImagerFOVShapeDropDownValueChanged(app, event)
            value = app.ImagerFOVShapeDropDown.Value;
            if value == "Rectangle"
                app.HalfAngle1EditField.Enable = 'on';
                app.HalfAngle2EditField.Enable = 'on';
            else
                app.HalfAngle1EditField.Enable = 'on';
                app.HalfAngle2EditField.Enable = 'off';
            end
        end

        % Value changed function: LLAtoUseSwitch
        function LLAtoUseSwitchValueChanged(app, event)
            value = app.LLAtoUseSwitch.Value;
            if value == "From STK"
                app.LatEditField.Enable = 'on';
                app.LonEditField.Enable = 'on';
                app.AltkmEditField.Enable = 'on';
                app.LatEditField_2.Enable = 'off';
                app.LonEditField_2.Enable = 'off';
                app.AltEditField_2.Enable = 'off';
            else
                app.LatEditField.Enable = 'off';
                app.LonEditField.Enable = 'off';
                app.AltkmEditField.Enable = 'off';
                app.LatEditField_2.Enable = 'on';
                app.LonEditField_2.Enable = 'on';
                app.AltEditField_2.Enable = 'on';
            end
        end

        % Button pushed function: InstFOVofImagerPlotsButton
        function InstFOVofImagerPlotsButtonPushed(app, event)
            RectOrCirc = app.ImagerFOVShapeDropDown.Value;
            HalfAngle1 = app.HalfAngle1EditField.Value;
            HalfAngle2 = app.HalfAngle2EditField.Value;
            OrientationAngles = [app.TiltAlongLongitudeXEditField.Value, app.TiltAlongLatitudeYEditField.Value, app.SpinAroundNadirZEditField.Value];
            if strcmp(app.LLAtoUseSwitch.Value, 'From STK')
                LLA = [app.LatEditField.Value, app.LonEditField.Value, app.AltkmEditField.Value];
            else
                LLA = [app.LatEditField_2.Value, app.LonEditField_2.Value, app.AltEditField_2.Value];
            end
            
            [~] = ImagerFOV(HalfAngle1, HalfAngle2, RectOrCirc, LLA, OrientationAngles, 1);
        end

        % Value changed function: StartTimeStepEditField
        function StartTimeStepEditFieldValueChanged(app, event)
            value = app.StartTimeStepEditField.Value;
            TimeUTCG = evalin('base', 'TimeUTCG');
            if value > app.AvailableTimeStepsEditField.Value
                app.DateTimeEditField_2.Value = 'Time Step Not Valid!';
            else
                app.DateTimeEditField_2.Value = TimeUTCG{value}(1:end-10);
            end
            if strcmp(app.DateTimeEditField_2.Value, 'Time Step Not Valid!') || strcmp(app.DateTimeEditField_3.Value, 'Time Step Not Valid!')
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotAllAccessAreasButton.Enable = 'off';
            else
                app.NoteLabel_5.Text = "Note: ";
                app.PlotAllAccessAreasButton.Enable = 'on';
            end
        end

        % Value changed function: EndTimeStepEditField
        function EndTimeStepEditFieldValueChanged(app, event)
            value = app.EndTimeStepEditField.Value;
            TimeUTCG = evalin('base', 'TimeUTCG');
            if value > app.AvailableTimeStepsEditField.Value
                app.DateTimeEditField_3.Value = 'Time Step Not Valid!';
            else
                app.DateTimeEditField_3.Value = TimeUTCG{value}(1:end-10);
            end
            if strcmp(app.DateTimeEditField_2.Value, 'Time Step Not Valid!') || strcmp(app.DateTimeEditField_3.Value, 'Time Step Not Valid!')
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotAllAccessAreasButton.Enable = 'off';
            else
                app.NoteLabel_5.Text = "Note: ";
                app.PlotAllAccessAreasButton.Enable = 'on';
            end
        end

        % Button pushed function: PlotAllAccessAreasButton
        function PlotAllAccessAreasButtonPushed(app, event)
            LatLongAlt = evalin('base', 'LatLongAlt');
            if app.StartTimeStepEditField.Value > app.EndTimeStepEditField.Value
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotAllAccessAreasButton.Enable = 'off';
            else
                LatLongAltReduced = LatLongAlt(app.StartTimeStepEditField.Value:app.EndTimeStepEditField.Value,:);
                app.NoteLabel_5.Text = "Note: ";
                PlotAllAccessAreas(LatLongAltReduced)
            end

        end

        % Button pushed function: InstFOVBoundingLatLonscsvFileButton
        function InstFOVBoundingLatLonscsvFileButtonPushed(app, event)
            RectOrCirc = app.ImagerFOVShapeDropDown.Value;
            HalfAngle1 = app.HalfAngle1EditField.Value;
            HalfAngle2 = app.HalfAngle2EditField.Value;
            OrientationAngles = [app.TiltAlongLongitudeXEditField.Value, app.TiltAlongLatitudeYEditField.Value, app.SpinAroundNadirZEditField.Value];
            if strcmp(app.LLAtoUseSwitch.Value, 'From STK')
                LLA = [app.LatEditField.Value, app.LonEditField.Value, app.AltkmEditField.Value];
            else
                LLA = [app.LatEditField_2.Value, app.LonEditField_2.Value, app.AltEditField_2.Value];
            end
            
            [BoundingLatLons] = ImagerFOV(HalfAngle1, HalfAngle2, RectOrCirc, LLA, OrientationAngles, 0);
            Latitudes = BoundingLatLons(:,1);
            Longitudes = BoundingLatLons(:,2);
            
            filename = inputdlg('Enter name of .csv to create');
            filename = strcat(filename{1,1}, '.csv');
            TableA = table(Latitudes, Longitudes);
            writetable(TableA, filename)
        end

        % Button pushed function: ImageGenerationButton
        function ImageGenerationButtonPushed(app, event)
            FOV = max(app.HalfAngle1EditField.Value, app.HalfAngle2EditField.Value);
            Angles = [app.TiltAlongLongitudeXEditField.Value, app.TiltAlongLatitudeYEditField.Value, app.SpinAroundNadirZEditField.Value];
            
            FilePath = pwd;
            FilePath = [FilePath, '\SimulatedImagery'];
            
            if strcmp(app.LLAtoUseSwitch.Value, 'From STK')
                LLA = [app.LatEditField.Value, app.LonEditField.Value, app.AltkmEditField.Value];
            else
                LLA = [app.LatEditField_2.Value, app.LonEditField_2.Value, app.AltEditField_2.Value];
            end
            
            PixelDims = [app.PixelsWidthEditField.Value, app.PixelsHeightEditField.Value];
            
            Call_STK_ImageGeneration(FOV, LLA, Angles, PixelDims, FilePath)
        end

        % Button pushed function: ImagesOverIntervalGenerationButton
        function ImagesOverIntervalGenerationButtonPushed(app, event)
            FOV = max(app.HalfAngle1EditField.Value, app.HalfAngle2EditField.Value);
            Angles = [app.TiltAlongLongitudeXEditField.Value, app.TiltAlongLatitudeYEditField.Value, app.SpinAroundNadirZEditField.Value];
            
            FilePath = pwd;
            FilePath = [FilePath, '\SimulatedImagery'];
            PixelDims = [app.PixelsWidthEditField.Value, app.PixelsHeightEditField.Value];
            
            LatLongAlt = evalin('base', 'LatLongAlt');
            if app.StartTimeStepEditField.Value > app.EndTimeStepEditField.Value
                app.NoteLabel_5.Text = "Note: Time Steps Not Valid!";
                app.PlotAllAccessAreasButton.Enable = 'off';
            else
                LatLongAltReduced = LatLongAlt(app.StartTimeStepEditField.Value:app.EndTimeStepEditField.Value,:);
                app.NoteLabel_5.Text = "Note: ";
                Call_STK_ImagesGeneration(FOV, LatLongAltReduced, Angles, PixelDims, FilePath)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InstFOVofImagerPlotsButton = uibutton(app.UIFigure, 'push');
            app.InstFOVofImagerPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @InstFOVofImagerPlotsButtonPushed, true);
            app.InstFOVofImagerPlotsButton.Text = 'Inst. FOV of Imager Plots';
            app.PlotAllAccessAreasButton = uibutton(app.UIFigure, 'push');
            app.PlotAllAccessAreasButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllAccessAreasButtonPushed, true);
            app.PlotAllAccessAreasButton.Text = 'Plot All Access Areas';
            app.AvailableTimeStepsEditFieldLabel = uilabel(app.UIFigure);
            app.AvailableTimeStepsEditFieldLabel.Text = 'Available Time Steps';
            app.AvailableTimeStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AvailableTimeStepsEditField.Editable = 'off';
            app.TimeSteptoViewEditFieldLabel = uilabel(app.UIFigure);
            app.TimeSteptoViewEditFieldLabel.Text = 'Time Step to View';
            app.TimeSteptoViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeSteptoViewEditField.Limits = [1 Inf];
            app.TimeSteptoViewEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSteptoViewEditFieldValueChanged, true);
            app.TimeSteptoViewEditField.Value = 1;
            app.DateTimeEditFieldLabel = uilabel(app.UIFigure);
            app.DateTimeEditFieldLabel.Text = 'Date/Time';
            app.DateTimeEditField = uieditfield(app.UIFigure, 'text');
            app.DateTimeEditField.Editable = 'off';
            app.TimeStepLengthsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepLengthsEditFieldLabel.Text = 'Time Step Length (s)';
            app.TimeStepLengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepLengthsEditField.Editable = 'off';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note:';
            app.LatEditFieldLabel = uilabel(app.UIFigure);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField.Limits = [-90 90];
            app.LatEditField.Editable = 'off';
            app.LonEditFieldLabel = uilabel(app.UIFigure);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.UIFigure, 'numeric');
            app.LonEditField.Limits = [-180 180];
            app.LonEditField.Editable = 'off';
            app.AltkmEditFieldLabel = uilabel(app.UIFigure);
            app.AltkmEditFieldLabel.Text = 'Alt (km)';
            app.AltkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltkmEditField.Limits = [0 Inf];
            app.AltkmEditField.Editable = 'off';
            app.ImagerFOVShapeDropDownLabel = uilabel(app.UIFigure);
            app.ImagerFOVShapeDropDownLabel.Text = 'Imager FOV Shape';
            app.ImagerFOVShapeDropDown = uidropdown(app.UIFigure);
            app.ImagerFOVShapeDropDown.Items = {'Rectangle', 'Circle'};
            app.ImagerFOVShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagerFOVShapeDropDownValueChanged, true);
            app.ImagerFOVShapeDropDown.Value = 'Rectangle';
            app.HalfAngle1EditFieldLabel = uilabel(app.UIFigure);
            app.HalfAngle1EditFieldLabel.Text = 'Half-Angle 1';
            app.HalfAngle1EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfAngle1EditField.Limits = [0 90];
            app.HalfAngle1EditField.Value = 10;
            app.HalfAngle2EditFieldLabel = uilabel(app.UIFigure);
            app.HalfAngle2EditFieldLabel.Text = 'Half-Angle 2';
            app.HalfAngle2EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfAngle2EditField.Limits = [0 90];
            app.HalfAngle2EditField.Value = 10;
            app.LLAtoUseSwitchLabel = uilabel(app.UIFigure);
            app.LLAtoUseSwitchLabel.Text = 'LLA to Use';
            app.LLAtoUseSwitch = uiswitch(app.UIFigure, 'rocker');
            app.LLAtoUseSwitch.Items = {'From STK', 'User Input'};
            app.LLAtoUseSwitch.ValueChangedFcn = createCallbackFcn(app, @LLAtoUseSwitchValueChanged, true);
            app.LLAtoUseSwitch.Value = 'From STK';
            app.LatEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatEditField_2.Limits = [-90 90];
            app.LatEditField_2.Enable = 'off';
            app.LonEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LonEditField_2.Limits = [-180 180];
            app.LonEditField_2.Enable = 'off';
            app.AltEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AltEditField_2.Limits = [1 Inf];
            app.AltEditField_2.Enable = 'off';
            app.AltEditField_2.Value = 500;
            app.OrientationAnglesofSensorViewLabel = uilabel(app.UIFigure);
            app.OrientationAnglesofSensorViewLabel.Text = 'Orientation Angles of Sensor View';
            app.SpinAroundNadirZEditFieldLabel = uilabel(app.UIFigure);
            app.SpinAroundNadirZEditFieldLabel.Text = 'Spin Around Nadir (Z)';
            app.SpinAroundNadirZEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiltAlongLongitudeXEditFieldLabel = uilabel(app.UIFigure);
            app.TiltAlongLongitudeXEditFieldLabel.Text = 'Tilt Along Longitude (X)';
            app.TiltAlongLongitudeXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiltAlongLatitudeYEditFieldLabel = uilabel(app.UIFigure);
            app.TiltAlongLatitudeYEditFieldLabel.Text = 'Tilt Along Latitude (Y)';
            app.TiltAlongLatitudeYEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoteLabel_2 = uilabel(app.UIFigure);
            app.NoteLabel_2.Text = 'Note:';
            app.StartTimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeStepEditFieldLabel.Text = 'Start Time Step';
            app.StartTimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeStepEditField.Limits = [1 Inf];
            app.StartTimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeStepEditFieldValueChanged, true);
            app.StartTimeStepEditField.Value = 1;
            app.EndTimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeStepEditFieldLabel.Text = 'End Time Step';
            app.EndTimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeStepEditField.Limits = [0 Inf];
            app.EndTimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeStepEditFieldValueChanged, true);
            app.EndTimeStepEditField.Value = 2;
            app.DateTimeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.DateTimeEditField_2.Editable = 'off';
            app.DateTimeEditField_3 = uieditfield(app.UIFigure, 'text');
            app.DateTimeEditField_3.Editable = 'off';
            app.NoteLabel_5 = uilabel(app.UIFigure);
            app.NoteLabel_5.Text = 'Note:';
            app.ImagerFOVSimulationLabel = uilabel(app.UIFigure);
            app.ImagerFOVSimulationLabel.Text = 'Imager FOV Simulation';
            app.AccessAreaOverTimeIntervalLabel = uilabel(app.UIFigure);
            app.AccessAreaOverTimeIntervalLabel.Text = 'Access Area Over Time Interval';
            app.InstantaneousFOVLabel = uilabel(app.UIFigure);
            app.InstantaneousFOVLabel.Text = 'Instantaneous FOV';
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel = uilabel(app.UIFigure);
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel.Text = 'Half Angle: If rectangle FOV shape is selected, Half-Angle 1 is the angular ''height'' of the image';
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_2 = uilabel(app.UIFigure);
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_2.Text = 'and Half-Angle 2 is the angular ''width'' of the image. If circular FOV is selected, then Half-Angle';
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_3 = uilabel(app.UIFigure);
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_3.Text = '1 is the angular radius of the the image, and Half-Angle 2 is not needed.';
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_4 = uilabel(app.UIFigure);
            app.NEEDDESCRIPTIONOFHALFANGLEANDORIENTATIONANGLESLabel_4.Text = 'Orientation Angles:';
            app.InstFOVBoundingLatLonscsvFileButton = uibutton(app.UIFigure, 'push');
            app.InstFOVBoundingLatLonscsvFileButton.ButtonPushedFcn = createCallbackFcn(app, @InstFOVBoundingLatLonscsvFileButtonPushed, true);
            app.InstFOVBoundingLatLonscsvFileButton.Text = 'Inst. FOV Bounding Lat/Lons .csv File';
            app.ImageGenerationButton = uibutton(app.UIFigure, 'push');
            app.ImageGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageGenerationButtonPushed, true);
            app.ImageGenerationButton.Text = 'Image Generation';
            app.PixelsWidthEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsWidthEditFieldLabel.Text = 'Pixels Width';
            app.PixelsWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsWidthEditField.Value = 800;
            app.PixelsHeightEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsHeightEditFieldLabel.Text = 'Pixels Height';
            app.PixelsHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsHeightEditField.Value = 600;
            app.ImagesOverIntervalGenerationButton = uibutton(app.UIFigure, 'push');
            app.ImagesOverIntervalGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesOverIntervalGenerationButtonPushed, true);
            app.ImagesOverIntervalGenerationButton.Text = 'Images Over Interval Generation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end