classdef LifetimeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MasskgEditFieldLabel           matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.NumericEditField
        AvgDragAream2EditFieldLabel    matlab.ui.control.Label
        AvgDragAream2EditField         matlab.ui.control.NumericEditField
        RunLifetimeAnalysisButton      matlab.ui.control.Button
        InitialTimePerOrbitminEditFieldLabel  matlab.ui.control.Label
        InitialTimePerOrbitminEditField  matlab.ui.control.NumericEditField
        OrbitsLeftEditFieldLabel       matlab.ui.control.Label
        OrbitsLeftEditField            matlab.ui.control.NumericEditField
        LifetimedaysEditFieldLabel     matlab.ui.control.Label
        LifetimedaysEditField          matlab.ui.control.NumericEditField
        ReentryDateEditFieldLabel      matlab.ui.control.Label
        ReentryDateEditField           matlab.ui.control.EditField
        OrbitsLeftEditField_2          matlab.ui.control.NumericEditField
        LifetimedaysEditField_2        matlab.ui.control.NumericEditField
        ReentryDateEditField_2         matlab.ui.control.EditField
        MinimumLabel                   matlab.ui.control.Label
        BestEstimateLabel              matlab.ui.control.Label
        StandardLabel                  matlab.ui.control.Label
        JacchiaRobertsLabel            matlab.ui.control.Label
        AtmosphericModelLabel          matlab.ui.control.Label
        OrbitsLeftEditField_3          matlab.ui.control.NumericEditField
        LifetimedaysEditField_3        matlab.ui.control.NumericEditField
        ReentryDateEditField_3         matlab.ui.control.EditField
        MaximumLabel_2                 matlab.ui.control.Label
        DTM2012Label_2                 matlab.ui.control.Label
        StartingAltitudekmEditFieldLabel  matlab.ui.control.Label
        StartingAltitudekmEditField    matlab.ui.control.NumericEditField
        AdjustingthealtitudeherewillonlyaffectlifetimeanalysisLabel  matlab.ui.control.Label
        AllotheranalysistoolswilluseinitiallyenteredaltitudeLabel  matlab.ui.control.Label
        MincsvfileButton               matlab.ui.control.Button
        MidcsvfileButton               matlab.ui.control.Button
        MaxcsvfileButton               matlab.ui.control.Button
        OrbitsByTimeStepcsvButton      matlab.ui.control.Button
        LifetimeAnalysisLabel          matlab.ui.control.Label
        DecayAltitudekmEditFieldLabel  matlab.ui.control.Label
        DecayAltitudekmEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                COEs = evalin('base', 'COEs');
                Altitude = COEs(1);
                app.StartingAltitudekmEditField.Value = Altitude-6371;
            catch
            end
        end

        % Button pushed function: RunLifetimeAnalysisButton
        function RunLifetimeAnalysisButtonPushed(app, event)
            try
                EpochDate = evalin('base', 'EpochDate');
                COEs = evalin('base', 'COEs');
            catch
                EpochDate = 'Today';
                COEs = [6371, 0, 51.6, 0, 0, 0];
            end
            
            Mass = app.MasskgEditField.Value;
            DragArea = app.AvgDragAream2EditField.Value;
            
            Altitude = app.StartingAltitudekmEditField.Value;
            COEs(1) = Altitude+6371;
            
            DecayAlt = app.DecayAltitudekmEditField.Value;
            
            f = uifigure;
            uiprogressdlg(f,'Title','Running lifetime analysis. May take a minute','Indeterminate','on');
            
            [OrbitsLifetime] = Call_STK_Lifetime(COEs, EpochDate, Mass, DragArea, DecayAlt);
            
            app.InitialTimePerOrbitminEditField.Value = (OrbitsLifetime.Mid{2,2}-OrbitsLifetime.Mid{1,2})/60;
            
            app.OrbitsLeftEditField.Value = double(OrbitsLifetime.Min{end,3});
            app.LifetimedaysEditField.Value = (OrbitsLifetime.Min{end,2}-OrbitsLifetime.Min{1,2})/(60*60*24);
            app.ReentryDateEditField.Value = OrbitsLifetime.Min{end,1}(1:end-19);
            
            app.OrbitsLeftEditField_2.Value = double(OrbitsLifetime.Mid{end,3});
            app.LifetimedaysEditField_2.Value = (OrbitsLifetime.Mid{end,2}-OrbitsLifetime.Mid{1,2})/(60*60*24);
            app.ReentryDateEditField_2.Value = OrbitsLifetime.Mid{end,1}(1:end-19);
            
            app.OrbitsLeftEditField_3.Value = double(OrbitsLifetime.Max{end,3});
            app.LifetimedaysEditField_3.Value = (OrbitsLifetime.Max{end,2}-OrbitsLifetime.Max{1,2})/(60*60*24);
            app.ReentryDateEditField_3.Value = OrbitsLifetime.Max{end,1}(1:end-19);
            
            assignin('base', 'OrbitsLifetimeMin', OrbitsLifetime.Min)
            assignin('base', 'OrbitsLifetimeMid', OrbitsLifetime.Mid)
            assignin('base', 'OrbitsLifetimeMax', OrbitsLifetime.Max)
            
            
            close(f);
            
            app.OrbitsByTimeStepcsvButton.Enable = 'on';
            app.MincsvfileButton.Enable = 'on';
            app.MidcsvfileButton.Enable = 'on';
            app.MaxcsvfileButton.Enable = 'on';
        end

        % Button pushed function: MincsvfileButton
        function MincsvfileButtonPushed(app, event)
            OrbitsLifetimeMin = evalin('base', 'OrbitsLifetimeMin');
            TimeUTCG = OrbitsLifetimeMin(:,1);
            for k = 1 : length(TimeUTCG)
                cellContents = TimeUTCG{k};
                % Truncate and stick back into the cell
                TimeUTCG{k} = cellContents(1:end-10);
            end
            TimeEpSec = OrbitsLifetimeMin(:,2);
            Orbits = OrbitsLifetimeMin(:,3);
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeUTCG, TimeEpSec, Orbits);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: MidcsvfileButton
        function MidcsvfileButtonPushed(app, event)
            OrbitsLifetimeMid = evalin('base', 'OrbitsLifetimeMid');
            TimeUTCG = OrbitsLifetimeMid(:,1);
            for k = 1 : length(TimeUTCG)
                cellContents = TimeUTCG{k};
                % Truncate and stick back into the cell
                TimeUTCG{k} = cellContents(1:end-10);
            end
            TimeEpSec = OrbitsLifetimeMid(:,2);
            Orbits = OrbitsLifetimeMid(:,3);
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeUTCG, TimeEpSec, Orbits);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: MaxcsvfileButton
        function MaxcsvfileButtonPushed(app, event)
            OrbitsLifetimeMax = evalin('base', 'OrbitsLifetimeMax');
            TimeUTCG = OrbitsLifetimeMax(:,1);
            for k = 1 : length(TimeUTCG)
                cellContents = TimeUTCG{k};
                % Truncate and stick back into the cell
                TimeUTCG{k} = cellContents(1:end-10);
            end
            TimeEpSec = OrbitsLifetimeMax(:,2);
            Orbits = OrbitsLifetimeMax(:,3);
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeUTCG, TimeEpSec, Orbits);
                writetable(TableA, filename)
            catch
            end
        end

        % Button pushed function: OrbitsByTimeStepcsvButton
        function OrbitsByTimeStepcsvButtonPushed(app, event)
            COEs = evalin('base', 'COEs');
            Time = evalin('base', 'Time');
            TimeStep = evalin('base', 'TimeStep');
            
            a = COEs(1);
            mu = 3.986004418*10^5;
            OrbitalPeriod = 2*pi*sqrt(a^3/mu); % [sec], Time of 1 Orbit
            
            for i = 1:size(Time,1)
                OrbitsAtTimeStep(i,1) = TimeStep/OrbitalPeriod*i;
            end
            assignin('base', 'OrbitsAtTimeStep', OrbitsAtTimeStep);
            
            
            TimeEpSec = Time;
            filename = inputdlg('Enter name of .csv to create');
            try
                filename = strcat(filename{1,1}, '.csv');
                TableA = table(TimeEpSec, OrbitsAtTimeStep);
                writetable(TableA, filename)
            catch
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Limits = [0 Inf];
            app.MasskgEditField.Value = 4;
            app.AvgDragAream2EditFieldLabel = uilabel(app.UIFigure);
            app.AvgDragAream2EditFieldLabel.Text = 'Avg Drag Area (m^2)';
            app.AvgDragAream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AvgDragAream2EditField.Limits = [0 Inf];
            app.AvgDragAream2EditField.Value = 0.01;
            app.RunLifetimeAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunLifetimeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunLifetimeAnalysisButtonPushed, true);
            app.RunLifetimeAnalysisButton.Text = 'Run Lifetime Analysis';
            app.InitialTimePerOrbitminEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTimePerOrbitminEditFieldLabel.Text = 'Initial Time Per Orbit (min)';
            app.InitialTimePerOrbitminEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTimePerOrbitminEditField.Editable = 'off';
            app.OrbitsLeftEditFieldLabel = uilabel(app.UIFigure);
            app.OrbitsLeftEditFieldLabel.Text = 'Orbits Left';
            app.OrbitsLeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrbitsLeftEditField.Editable = 'off';
            app.LifetimedaysEditFieldLabel = uilabel(app.UIFigure);
            app.LifetimedaysEditFieldLabel.Text = 'Lifetime (days)';
            app.LifetimedaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.LifetimedaysEditField.Editable = 'off';
            app.ReentryDateEditFieldLabel = uilabel(app.UIFigure);
            app.ReentryDateEditFieldLabel.Text = 'Reentry Date';
            app.ReentryDateEditField = uieditfield(app.UIFigure, 'text');
            app.ReentryDateEditField.Editable = 'off';
            app.OrbitsLeftEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.OrbitsLeftEditField_2.Editable = 'off';
            app.LifetimedaysEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LifetimedaysEditField_2.Editable = 'off';
            app.ReentryDateEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ReentryDateEditField_2.Editable = 'off';
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.BestEstimateLabel = uilabel(app.UIFigure);
            app.BestEstimateLabel.Text = 'Best Estimate';
            app.StandardLabel = uilabel(app.UIFigure);
            app.StandardLabel.Text = '(1976 Standard)';
            app.JacchiaRobertsLabel = uilabel(app.UIFigure);
            app.JacchiaRobertsLabel.Text = '(Jacchia-Roberts)';
            app.AtmosphericModelLabel = uilabel(app.UIFigure);
            app.AtmosphericModelLabel.Text = 'Atmospheric Model';
            app.OrbitsLeftEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.OrbitsLeftEditField_3.Editable = 'off';
            app.LifetimedaysEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LifetimedaysEditField_3.Editable = 'off';
            app.ReentryDateEditField_3 = uieditfield(app.UIFigure, 'text');
            app.ReentryDateEditField_3.Editable = 'off';
            app.MaximumLabel_2 = uilabel(app.UIFigure);
            app.MaximumLabel_2.Text = 'Maximum';
            app.DTM2012Label_2 = uilabel(app.UIFigure);
            app.DTM2012Label_2.Text = '(DTM 2012)';
            app.StartingAltitudekmEditFieldLabel = uilabel(app.UIFigure);
            app.StartingAltitudekmEditFieldLabel.Text = 'Starting Altitude (km)';
            app.StartingAltitudekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingAltitudekmEditField.Limits = [0 Inf];
            app.StartingAltitudekmEditField.Value = 400;
            app.AdjustingthealtitudeherewillonlyaffectlifetimeanalysisLabel = uilabel(app.UIFigure);
            app.AdjustingthealtitudeherewillonlyaffectlifetimeanalysisLabel.Text = 'Adjusting the altitude here will only affect lifetime analysis.';
            app.AllotheranalysistoolswilluseinitiallyenteredaltitudeLabel = uilabel(app.UIFigure);
            app.AllotheranalysistoolswilluseinitiallyenteredaltitudeLabel.Text = 'All other analysis tools will use initially entered altitude.';
            app.MincsvfileButton = uibutton(app.UIFigure, 'push');
            app.MincsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @MincsvfileButtonPushed, true);
            app.MincsvfileButton.Enable = 'off';
            app.MincsvfileButton.Text = 'Min .csv file';
            app.MidcsvfileButton = uibutton(app.UIFigure, 'push');
            app.MidcsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @MidcsvfileButtonPushed, true);
            app.MidcsvfileButton.Enable = 'off';
            app.MidcsvfileButton.Text = 'Mid .csv file';
            app.MaxcsvfileButton = uibutton(app.UIFigure, 'push');
            app.MaxcsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @MaxcsvfileButtonPushed, true);
            app.MaxcsvfileButton.Enable = 'off';
            app.MaxcsvfileButton.Text = 'Max .csv file';
            app.OrbitsByTimeStepcsvButton = uibutton(app.UIFigure, 'push');
            app.OrbitsByTimeStepcsvButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitsByTimeStepcsvButtonPushed, true);
            app.OrbitsByTimeStepcsvButton.Enable = 'off';
            app.OrbitsByTimeStepcsvButton.Text = 'Orbits By TimeStep .csv';
            app.LifetimeAnalysisLabel = uilabel(app.UIFigure);
            app.LifetimeAnalysisLabel.Text = 'Lifetime Analysis';
            app.DecayAltitudekmEditFieldLabel = uilabel(app.UIFigure);
            app.DecayAltitudekmEditFieldLabel.Text = 'Decay Altitude (km)';
            app.DecayAltitudekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayAltitudekmEditField.Limits = [0 1000];
            app.DecayAltitudekmEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = LifetimeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end