classdef OrbitInitializationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        YearEditFieldLabel         matlab.ui.control.Label
        YearEpochEditField         matlab.ui.control.NumericEditField
        DayEditFieldLabel          matlab.ui.control.Label
        DayEpochEditField          matlab.ui.control.NumericEditField
        MonthDropDownLabel         matlab.ui.control.Label
        MonthEpochDropDown         matlab.ui.control.DropDown
        HourDropDownLabel          matlab.ui.control.Label
        HourEpochDropDown          matlab.ui.control.DropDown
        MinutesDropDownLabel       matlab.ui.control.Label
        MinutesEpochDropDown       matlab.ui.control.DropDown
        SecondsLabel               matlab.ui.control.Label
        SecondsEpochEditField      matlab.ui.control.NumericEditField
        OrbitInitializationLabel   matlab.ui.control.Label
        OrbitEpochLabel            matlab.ui.control.Label
        InitialOrbitStateLabel     matlab.ui.control.Label
        XkmEditFieldLabel          matlab.ui.control.Label
        XkmEditField               matlab.ui.control.NumericEditField
        YkmEditFieldLabel          matlab.ui.control.Label
        YkmEditField               matlab.ui.control.NumericEditField
        ZkmEditFieldLabel          matlab.ui.control.Label
        ZkmEditField               matlab.ui.control.NumericEditField
        VelXkmsEditFieldLabel      matlab.ui.control.Label
        VelXkmsEditField           matlab.ui.control.NumericEditField
        VelYkmsEditFieldLabel      matlab.ui.control.Label
        VelYkmsEditField           matlab.ui.control.NumericEditField
        VelZkmsEditFieldLabel      matlab.ui.control.Label
        VelZkmsEditField           matlab.ui.control.NumericEditField
        EnterButton                matlab.ui.control.Button
        CartesianorKeplerian       matlab.ui.control.RockerSwitch
        akmEditFieldLabel          matlab.ui.control.Label
        akmEditField               matlab.ui.control.NumericEditField
        eEditFieldLabel            matlab.ui.control.Label
        eEditField                 matlab.ui.control.NumericEditField
        idegEditFieldLabel         matlab.ui.control.Label
        idegEditField              matlab.ui.control.NumericEditField
        OmegadegEditFieldLabel     matlab.ui.control.Label
        OmegadegEditField          matlab.ui.control.NumericEditField
        wdegEditFieldLabel         matlab.ui.control.Label
        wdegEditField              matlab.ui.control.NumericEditField
        vdegEditFieldLabel         matlab.ui.control.Label
        vdegEditField              matlab.ui.control.NumericEditField
        TLEFileNameEditFieldLabel  matlab.ui.control.Label
        TLEFileNameEditField       matlab.ui.control.EditField
        LoadTLEFileButton          matlab.ui.control.Button
        TLEtxtfilemustbeincurrentdirectoryandmustbeLabel  matlab.ui.control.Label
        MessageLabel               matlab.ui.control.Label
        httpswwwcelestrakcomNORADdocumentationtlefmtphpLabel  matlab.ui.control.Label
        Thetxtfilemustincludea24characternamelineasshownLabel  matlab.ui.control.Label
        Thetxtfilemustincludea24characternamelineasshownLabel_2  matlab.ui.control.Label
        ManuallyenterelementsaboveorloadTLEbelowLabel  matlab.ui.control.Label
        Thetxtfilemustincludea24characternamelineasshownLabel_3  matlab.ui.control.Label
        NotesLabel                 matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'TLElines', 0)
        end

        % Value changed function: CartesianorKeplerian
        function CartesianorKeplerianValueChanged(app, event)
            value = app.CartesianorKeplerian.Value;
            if strcmp(value, 'Cartesian ECIF')
                app.akmEditField.Enable = 'off';
                app.eEditField.Enable = 'off';
                app.idegEditField.Enable = 'off';
                app.OmegadegEditField.Enable = 'off';
                app.wdegEditField.Enable = 'off';
                app.vdegEditField.Enable = 'off';
                app.XkmEditField.Enable = 'on';
                app.YkmEditField.Enable = 'on';
                app.ZkmEditField.Enable = 'on';
                app.VelXkmsEditField.Enable = 'on';
                app.VelYkmsEditField.Enable = 'on';
                app.VelZkmsEditField.Enable = 'on';
            end
            if strcmp(value, 'Keplerian COEs')
                app.akmEditField.Enable = 'on';
                app.eEditField.Enable = 'on';
                app.idegEditField.Enable = 'on';
                app.OmegadegEditField.Enable = 'on';
                app.wdegEditField.Enable = 'on';
                app.vdegEditField.Enable = 'on';
                app.XkmEditField.Enable = 'off';
                app.YkmEditField.Enable = 'off';
                app.ZkmEditField.Enable = 'off';
                app.VelXkmsEditField.Enable = 'off';
                app.VelYkmsEditField.Enable = 'off';
                app.VelZkmsEditField.Enable = 'off';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)

            YS = string(app.YearEpochEditField.Value);
            MoS = string(app.MonthEpochDropDown.Value);
            DS = string(app.DayEpochEditField.Value);
            HS = string(app.HourEpochDropDown.Value);
            MiS = string(app.MinutesEpochDropDown.Value);
            SS = string(app.SecondsEpochEditField.Value);
            sp = " ";
            co = ":";
            assignin('base', 'EpochDate', char(strcat(DS,sp,MoS,sp,YS,sp,HS,co,MiS,co,SS)))
            
            if strcmp(app.CartesianorKeplerian.Value, 'Cartesian ECIF')
                InitialX = app.XkmEditField.Value;
                InitialY = app.YkmEditField.Value;
                InitialZ = app.ZkmEditField.Value;
                InitialXvel = app.VelXkmsEditField.Value;
                InitialYvel = app.VelYkmsEditField.Value;
                InitialZvel = app.VelZkmsEditField.Value;
                InitialStateVector = [InitialX, InitialY, InitialZ, InitialXvel, InitialYvel, InitialZvel];
                assignin('base', 'InitialStateVector', [InitialX, InitialY, InitialZ, InitialXvel, InitialYvel, InitialZvel]);
                [~, ~, ~, ~, ~, muEarth, ~] = LoadingConstants();
                [COEs] = Cartesian2Keplerian(InitialStateVector(1:3), InitialStateVector(4:6), muEarth);
                assignin('base', 'COEs', COEs)
            end
            if strcmp(app.CartesianorKeplerian.Value, 'Keplerian COEs')
                SemiMajorAxis = app.akmEditField.Value;
                Eccentricity = app.eEditField.Value;
                Inclination = app.idegEditField.Value;
                RAAN = app.OmegadegEditField.Value;
                ArgPeriapsis = app.wdegEditField.Value;
                TrueAnomaly = app.vdegEditField.Value;
                assignin('base', 'COEs', [SemiMajorAxis, Eccentricity, Inclination, RAAN, ArgPeriapsis, TrueAnomaly]);
            end
            
            
        end

        % Button pushed function: LoadTLEFileButton
        function LoadTLEFileButtonPushed(app, event)
            %app.CartesianorKeplerian.Value = 'Keplerian COEs';
            %value = app.CartesianorKeplerian.Value;
            %if strcmp(value, 'Cartesian ECIF')
                app.akmEditField.Enable = 'off';
                app.eEditField.Enable = 'off';
                app.idegEditField.Enable = 'off';
                app.OmegadegEditField.Enable = 'off';
                app.wdegEditField.Enable = 'off';
                app.vdegEditField.Enable = 'off';
                app.XkmEditField.Enable = 'off';
                app.YkmEditField.Enable = 'off';
                app.ZkmEditField.Enable = 'off';
                app.VelXkmsEditField.Enable = 'off';
                app.VelYkmsEditField.Enable = 'off';
                app.VelZkmsEditField.Enable = 'off';
            %end
            %if strcmp(value, 'Keplerian COEs')
                %app.akmEditField.Enable = 'off';
                %app.eEditField.Enable = 'off';
                %app.idegEditField.Enable = 'off';
                %app.OmegadegEditField.Enable = 'off';
                %app.wdegEditField.Enable = 'off';
                %app.vdegEditField.Enable = 'off';
                %app.XkmEditField.Enable = 'off';
                %app.YkmEditField.Enable = 'off';
                %app.ZkmEditField.Enable = 'off';
                %app.VelXkmsEditField.Enable = 'off';
                %app.VelYkmsEditField.Enable = 'off';
                %app.VelZkmsEditField.Enable = 'off';
            %end
                app.YearEpochEditField.Enable = 'off';
                app.DayEpochEditField.Enable = 'off';
                app.MonthEpochDropDown.Enable = 'off';
                app.HourEpochDropDown.Enable = 'off';
                app.MinutesEpochDropDown.Enable = 'off';
                app.SecondsEpochEditField.Enable = 'off';
                
                app.CartesianorKeplerian.Enable = 'off';

            TLEfile = num2str(app.TLEFileNameEditField.Value);
            try
                [OE, GregorianDateElements, L2c, L3c] = Read_TLE(TLEfile);
                app.akmEditField.Value = OE(1);
                app.eEditField.Value = OE(2);
                app.idegEditField.Value = OE(3);
                app.OmegadegEditField.Value = OE(4);
                app.wdegEditField.Value = OE(5);
                app.vdegEditField.Value = OE(6);
                app.YearEpochEditField.Value = GregorianDateElements(1);
                switch GregorianDateElements(2)
                    case 1
                        app.MonthEpochDropDown.Value = 'January';
                    case 2
                        app.MonthEpochDropDown.Value = 'February';
                    case 3
                        app.MonthEpochDropDown.Value = 'March';
                    case 4
                        app.MonthEpochDropDown.Value = 'April';
                    case 5
                        app.MonthEpochDropDown.Value = 'May';
                    case 6
                        app.MonthEpochDropDown.Value = 'June';
                    case 7
                        app.MonthEpochDropDown.Value = 'July';
                    case 8
                        app.MonthEpochDropDown.Value = 'August';
                    case 9
                        app.MonthEpochDropDown.Value = 'September';
                    case 10
                        app.MonthEpochDropDown.Value = 'October';
                    case 11
                        app.MonthEpochDropDown.Value = 'November';
                    case 12
                        app.MonthEpochDropDown.Value = 'December';
                end
                app.DayEpochEditField.Value = GregorianDateElements(3);
                app.HourEpochDropDown.Value = num2str(GregorianDateElements(4));
                app.MinutesEpochDropDown.Value = num2str(GregorianDateElements(5));
                app.SecondsEpochEditField.Value = GregorianDateElements(6);
                app.MessageLabel.Text = 'Message: The TLE file loaded successfully. Must still click Enter';
                assignin('base', 'TLElines', [L2c; L3c])
            catch
                disp('The TLE file name is not valid. Be sure to include the .txt extension')
                app.MessageLabel.Text = 'Message: The TLE file name is not valid. Be sure to include the .txt extension';
                app.akmEditField.Value = 0;
                app.eEditField.Value = 0;
                app.idegEditField.Value = 0;
                app.OmegadegEditField.Value = 0;
                app.wdegEditField.Value = 0;
                app.vdegEditField.Value = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.YearEditFieldLabel = uilabel(app.UIFigure);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearEpochEditField.Value = 2018;
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEpochEditField.Value = 12;
            app.MonthDropDownLabel = uilabel(app.UIFigure);
            app.MonthDropDownLabel.Text = 'Month ';
            app.MonthEpochDropDown = uidropdown(app.UIFigure);
            app.MonthEpochDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthEpochDropDown.Value = 'December';
            app.HourDropDownLabel = uilabel(app.UIFigure);
            app.HourDropDownLabel.Text = 'Hour';
            app.HourEpochDropDown = uidropdown(app.UIFigure);
            app.HourEpochDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.HourEpochDropDown.Value = '0';
            app.MinutesDropDownLabel = uilabel(app.UIFigure);
            app.MinutesDropDownLabel.Text = 'Minutes';
            app.MinutesEpochDropDown = uidropdown(app.UIFigure);
            app.MinutesEpochDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59'};
            app.MinutesEpochDropDown.Value = '0';
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.SecondsEpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrbitInitializationLabel = uilabel(app.UIFigure);
            app.OrbitInitializationLabel.Text = 'Orbit Initialization';
            app.OrbitEpochLabel = uilabel(app.UIFigure);
            app.OrbitEpochLabel.Text = 'Orbit Epoch';
            app.InitialOrbitStateLabel = uilabel(app.UIFigure);
            app.InitialOrbitStateLabel.Text = 'Initial Orbit State';
            app.XkmEditFieldLabel = uilabel(app.UIFigure);
            app.XkmEditFieldLabel.Text = 'X (km)';
            app.XkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XkmEditField.Enable = 'off';
            app.XkmEditField.Value = 7000;
            app.YkmEditFieldLabel = uilabel(app.UIFigure);
            app.YkmEditFieldLabel.Text = 'Y (km)';
            app.YkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YkmEditField.Enable = 'off';
            app.ZkmEditFieldLabel = uilabel(app.UIFigure);
            app.ZkmEditFieldLabel.Text = 'Z (km)';
            app.ZkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZkmEditField.Enable = 'off';
            app.VelXkmsEditFieldLabel = uilabel(app.UIFigure);
            app.VelXkmsEditFieldLabel.Text = 'Vel X (km/s)';
            app.VelXkmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelXkmsEditField.Enable = 'off';
            app.VelYkmsEditFieldLabel = uilabel(app.UIFigure);
            app.VelYkmsEditFieldLabel.Text = 'Vel Y (km/s)';
            app.VelYkmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelYkmsEditField.Enable = 'off';
            app.VelYkmsEditField.Value = 4.6857;
            app.VelZkmsEditFieldLabel = uilabel(app.UIFigure);
            app.VelZkmsEditFieldLabel.Text = 'Vel Z (km/s)';
            app.VelZkmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelZkmsEditField.Enable = 'off';
            app.VelZkmsEditField.Value = 5.91196;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.CartesianorKeplerian = uiswitch(app.UIFigure, 'rocker');
            app.CartesianorKeplerian.Items = {'Cartesian ECIF', 'Keplerian COEs'};
            app.CartesianorKeplerian.ValueChangedFcn = createCallbackFcn(app, @CartesianorKeplerianValueChanged, true);
            app.CartesianorKeplerian.Value = 'Keplerian COEs';
            app.akmEditFieldLabel = uilabel(app.UIFigure);
            app.akmEditFieldLabel.Text = 'a (km)';
            app.akmEditField = uieditfield(app.UIFigure, 'numeric');
            app.akmEditField.Value = 6793.14;
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.idegEditFieldLabel = uilabel(app.UIFigure);
            app.idegEditFieldLabel.Text = 'i (deg)';
            app.idegEditField = uieditfield(app.UIFigure, 'numeric');
            app.idegEditField.Value = 51.6;
            app.OmegadegEditFieldLabel = uilabel(app.UIFigure);
            app.OmegadegEditFieldLabel.Text = 'Omega (deg)';
            app.OmegadegEditField = uieditfield(app.UIFigure, 'numeric');
            app.wdegEditFieldLabel = uilabel(app.UIFigure);
            app.wdegEditFieldLabel.Text = 'w (deg)';
            app.wdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.vdegEditFieldLabel = uilabel(app.UIFigure);
            app.vdegEditFieldLabel.Text = 'v (deg)';
            app.vdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.TLEFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.TLEFileNameEditFieldLabel.Text = 'TLE File Name';
            app.TLEFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadTLEFileButton = uibutton(app.UIFigure, 'push');
            app.LoadTLEFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTLEFileButtonPushed, true);
            app.LoadTLEFileButton.Text = 'Load TLE File';
            app.TLEtxtfilemustbeincurrentdirectoryandmustbeLabel = uilabel(app.UIFigure);
            app.TLEtxtfilemustbeincurrentdirectoryandmustbeLabel.Text = 'TLE .txt file must be in current directory and must be formatted correctly as specified at the top of the page here:';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message: ';
            app.httpswwwcelestrakcomNORADdocumentationtlefmtphpLabel = uilabel(app.UIFigure);
            app.httpswwwcelestrakcomNORADdocumentationtlefmtphpLabel.Text = 'https://www.celestrak.com/NORAD/documentation/tle-fmt.php';
            app.Thetxtfilemustincludea24characternamelineasshownLabel = uilabel(app.UIFigure);
            app.Thetxtfilemustincludea24characternamelineasshownLabel.Text = 'The .txt file must include a 24 character name line as shown. If a TLE is loaded, the satellite will be propagated';
            app.Thetxtfilemustincludea24characternamelineasshownLabel_2 = uilabel(app.UIFigure);
            app.Thetxtfilemustincludea24characternamelineasshownLabel_2.Text = 'via SGP4 in STK. To go back to using manually entered orbit elements and HPOP in STK, close and reopen';
            app.ManuallyenterelementsaboveorloadTLEbelowLabel = uilabel(app.UIFigure);
            app.ManuallyenterelementsaboveorloadTLEbelowLabel.Text = 'Manually enter elements above or load TLE below';
            app.Thetxtfilemustincludea24characternamelineasshownLabel_3 = uilabel(app.UIFigure);
            app.Thetxtfilemustincludea24characternamelineasshownLabel_3.Text = 'this GUI. An example of a .txt TLE file is provided and named ''TLEexample.txt'' ';
            app.NotesLabel = uilabel(app.UIFigure);
            app.NotesLabel.Text = 'Notes:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OrbitInitializationGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end