classdef PlotsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TemperaturePlotButton          matlab.ui.control.Button
        BatteryStatePlotButton         matlab.ui.control.Button
        PowerGenerationPlotButton      matlab.ui.control.Button
        SolarIrradiancePlotButton      matlab.ui.control.Button
        HeatTransferPlotButton         matlab.ui.control.Button
        AzElRangePlotsButton           matlab.ui.control.Button
        ForGroundStationPassEditFieldLabel  matlab.ui.control.Label
        ForGroundStationPassEditField  matlab.ui.control.NumericEditField
        GroundTrackButton              matlab.ui.control.Button
        MessageLabel                   matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: AzElRangePlotsButton
        function AzElRangePlotsButtonPushed(app, event)
            PassNumber = app.ForGroundStationPassEditField.Value;
            AERfromGroundStationCell = evalin('base','AERfromGroundStationCell');
            StartDate = evalin('base','StartDate');
            try
                Plot_AzElRange(AERfromGroundStationCell, PassNumber, StartDate)
            catch
                disp('There is no pass of this number')
                app.MessageLabel.Text = 'Message: There is no pass of this number';
            end
        end

        % Button pushed function: BatteryStatePlotButton
        function BatteryStatePlotButtonPushed(app, event)
            Time = evalin('base','Time');
            BatteryCharge = evalin('base','BatteryCharge');
            BatteryCapacity = evalin('base','BatteryCapacity');
            BatteryChargeLowerLimit = evalin('base','BatteryChargeLowerLimit');
            Iterations = evalin('base','Iterations');
            Plot_BatteryCharge(Time, BatteryCharge, BatteryCapacity, BatteryChargeLowerLimit, Iterations)
        end

        % Button pushed function: GroundTrackButton
        function GroundTrackButtonPushed(app, event)
            LatLongAlt = evalin('base','LatLongAlt');
            GroundStationLLA = evalin('base','GroundStationLLA');
            TargetsLLA = evalin('base','TargetsLLA');
            GroundTrackPlot(LatLongAlt, GroundStationLLA, TargetsLLA)
        end

        % Button pushed function: HeatTransferPlotButton
        function HeatTransferPlotButtonPushed(app, event)
            Time = evalin('base','Time');
            QinDirSolar = evalin('base','QinDirSolar');
            QinAlbedo = evalin('base','QinAlbedo');
            QinEarthIR = evalin('base','QinEarthIR');
            QoutSatRad = evalin('base','QoutSatRad');
            Plot_Heat(Time, QinDirSolar, QinAlbedo, QinEarthIR, QoutSatRad)
        end

        % Button pushed function: PowerGenerationPlotButton
        function PowerGenerationPlotButtonPushed(app, event)
            Time = evalin('base','Time');
            PowerDirect = evalin('base','PowerDirect');
            PowerAlbedo = evalin('base','PowerAlbedo');
            Plot_PowerGeneration(Time, PowerDirect, PowerAlbedo)
        end

        % Button pushed function: SolarIrradiancePlotButton
        function SolarIrradiancePlotButtonPushed(app, event)
            Time = evalin('base','Time');
            DirectIrrPerSide = evalin('base','DirectIrrPerSide');
            EclipseBinary = evalin('base','EclipseBinary');
            Plot_Irradiance(Time, DirectIrrPerSide, EclipseBinary)
        end

        % Button pushed function: TemperaturePlotButton
        function TemperaturePlotButtonPushed(app, event)
            Time = evalin('base','Time');
            Temp = evalin('base','Temp');
            SurvivalMinTemp = evalin('base','SurvivalMinTemp');
            SurvivalMaxTemp = evalin('base','SurvivalMaxTemp');
            Iterations = evalin('base','Iterations');
            OperationalMinTemp = evalin('base','OperationalMinTemp');
            OperationalMaxTemp = evalin('base','OperationalMaxTemp');
            ScientificMinTemp = evalin('base','ScientificMinTemp');
            ScientificMaxTemp = evalin('base','ScientificMaxTemp');
            Plot_Temperature(Time, Temp, Iterations,SurvivalMinTemp,SurvivalMaxTemp,OperationalMinTemp,OperationalMaxTemp, ScientificMinTemp, ScientificMaxTemp)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TemperaturePlotButton = uibutton(app.UIFigure, 'push');
            app.TemperaturePlotButton.ButtonPushedFcn = createCallbackFcn(app, @TemperaturePlotButtonPushed, true);
            app.TemperaturePlotButton.Text = 'Temperature Plot';
            app.BatteryStatePlotButton = uibutton(app.UIFigure, 'push');
            app.BatteryStatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @BatteryStatePlotButtonPushed, true);
            app.BatteryStatePlotButton.Text = 'Battery State Plot';
            app.PowerGenerationPlotButton = uibutton(app.UIFigure, 'push');
            app.PowerGenerationPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PowerGenerationPlotButtonPushed, true);
            app.PowerGenerationPlotButton.Text = 'Power Generation Plot';
            app.SolarIrradiancePlotButton = uibutton(app.UIFigure, 'push');
            app.SolarIrradiancePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SolarIrradiancePlotButtonPushed, true);
            app.SolarIrradiancePlotButton.Text = 'Solar Irradiance Plot';
            app.HeatTransferPlotButton = uibutton(app.UIFigure, 'push');
            app.HeatTransferPlotButton.ButtonPushedFcn = createCallbackFcn(app, @HeatTransferPlotButtonPushed, true);
            app.HeatTransferPlotButton.Text = 'Heat Transfer Plot';
            app.AzElRangePlotsButton = uibutton(app.UIFigure, 'push');
            app.AzElRangePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @AzElRangePlotsButtonPushed, true);
            app.AzElRangePlotsButton.Text = 'Az/El/Range Plots';
            app.ForGroundStationPassEditFieldLabel = uilabel(app.UIFigure);
            app.ForGroundStationPassEditFieldLabel.Text = 'For Ground Station Pass #:';
            app.ForGroundStationPassEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForGroundStationPassEditField.Limits = [1 Inf];
            app.ForGroundStationPassEditField.Value = 1;
            app.GroundTrackButton = uibutton(app.UIFigure, 'push');
            app.GroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @GroundTrackButtonPushed, true);
            app.GroundTrackButton.Text = 'Ground Track';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlotsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end