classdef SatelliteAttitudeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AttitudeProfileDropDownLabel    matlab.ui.control.Label
        AttitudeProfileDropDown         matlab.ui.control.DropDown
        ProfileDescriptionsLabel        matlab.ui.control.Label
        ProfileDescriptionsTextArea     matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ConstraintAngledegEditFieldLabel  matlab.ui.control.Label
        ConstraintAngledegEditField     matlab.ui.control.NumericEditField
        AlignmentAngledegEditFieldLabel  matlab.ui.control.Label
        AlignmentAngledegEditField      matlab.ui.control.NumericEditField
        BodyVectorXEditFieldLabel       matlab.ui.control.Label
        BodyVectorXEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        BodyVectorYEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        BodyVectorZEditField            matlab.ui.control.NumericEditField
        InertialVectorXEditFieldLabel   matlab.ui.control.Label
        InertialVectorXEditField        matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        InertialVectorYEditField        matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        InertialVectorZEditField        matlab.ui.control.NumericEditField
        SpinRaterevminEditFieldLabel    matlab.ui.control.Label
        SpinRaterevminEditField         matlab.ui.control.NumericEditField
        PrecessionRaterevminEditFieldLabel  matlab.ui.control.Label
        PrecessionRaterevminEditField   matlab.ui.control.NumericEditField
        NutationAngledegEditFieldLabel  matlab.ui.control.Label
        NutationAngledegEditField       matlab.ui.control.NumericEditField
        EnterAttitudeInformationButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: AttitudeProfileDropDown
        function AttitudeProfileDropDownValueChanged(app, event)
            AttitudeOption = app.AttitudeProfileDropDown.Value;
            switch AttitudeOption
                case 'Option 1'
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 2'
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 3'
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 4'    
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 5'    
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 6'    
                    app.ConstraintAngledegEditField.Enable = 'on';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 7'    
                    app.ConstraintAngledegEditField.Enable = 'off';
                    app.AlignmentAngledegEditField.Enable = 'on';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 8'    
                    app.ConstraintAngledegEditField.Enable = 'off';
                    app.AlignmentAngledegEditField.Enable = 'on';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 9'    
                    app.ConstraintAngledegEditField.Enable = 'off';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'on';
                    app.InertialVectorYEditField.Enable = 'on';
                    app.InertialVectorZEditField.Enable = 'on';
                    app.SpinRaterevminEditField.Enable = 'off';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 10'
                    app.ConstraintAngledegEditField.Enable = 'off';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'on';
                    app.BodyVectorYEditField.Enable = 'on';
                    app.BodyVectorZEditField.Enable = 'on';
                    app.InertialVectorXEditField.Enable = 'on';
                    app.InertialVectorYEditField.Enable = 'on';
                    app.InertialVectorZEditField.Enable = 'on';
                    app.SpinRaterevminEditField.Enable = 'on';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 11'    
                    app.ConstraintAngledegEditField.Enable = 'of';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'on';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 12'    
                    app.ConstraintAngledegEditField.Enable = 'of';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'off';
                    app.BodyVectorYEditField.Enable = 'off';
                    app.BodyVectorZEditField.Enable = 'off';
                    app.InertialVectorXEditField.Enable = 'off';
                    app.InertialVectorYEditField.Enable = 'off';
                    app.InertialVectorZEditField.Enable = 'off';
                    app.SpinRaterevminEditField.Enable = 'on';
                    app.PrecessionRaterevminEditField.Enable = 'off';
                    app.NutationAngledegEditField.Enable = 'off';
                case 'Option 13'    
                    app.ConstraintAngledegEditField.Enable = 'off';
                    app.AlignmentAngledegEditField.Enable = 'off';
                    app.BodyVectorXEditField.Enable = 'on';
                    app.BodyVectorYEditField.Enable = 'on';
                    app.BodyVectorZEditField.Enable = 'on';
                    app.InertialVectorXEditField.Enable = 'on';
                    app.InertialVectorYEditField.Enable = 'on';
                    app.InertialVectorZEditField.Enable = 'on';
                    app.SpinRaterevminEditField.Enable = 'on';
                    app.PrecessionRaterevminEditField.Enable = 'on';
                    app.NutationAngledegEditField.Enable = 'on';
            end        
                
        end

        % Button pushed function: EnterAttitudeInformationButton
        function EnterAttitudeInformationButtonPushed(app, event)
            AttitudeOption = app.AttitudeProfileDropDown.Value;
            switch AttitudeOption
                case 'Option 1'
                    AttitudeOptionNumber = 1;
                case 'Option 2'
                    AttitudeOptionNumber = 2;
                case 'Option 3'
                    AttitudeOptionNumber = 3;
                case 'Option 4'
                    AttitudeOptionNumber = 4;
                case 'Option 5'
                    AttitudeOptionNumber = 5;
                case 'Option 6'
                    AttitudeOptionNumber = 6;
                case 'Option 7'
                    AttitudeOptionNumber = 7;
                case 'Option 8'
                    AttitudeOptionNumber = 8;
                case 'Option 9'
                    AttitudeOptionNumber = 9;
                case 'Option 10'
                    AttitudeOptionNumber = 10;
                case 'Option 11'
                    AttitudeOptionNumber = 11;
                case 'Option 12'
                    AttitudeOptionNumber = 12;
                case 'Option 13'
                    AttitudeOptionNumber = 13;
            end
            ConstraintAngle = app.ConstraintAngledegEditField.Value;
            AlignmentAngle = app.AlignmentAngledegEditField.Value;
            BodyVectorX = app.BodyVectorXEditField.Value;
            BodyVectorY = app.BodyVectorYEditField.Value;
            BodyVectorZ = app.BodyVectorZEditField.Value;
            InertialVectorX = app.InertialVectorXEditField.Value;
            InertialVectorY = app.InertialVectorYEditField.Value;
            InertialVectorZ = app.InertialVectorZEditField.Value;
            SpinRate = app.SpinRaterevminEditField.Value;
            PrecessionRate = app.PrecessionRaterevminEditField.Value;
            NutationAngle = app.NutationAngledegEditField.Value;
            
            AttitudeParameters = [AttitudeOptionNumber, ConstraintAngle, AlignmentAngle, BodyVectorX, BodyVectorY, BodyVectorZ, InertialVectorX, InertialVectorY, InertialVectorZ, SpinRate, PrecessionRate, NutationAngle];
            assignin('base', 'AttitudeParameters', AttitudeParameters)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AttitudeProfileDropDownLabel = uilabel(app.UIFigure);
            app.AttitudeProfileDropDownLabel.Text = 'Attitude Profile';
            app.AttitudeProfileDropDown = uidropdown(app.UIFigure);
            app.AttitudeProfileDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9', 'Option 10', 'Option 11', 'Option 12', 'Option 13'};
            app.AttitudeProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @AttitudeProfileDropDownValueChanged, true);
            app.ProfileDescriptionsLabel = uilabel(app.UIFigure);
            app.ProfileDescriptionsLabel.Text = ' Profile Descriptions';
            app.ProfileDescriptionsTextArea = uitextarea(app.UIFigure);
            app.ProfileDescriptionsTextArea.Editable = 'off';
            app.ProfileDescriptionsTextArea.Value = {'Option 1: Nadir alignment with ECF velocity constraint: '; '               Z-face nadir, constraint angle determines velocity face/vector. At 0 deg, X-face is aligned with ECF velocity. 90 deg, Y-face. 180 deg, -X-face.'; ''; 'Option 2: Nadir alignment with ECI velocity constraint: '; '               Z-face nadir, constraint angle determines velocity face/vector. At 0 deg, X-face is aligned with ECI velocity. 90 deg, Y-face. 180 deg, -X-face.'; ''; 'Option 3: Nadir alignment with Sun constraint: '; '               Z-face nadir, constraint angle determines face/vector to be as aligned with Sun vector as possible given the nadir alignment. At 0 deg, X-face attempts to align to Sun. 90 deg, Y-face. 180 deg, -X-face.'; ''; 'Option 4: ECF velocity alignment with radial constraint: '; '               X-face ECF velocity facing, constraint angle determines radial/zenith face/vector. At 0 deg, Z-face aligns radially. 90 deg, Y-face. 180 deg, -Z-face.'; ''; 'Option 5: ECF velocity alignment with nadir constraint: '; '               X-face ECF velocity facing, constraint angle determines nadir face/vector. At 0 deg, Z-face is nadir. 90 deg, -Y-face. 180 deg, -Z-face.'; ''; 'Option 6: ECI velocity alignment with nadir constraint:'; '               X-face ECI velocity facing, constraint angle determines nadir face/vector. At 0 deg, Z-face is nadir. 90 deg, -Y-face. 180 deg, -Z-face.'; ''; 'Option 7: ECI velocity alignment with Sun constraint'; '               Z-face attempts to face the sun. Alignment angle determines which face/vector is ECI velocity facing. At 0 deg, X-face is aligned with ECI velocity. 90 deg, Y-face. 180 deg, -X-face.'; ''; 'Option 8: Sun alignment with nadir constraint'; '               Z-face attempts to face nadir. Alignment angle determines which face/vector is Sun facing. At 0 deg, X-face is aligned with Sun. 90 deg, Y-face. 180 deg, -X-face.'; ''; 'Option 9: Yaw to nadir:'; '               Z-face points towards inertial vector determined by cartesian x,y,z input. X-face attempts to face nadir.'; ''; 'Option 10: Spinning:'; '                Body face/vector defined will be aligned with inertial face/vector defined, both by cartesian x,y,z input. The satellite will spin about these vectors, at rate defined by spin rate. '; ''; 'Option 11 : Spin about nadir:'; '                Z-face points nadir. Satellite will spin about this vector, at rate defined by spin rate.'; ''; 'Option 12: Spin about Sun:'; '                Z-face points towards Sun. Satellite will spin about this vector, at rate defined by spin rate.'; ''; 'Option 13: Precessing Spin:'; '                Satellite will spin about body vector defined by cartesian x,y,z input, at rate defined by spin rate. This body spin vector will precess about an inertial vector defined by cartesian x,y,z input, at a'; '                rate defined by precession rate. The nutation angle input defines the angle between the two vectors.'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'More information on STKs attitude profiles found at http://help.agi.com/stk/11.4.0/index.htm#stk/referenceframesvehicle.htm';
            app.ConstraintAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintAngledegEditFieldLabel.Text = 'Constraint Angle (deg)';
            app.ConstraintAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlignmentAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AlignmentAngledegEditFieldLabel.Text = 'Alignment Angle (deg)';
            app.AlignmentAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlignmentAngledegEditField.Enable = 'off';
            app.BodyVectorXEditFieldLabel = uilabel(app.UIFigure);
            app.BodyVectorXEditFieldLabel.Text = 'Body Vector, X:';
            app.BodyVectorXEditField = uieditfield(app.UIFigure, 'numeric');
            app.BodyVectorXEditField.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.BodyVectorYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BodyVectorYEditField.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z:';
            app.BodyVectorZEditField = uieditfield(app.UIFigure, 'numeric');
            app.BodyVectorZEditField.Enable = 'off';
            app.InertialVectorXEditFieldLabel = uilabel(app.UIFigure);
            app.InertialVectorXEditFieldLabel.Text = 'Inertial Vector, X:';
            app.InertialVectorXEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertialVectorXEditField.Enable = 'off';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y:';
            app.InertialVectorYEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertialVectorYEditField.Enable = 'off';
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z:';
            app.InertialVectorZEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertialVectorZEditField.Enable = 'off';
            app.SpinRaterevminEditFieldLabel = uilabel(app.UIFigure);
            app.SpinRaterevminEditFieldLabel.Text = 'Spin Rate (rev/min)';
            app.SpinRaterevminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpinRaterevminEditField.Enable = 'off';
            app.PrecessionRaterevminEditFieldLabel = uilabel(app.UIFigure);
            app.PrecessionRaterevminEditFieldLabel.Text = 'Precession Rate (rev/min)';
            app.PrecessionRaterevminEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecessionRaterevminEditField.Enable = 'off';
            app.NutationAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.NutationAngledegEditFieldLabel.Text = 'Nutation Angle (deg)';
            app.NutationAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.NutationAngledegEditField.Enable = 'off';
            app.EnterAttitudeInformationButton = uibutton(app.UIFigure, 'push');
            app.EnterAttitudeInformationButton.ButtonPushedFcn = createCallbackFcn(app, @EnterAttitudeInformationButtonPushed, true);
            app.EnterAttitudeInformationButton.Text = 'Enter Attitude Information';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SatelliteAttitudeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end