classdef SizeSolarPanelsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CubeSatSizeandSolarPanelsLabel  matlab.ui.control.Label
        SolarPanelTotalEfficiencyEditFieldLabel  matlab.ui.control.Label
        SolarPanelTotalEfficiencyEditField  matlab.ui.control.NumericEditField
        SolarPanelAreaPerSidecm2Label   matlab.ui.control.Label
        EnterOption1Button              matlab.ui.control.Button
        X1Label                         matlab.ui.control.Label
        SPA1EditField                   matlab.ui.control.NumericEditField
        Y2Label                         matlab.ui.control.Label
        SPA2EditField                   matlab.ui.control.NumericEditField
        Z3Label                         matlab.ui.control.Label
        SPA3EditField                   matlab.ui.control.NumericEditField
        X4Label                         matlab.ui.control.Label
        SPA4EditField                   matlab.ui.control.NumericEditField
        Y5Label                         matlab.ui.control.Label
        SPA5EditField                   matlab.ui.control.NumericEditField
        Z6Label                         matlab.ui.control.Label
        SPA6EditField                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Option1IfyoursatelliteisoneofthestandardcubesatLabel  matlab.ui.control.Label
        sizesinthemenubelowthenusethissideoftheGUILabel  matlab.ui.control.Label
        STKwillnothavetoberunagaintodeterminepowergenerationLabel  matlab.ui.control.Label
        Option2IfyoursatelliteisnotastandardsizeandorhasLabel  matlab.ui.control.Label
        suchaswithadartconfigurationyouwillneedtouseaCadmodelandLabel  matlab.ui.control.Label
        STKwillberunagainEitherselectoneofthemodelsbeloworputyourLabel  matlab.ui.control.Label
        owndaeCADfileinthefolderwiththeothersLabel  matlab.ui.control.Label
        InformationoncreatingyourownmodelwithdefinedsolarpanelLabel  matlab.ui.control.Label
        layerscanbefoundinthePDFentitledLabel  matlab.ui.control.Label
        SizeofEachSidecubesatcubesLabel  matlab.ui.control.Label
        XDropDownLabel                  matlab.ui.control.Label
        XDropDown                       matlab.ui.control.DropDown
        YDropDownLabel                  matlab.ui.control.Label
        YDropDown                       matlab.ui.control.DropDown
        ZDropDownLabel                  matlab.ui.control.Label
        ZDropDown                       matlab.ui.control.DropDown
        Switch                          matlab.ui.control.RockerSwitch
        CubeSatSizeDropDownLabel        matlab.ui.control.Label
        CubeSatSizeDropDown             matlab.ui.control.DropDown
        EnterOption2AndRunSTKButton     matlab.ui.control.Button
        CustomModeldaeFileNameEditFieldLabel  matlab.ui.control.Label
        CustomModeldaeFileNameEditField  matlab.ui.control.EditField
        Thepremademodelshave____cm2ofsolarpanelsLabel  matlab.ui.control.Label
        per1x1cubefacewhichisfairlystandardLabel  matlab.ui.control.Label
        SolarPanelTotalEfficiencyEditField_2Label  matlab.ui.control.Label
        SolarPanelTotalEfficiencyEditField_2  matlab.ui.control.NumericEditField
        IncludethedaefileextensionLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.RockerSwitch
        NumberofSolarPanelGroupsinModelEditFieldLabel  matlab.ui.control.Label
        NumberofSolarPanelGroupsinModelEditField  matlab.ui.control.NumericEditField
        EditField_1                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        SolarPanelGroupNamesLabel       matlab.ui.control.Label
        EditField_7                     matlab.ui.control.EditField
        EditField_8                     matlab.ui.control.EditField
        CADBlenderColladaSTK_v05bpdfLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CubeSatSizeDropDown.Enable = 'off';
            app.SolarPanelTotalEfficiencyEditField_2.Enable = 'off';
            app.CustomModeldaeFileNameEditField.Enable = 'off';
            app.Switch_2.Enable = 'off';
            app.EnterOption2AndRunSTKButton.Enable = 'off';
            app.NumberofSolarPanelGroupsinModelEditField.Enable = 'off';
            app.EditField_1.Enable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_4.Enable = 'off';
            app.EditField_5.Enable = 'off';
            app.EditField_6.Enable = 'off';
            app.EditField_7.Enable = 'off';
            app.EditField_8.Enable = 'off';
        end

        % Button pushed function: EnterOption1Button
        function EnterOption1ButtonPushed(app, event)

            AreasSelection = [convertCharsToStrings(app.XDropDown.Value), convertCharsToStrings(app.YDropDown.Value), convertCharsToStrings(app.ZDropDown.Value)];
            assignin('base', 'AreasSelection', AreasSelection)
            for i = 1:3
                switch AreasSelection(i)
                    case "1x1"
                        AreaSat(i) = .01;
                    case "1x2"
                        AreaSat(i) = .02;
                    case "1x3"
                        AreaSat(i) = .03;
                    case "2x3"
                        AreaSat(i) = .06;
                    case "1x6"
                        AreaSat(i) = .06;
                end
            end
            AreaSat = [AreaSat,AreaSat];
            
            assignin('base', 'AreaSat', AreaSat)
            SPA1 = app.SPA1EditField.Value/10000; % Solar panel area entered in cm^2, but here we convert to m^2
            SPA2 = app.SPA2EditField.Value/10000;
            SPA3 = app.SPA3EditField.Value/10000;
            SPA4 = app.SPA4EditField.Value/10000;
            SPA5 = app.SPA5EditField.Value/10000;
            SPA6 = app.SPA6EditField.Value/10000;
            assignin('base', 'AreaSolarCells', [SPA1, SPA2, SPA3, SPA4, SPA5, SPA6])
            assignin('base', 'SolEffcy', app.SolarPanelTotalEfficiencyEditField.Value)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'Option 1')
                app.SPA1EditField.Enable = 'on';
                app.SPA2EditField.Enable = 'on';
                app.SPA3EditField.Enable = 'on';
                app.SPA4EditField.Enable = 'on';
                app.SPA5EditField.Enable = 'on';
                app.SPA6EditField.Enable = 'on';
                app.XDropDown.Enable = 'on';
                app.YDropDown.Enable = 'on';
                app.ZDropDown.Enable = 'on';
                app.SolarPanelTotalEfficiencyEditField.Enable = 'on';
                app.EnterOption1Button.Enable = 'on';
                
                app.CubeSatSizeDropDown.Enable = 'off';
                app.SolarPanelTotalEfficiencyEditField_2.Enable = 'off';
                app.CustomModeldaeFileNameEditField.Enable = 'off';
                app.Switch_2.Enable = 'off';
                app.EnterOption2AndRunSTKButton.Enable = 'off';
                app.NumberofSolarPanelGroupsinModelEditField.Enable = 'off';
                app.EditField_1.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Enable = 'off';
                app.EditField_5.Enable = 'off';
                app.EditField_6.Enable = 'off';
                app.EditField_7.Enable = 'off';
                app.EditField_8.Enable = 'off';
            else
                app.SPA1EditField.Enable = 'off';
                app.SPA2EditField.Enable = 'off';
                app.SPA3EditField.Enable = 'off';
                app.SPA4EditField.Enable = 'off';
                app.SPA5EditField.Enable = 'off';
                app.SPA6EditField.Enable = 'off';
                app.XDropDown.Enable = 'off';
                app.YDropDown.Enable = 'off';
                app.ZDropDown.Enable = 'off';
                app.SolarPanelTotalEfficiencyEditField.Enable = 'off';
                app.EnterOption1Button.Enable = 'off';
                
                app.Switch_2.Enable = 'on';
                app.EnterOption2AndRunSTKButton.Enable = 'on';
                if strcmp(app.Switch_2.Value, 'Premade Model')
                    app.CubeSatSizeDropDown.Enable = 'on';
                    app.SolarPanelTotalEfficiencyEditField_2.Enable = 'on';
                    app.CustomModeldaeFileNameEditField.Enable = 'off';
                    app.NumberofSolarPanelGroupsinModelEditField.Enable = 'off';
                    app.EditField_1.Enable = 'off';
                    app.EditField_2.Enable = 'off';
                    app.EditField_3.Enable = 'off';
                    app.EditField_4.Enable = 'off';
                    app.EditField_5.Enable = 'off';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                else
                    app.CubeSatSizeDropDown.Enable = 'off';
                    app.SolarPanelTotalEfficiencyEditField_2.Enable = 'off';
                    app.CustomModeldaeFileNameEditField.Enable = 'on';
                    app.NumberofSolarPanelGroupsinModelEditField.Enable = 'on';
                    app.NumberofSolarPanelGroupsinModelEditField.Value;
                    switch app.NumberofSolarPanelGroupsinModelEditField.Value
                        case 1
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'off';
                            app.EditField_3.Enable = 'off';
                            app.EditField_4.Enable = 'off';
                            app.EditField_5.Enable = 'off';
                            app.EditField_6.Enable = 'off';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 2
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'off';
                            app.EditField_4.Enable = 'off';
                            app.EditField_5.Enable = 'off';
                            app.EditField_6.Enable = 'off';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 3
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'off';
                            app.EditField_5.Enable = 'off';
                            app.EditField_6.Enable = 'off';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 4
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'on';
                            app.EditField_5.Enable = 'off';
                            app.EditField_6.Enable = 'off';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 5
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'on';
                            app.EditField_5.Enable = 'on';
                            app.EditField_6.Enable = 'off';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 6
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'on';
                            app.EditField_5.Enable = 'on';
                            app.EditField_6.Enable = 'on';
                            app.EditField_7.Enable = 'off';
                            app.EditField_8.Enable = 'off';
                        case 7
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'on';
                            app.EditField_5.Enable = 'on';
                            app.EditField_6.Enable = 'on';
                            app.EditField_7.Enable = 'on';
                            app.EditField_8.Enable = 'off';
                        case 8
                            app.EditField_1.Enable = 'on';
                            app.EditField_2.Enable = 'on';
                            app.EditField_3.Enable = 'on';
                            app.EditField_4.Enable = 'on';
                            app.EditField_5.Enable = 'on';
                            app.EditField_6.Enable = 'on';
                            app.EditField_7.Enable = 'on';
                            app.EditField_8.Enable = 'on';
                    end
                end
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value, 'Premade Model')
                app.CubeSatSizeDropDown.Enable = 'on';
                app.SolarPanelTotalEfficiencyEditField_2.Enable = 'on';
                app.CustomModeldaeFileNameEditField.Enable = 'off';
                app.NumberofSolarPanelGroupsinModelEditField.Enable = 'off';
                app.EditField_1.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Enable = 'off';
                app.EditField_5.Enable = 'off';
                app.EditField_6.Enable = 'off';
                app.EditField_7.Enable = 'off';
                app.EditField_8.Enable = 'off';
            else
                app.CubeSatSizeDropDown.Enable = 'off';
                app.SolarPanelTotalEfficiencyEditField_2.Enable = 'off';
                app.CustomModeldaeFileNameEditField.Enable = 'on';
                app.NumberofSolarPanelGroupsinModelEditField.Enable = 'on';
                switch app.NumberofSolarPanelGroupsinModelEditField.Value
                    case 1
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'off';
                        app.EditField_3.Enable = 'off';
                        app.EditField_4.Enable = 'off';
                        app.EditField_5.Enable = 'off';
                        app.EditField_6.Enable = 'off';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 2
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'off';
                        app.EditField_4.Enable = 'off';
                        app.EditField_5.Enable = 'off';
                        app.EditField_6.Enable = 'off';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 3
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'off';
                        app.EditField_5.Enable = 'off';
                        app.EditField_6.Enable = 'off';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 4
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'on';
                        app.EditField_5.Enable = 'off';
                        app.EditField_6.Enable = 'off';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 5
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'on';
                        app.EditField_5.Enable = 'on';
                        app.EditField_6.Enable = 'off';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 6
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'on';
                        app.EditField_5.Enable = 'on';
                        app.EditField_6.Enable = 'on';
                        app.EditField_7.Enable = 'off';
                        app.EditField_8.Enable = 'off';
                    case 7
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'on';
                        app.EditField_5.Enable = 'on';
                        app.EditField_6.Enable = 'on';
                        app.EditField_7.Enable = 'on';
                        app.EditField_8.Enable = 'off';
                    case 8
                        app.EditField_1.Enable = 'on';
                        app.EditField_2.Enable = 'on';
                        app.EditField_3.Enable = 'on';
                        app.EditField_4.Enable = 'on';
                        app.EditField_5.Enable = 'on';
                        app.EditField_6.Enable = 'on';
                        app.EditField_7.Enable = 'on';
                        app.EditField_8.Enable = 'on';
                end
            end
            
        end

        % Button pushed function: EnterOption2AndRunSTKButton
        function EnterOption2AndRunSTKButtonPushed(app, event)
            if strcmp(app.Switch_2.Value, 'Premade Model')
                switch app.CubeSatSizeDropDown.Value
                    case '3U'
                        ModelFileName = '3U_SP4Sides.dae';
                        NumSolPanGroups = 4;
                    case '3U Dart'
                        ModelFileName = '3UDart_SP8Sides.dae';
                        NumSolPanGroups = 4;
                    case '6U'
                        ModelFileName = '6U_SP4Sides.dae';
                        NumSolPanGroups = 4;
                    case '6U Dart'
                        ModelFileName = '6UDart_SP8Sides.dae';
                        NumSolPanGroups = 4;
                end
                SolPanGroupNames = ["Y_Minus_Cells","Y_Plus_Cells","Z_Minus_Cells","Z_Plus_Cells"];
            else
                ModelFileName = app.CustomModeldaeFileNameEditField.Value;
                NumSolPanGroups = app.NumberofSolarPanelGroupsinModelEditField.Value;
                switch NumSolPanGroup
                    case 1
                        a = convertCharsToStrings(app.EditField_1.Value);
                        SolPanGroupNames = [a];
                    case 2
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        SolPanGroupNames = [a,b];
                    case 3
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        SolPanGroupNames = [a,b,c];
                    case 4
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        d = convertCharsToStrings(app.EditField_4.Value);
                        SolPanGroupNames = [a,b,c,d];
                    case 5
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        d = convertCharsToStrings(app.EditField_4.Value);
                        e = convertCharsToStrings(app.EditField_5.Value);
                        SolPanGroupNames = [a,b,c,d,e];                      
                    case 6
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        d = convertCharsToStrings(app.EditField_4.Value);
                        e = convertCharsToStrings(app.EditField_5.Value);
                        f = convertCharsToStrings(app.EditField_6.Value);
                        SolPanGroupNames = [a,b,c,d,e,f];
                    case 7
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        d = convertCharsToStrings(app.EditField_4.Value);
                        e = convertCharsToStrings(app.EditField_5.Value);
                        f = convertCharsToStrings(app.EditField_6.Value);
                        g = convertCharsToStrings(app.EditField_7.Value);
                        SolPanGroupNames = [a,b,c,d,e,f,g];
                    case 8
                        a = convertCharsToStrings(app.EditField_1.Value);
                        b = convertCharsToStrings(app.EditField_2.Value);
                        c = convertCharsToStrings(app.EditField_3.Value);
                        d = convertCharsToStrings(app.EditField_4.Value);
                        e = convertCharsToStrings(app.EditField_5.Value);
                        f = convertCharsToStrings(app.EditField_6.Value);
                        g = convertCharsToStrings(app.EditField_7.Value);
                        h = convertCharsToStrings(app.EditField_8.Value);
                        SolPanGroupNames = [a,b,c,d,e,f,g,h];
                end
            end
            
            ModelFileLocation = [pwd, '\Models\', ModelFileName];
            assignin('base', 'ModelFileLocation', ModelFileLocation)
            assignin('base', 'NumSolPanGroups', NumSolPanGroups)
            assignin('base', 'SolPanGroupNames', SolPanGroupNames)
            
            StartDate = evalin('base', 'StartDate');
            EndDate = evalin('base', 'EndDate');
            TimeStep = evalin('base', 'TimeStep');
            COEs = evalin('base', 'COEs');
            SatEpochDate = evalin('base', 'SatEpochDate');
            
            [SolarPanels] = Call_STK_ModelSolarPanelPower(StartDate, EndDate, TimeStep, COEs, SatEpochDate, ...
                ModelFileLocation, NumSolPanGroups, SolPanGroupNames);
            
            assignin('base', 'SolarPanels', SolarPanels)

        end

        % Value changed function: 
        % NumberofSolarPanelGroupsinModelEditField
        function NumberofSolarPanelGroupsinModelEditFieldValueChanged(app, event)
            value = app.NumberofSolarPanelGroupsinModelEditField.Value;
            switch value
                case 1
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'off';
                    app.EditField_3.Enable = 'off';
                    app.EditField_4.Enable = 'off';
                    app.EditField_5.Enable = 'off';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 2
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'off';
                    app.EditField_4.Enable = 'off';
                    app.EditField_5.Enable = 'off';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 3
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'off';
                    app.EditField_5.Enable = 'off';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 4
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'on';
                    app.EditField_5.Enable = 'off';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 5
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'on';
                    app.EditField_5.Enable = 'on';
                    app.EditField_6.Enable = 'off';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 6
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'on';
                    app.EditField_5.Enable = 'on';
                    app.EditField_6.Enable = 'on';
                    app.EditField_7.Enable = 'off';
                    app.EditField_8.Enable = 'off';
                case 7
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'on';
                    app.EditField_5.Enable = 'on';
                    app.EditField_6.Enable = 'on';
                    app.EditField_7.Enable = 'on';
                    app.EditField_8.Enable = 'off';
                case 8
                    app.EditField_1.Enable = 'on';
                    app.EditField_2.Enable = 'on';
                    app.EditField_3.Enable = 'on';
                    app.EditField_4.Enable = 'on';
                    app.EditField_5.Enable = 'on';
                    app.EditField_6.Enable = 'on';
                    app.EditField_7.Enable = 'on';
                    app.EditField_8.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CubeSatSizeandSolarPanelsLabel = uilabel(app.UIFigure);
            app.CubeSatSizeandSolarPanelsLabel.Text = 'CubeSat Size and Solar Panels';
            app.SolarPanelTotalEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.SolarPanelTotalEfficiencyEditFieldLabel.Text = 'Solar Panel Total Efficiency';
            app.SolarPanelTotalEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolarPanelTotalEfficiencyEditField.Value = 0.3;
            app.SolarPanelAreaPerSidecm2Label = uilabel(app.UIFigure);
            app.SolarPanelAreaPerSidecm2Label.Text = 'Solar Panel Area Per Side (cm^2)';
            app.EnterOption1Button = uibutton(app.UIFigure, 'push');
            app.EnterOption1Button.ButtonPushedFcn = createCallbackFcn(app, @EnterOption1ButtonPushed, true);
            app.EnterOption1Button.Text = 'Enter Option 1';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = 'X (1)';
            app.SPA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2Label = uilabel(app.UIFigure);
            app.Y2Label.Text = 'Y (2)';
            app.SPA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SPA2EditField.Value = 200;
            app.Z3Label = uilabel(app.UIFigure);
            app.Z3Label.Text = 'Z (3)';
            app.SPA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SPA3EditField.Value = 400;
            app.X4Label = uilabel(app.UIFigure);
            app.X4Label.Text = '-X (4)';
            app.SPA4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y5Label = uilabel(app.UIFigure);
            app.Y5Label.Text = '-Y (5)';
            app.SPA5EditField = uieditfield(app.UIFigure, 'numeric');
            app.SPA5EditField.Value = 200;
            app.Z6Label = uilabel(app.UIFigure);
            app.Z6Label.Text = '-Z (6)';
            app.SPA6EditField = uieditfield(app.UIFigure, 'numeric');
            app.SPA6EditField.Value = 400;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'If unsure which sides to assign which area, look at the';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'SatelliteAttitude GUI for descriptions of the various attitude profiles';
            app.Option1IfyoursatelliteisoneofthestandardcubesatLabel = uilabel(app.UIFigure);
            app.Option1IfyoursatelliteisoneofthestandardcubesatLabel.Text = 'Option 1: If your satellite is one of the standard cubesat';
            app.sizesinthemenubelowthenusethissideoftheGUILabel = uilabel(app.UIFigure);
            app.sizesinthemenubelowthenusethissideoftheGUILabel.Text = 'sizes in the menu below, then use this side of the GUI.';
            app.STKwillnothavetoberunagaintodeterminepowergenerationLabel = uilabel(app.UIFigure);
            app.STKwillnothavetoberunagaintodeterminepowergenerationLabel.Text = 'STK will not have to be run again to determine power generation';
            app.Option2IfyoursatelliteisnotastandardsizeandorhasLabel = uilabel(app.UIFigure);
            app.Option2IfyoursatelliteisnotastandardsizeandorhasLabel.Text = 'Option 2: If your satellite is not a standard size and/or has deployables';
            app.suchaswithadartconfigurationyouwillneedtouseaCadmodelandLabel = uilabel(app.UIFigure);
            app.suchaswithadartconfigurationyouwillneedtouseaCadmodelandLabel.Text = 'such as with a dart configuration, you will need to use a Cad model and';
            app.STKwillberunagainEitherselectoneofthemodelsbeloworputyourLabel = uilabel(app.UIFigure);
            app.STKwillberunagainEitherselectoneofthemodelsbeloworputyourLabel.Text = 'STK will be run again. Either select one of the models below or put your';
            app.owndaeCADfileinthefolderwiththeothersLabel = uilabel(app.UIFigure);
            app.owndaeCADfileinthefolderwiththeothersLabel.Text = 'own .dae CAD file in the folder with the others. ';
            app.InformationoncreatingyourownmodelwithdefinedsolarpanelLabel = uilabel(app.UIFigure);
            app.InformationoncreatingyourownmodelwithdefinedsolarpanelLabel.Text = 'Information on creating your own model with defined solar panel ';
            app.layerscanbefoundinthePDFentitledLabel = uilabel(app.UIFigure);
            app.layerscanbefoundinthePDFentitledLabel.Text = 'layers can be found in the PDF entitled:';
            app.SizeofEachSidecubesatcubesLabel = uilabel(app.UIFigure);
            app.SizeofEachSidecubesatcubesLabel.Text = 'Size of Each Side (cubesat ''cubes'')';
            app.XDropDownLabel = uilabel(app.UIFigure);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'1x1', '1x2', '1x3', '2x3', '1x6'};
            app.XDropDown.Value = '1x2';
            app.YDropDownLabel = uilabel(app.UIFigure);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'1x1', '1x2', '1x3', '2x3', '1x6'};
            app.YDropDown.Value = '1x3';
            app.ZDropDownLabel = uilabel(app.UIFigure);
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.UIFigure);
            app.ZDropDown.Items = {'1x1', '1x2', '1x3', '2x3', '1x6'};
            app.ZDropDown.Value = '2x3';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Option 1', 'Option2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Option 1';
            app.CubeSatSizeDropDownLabel = uilabel(app.UIFigure);
            app.CubeSatSizeDropDownLabel.Enable = 'off';
            app.CubeSatSizeDropDownLabel.Text = 'CubeSat Size';
            app.CubeSatSizeDropDown = uidropdown(app.UIFigure);
            app.CubeSatSizeDropDown.Items = {'3U', '3U Dart', '6U', '6U Dart'};
            app.CubeSatSizeDropDown.Enable = 'off';
            app.CubeSatSizeDropDown.Value = '3U';
            app.EnterOption2AndRunSTKButton = uibutton(app.UIFigure, 'push');
            app.EnterOption2AndRunSTKButton.ButtonPushedFcn = createCallbackFcn(app, @EnterOption2AndRunSTKButtonPushed, true);
            app.EnterOption2AndRunSTKButton.Enable = 'off';
            app.EnterOption2AndRunSTKButton.Text = 'Enter Option 2 (And Run STK)';
            app.CustomModeldaeFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.CustomModeldaeFileNameEditFieldLabel.Enable = 'off';
            app.CustomModeldaeFileNameEditFieldLabel.Text = 'Custom Model .dae File Name';
            app.CustomModeldaeFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.CustomModeldaeFileNameEditField.Enable = 'off';
            app.Thepremademodelshave____cm2ofsolarpanelsLabel = uilabel(app.UIFigure);
            app.Thepremademodelshave____cm2ofsolarpanelsLabel.Enable = 'off';
            app.Thepremademodelshave____cm2ofsolarpanelsLabel.Text = 'The premade models have ____ cm^2 of solar panels';
            app.per1x1cubefacewhichisfairlystandardLabel = uilabel(app.UIFigure);
            app.per1x1cubefacewhichisfairlystandardLabel.Enable = 'off';
            app.per1x1cubefacewhichisfairlystandardLabel.Text = 'per 1x1 cube face, which is fairly standard.';
            app.SolarPanelTotalEfficiencyEditField_2Label = uilabel(app.UIFigure);
            app.SolarPanelTotalEfficiencyEditField_2Label.Enable = 'off';
            app.SolarPanelTotalEfficiencyEditField_2Label.Text = 'Solar Panel Total Efficiency';
            app.SolarPanelTotalEfficiencyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SolarPanelTotalEfficiencyEditField_2.Enable = 'off';
            app.SolarPanelTotalEfficiencyEditField_2.Value = 0.3;
            app.IncludethedaefileextensionLabel = uilabel(app.UIFigure);
            app.IncludethedaefileextensionLabel.Enable = 'off';
            app.IncludethedaefileextensionLabel.Text = 'Include the .dae file extension';
            app.Switch_2 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_2.Items = {'Custom Model', 'Premade Model'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Enable = 'off';
            app.Switch_2.Value = 'Premade Model';
            app.NumberofSolarPanelGroupsinModelEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSolarPanelGroupsinModelEditFieldLabel.Enable = 'off';
            app.NumberofSolarPanelGroupsinModelEditFieldLabel.Text = 'Number of Solar Panel Groups in Model';
            app.NumberofSolarPanelGroupsinModelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSolarPanelGroupsinModelEditField.Limits = [1 8];
            app.NumberofSolarPanelGroupsinModelEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofSolarPanelGroupsinModelEditFieldValueChanged, true);
            app.NumberofSolarPanelGroupsinModelEditField.Enable = 'off';
            app.NumberofSolarPanelGroupsinModelEditField.Value = 1;
            app.EditField_1 = uieditfield(app.UIFigure, 'text');
            app.EditField_1.Enable = 'off';
            app.EditField_1.Value = 'Enter Names';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Enable = 'off';
            app.SolarPanelGroupNamesLabel = uilabel(app.UIFigure);
            app.SolarPanelGroupNamesLabel.Enable = 'off';
            app.SolarPanelGroupNamesLabel.Text = 'Solar Panel Group Names';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Enable = 'off';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Enable = 'off';
            app.CADBlenderColladaSTK_v05bpdfLabel = uilabel(app.UIFigure);
            app.CADBlenderColladaSTK_v05bpdfLabel.Text = 'CAD-Blender-Collada-STK_v05b.pdf';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SizeSolarPanelsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end